classdef maaiEPSC < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      MiniFigure                      matlab.ui.Figure
      MiniGrid1                       matlab.ui.container.GridLayout
      TabGroup                        matlab.ui.container.TabGroup
      Tab1                            matlab.ui.container.Tab
      Tab1Grid1                       matlab.ui.container.GridLayout
      Tab1GridRight3                  matlab.ui.container.GridLayout
      MoveSumOptionsPanel             matlab.ui.container.Panel
      DiffSumLength                   matlab.ui.control.Spinner
      SumLengthSpinnerLabel_2         matlab.ui.control.Label
      DiffTresh                       matlab.ui.control.Spinner
      TresholdSpinnerLabel_2          matlab.ui.control.Label
      DecaySumTresh                   matlab.ui.control.Spinner
      SumTresholdSpinnerLabel         matlab.ui.control.Label
      DecaySumLength                  matlab.ui.control.Spinner
      SumLengthSpinnerLabel           matlab.ui.control.Label
      DecayTreshold                   matlab.ui.control.Spinner
      TresholdSpinnerLabel            matlab.ui.control.Label
      RiseLabel                       matlab.ui.control.Label
      RiseSumTresh                    matlab.ui.control.Spinner
      SumTresholdLabel_Rise           matlab.ui.control.Label
      RiseSumLength                   matlab.ui.control.Spinner
      SumLengthLabel_Rise             matlab.ui.control.Label
      RiseTreshold                    matlab.ui.control.Spinner
      TresholdLabel_Rise              matlab.ui.control.Label
      DecayLabel                      matlab.ui.control.Label
      DerivativeLabel                 matlab.ui.control.Label
      DiffSumTresh                    matlab.ui.control.Spinner
      SumTresholdLabel_Diff           matlab.ui.control.Label
      FixLimsPanel                    matlab.ui.container.Panel
      DebugBtn                        matlab.ui.control.Button
      dXSlider                        matlab.ui.control.Slider
      dXSliderLabel                   matlab.ui.control.Label
      XLim1val                        matlab.ui.control.NumericEditField
      XLim1EditFieldLabel             matlab.ui.control.Label
      YLim1val                        matlab.ui.control.NumericEditField
      YLim1Label                      matlab.ui.control.Label
      dYSliderLabel                   matlab.ui.control.Label
      dYSlider                        matlab.ui.control.Slider
      BtnXStepBack                    matlab.ui.control.Button
      BtnXStepForw                    matlab.ui.control.Button
      BtnYStepDown                    matlab.ui.control.Button
      BtnYStepUp                      matlab.ui.control.Button
      DerOnlyTypePanel                matlab.ui.container.Panel
      derMinIEI                       matlab.ui.control.NumericEditField
      MinIEIsamplesLabel              matlab.ui.control.Label
      derMaxDecay                     matlab.ui.control.NumericEditField
      DecayTresholdLabel              matlab.ui.control.Label
      derMaxRise                      matlab.ui.control.NumericEditField
      MaxRiseTimemsLabel              matlab.ui.control.Label
      OnsetTreshLabel                 matlab.ui.control.Label
      dpafteronsetLabel               matlab.ui.control.Label
      PreZerosLabel                   matlab.ui.control.Label
      Tab1GridRight2                  matlab.ui.container.GridLayout
      Panel3Der                       matlab.ui.container.Panel
      ShoweventsinnormallyfilteredtraceButton  matlab.ui.control.Button
      PlotDersAx2                     matlab.ui.control.CheckBox
      TresholdLabel_3                 matlab.ui.control.Label
      der3Tresh                       matlab.ui.control.Spinner
      SmoothLabel                     matlab.ui.control.Label
      LocalSmoothRange                matlab.ui.control.Spinner
      derTresh                        matlab.ui.control.Spinner
      TresholdLabel                   matlab.ui.control.Label
      ButtonGroup                     matlab.ui.container.ButtonGroup
      rdDerivativeButton              matlab.ui.control.RadioButton
      MovSumButton                    matlab.ui.control.RadioButton
      stDerivativeButton              matlab.ui.control.RadioButton
      Panel2Der                       matlab.ui.container.Panel
      SlidingDerivative               matlab.ui.control.CheckBox
      ShowProgressCheck               matlab.ui.control.CheckBox
      ReAnalyse                       matlab.ui.control.Button
      FilterDerGoButton               matlab.ui.control.Button
      Gauge_semicirc2                 matlab.ui.control.SemicircularGauge
      SecondaryDerFilterOrder         matlab.ui.control.Spinner
      OrderLabel_4                    matlab.ui.control.Label
      SecondaryDerLowPassHz           matlab.ui.control.NumericEditField
      LowPassfqLabel                  matlab.ui.control.Label
      LengthLabel_4                   matlab.ui.control.Label
      SecondaryDerFilterLength        matlab.ui.control.Spinner
      SecondaryDerFilterType          matlab.ui.control.DropDown
      FilterDropDown_2Label_2         matlab.ui.control.Label
      Panel1Der                       matlab.ui.container.Panel
      DerFilterOrder                  matlab.ui.control.Spinner
      OrderLabel_3                    matlab.ui.control.Label
      DerLowPassHz                    matlab.ui.control.NumericEditField
      LowPassfqLabel_2                matlab.ui.control.Label
      DerFilterLength                 matlab.ui.control.Spinner
      LengthLabel_3                   matlab.ui.control.Label
      DerFilterType                   matlab.ui.control.DropDown
      FilterDropDownLabel_2           matlab.ui.control.Label
      Tab1GridRight1                  matlab.ui.container.GridLayout
      Panel3Data                      matlab.ui.container.Panel
      OnlyderMethodBtn                matlab.ui.control.CheckBox
      ShowUnfiltereddataCheckBox      matlab.ui.control.CheckBox
      FilterTraceButton               matlab.ui.control.Button
      Panel1Data                      matlab.ui.container.Panel
      DataFilterLength                matlab.ui.control.Spinner
      LengthLabel_5                   matlab.ui.control.Label
      DataFilterType                  matlab.ui.control.DropDown
      FilterDropDownLabel_3           matlab.ui.control.Label
      ShowStarts2CheckBox             matlab.ui.control.CheckBox
      ShowPeaks2CheckBox              matlab.ui.control.CheckBox
      ShowStartsCheckBox              matlab.ui.control.CheckBox
      ShowPeaksCheckBox               matlab.ui.control.CheckBox
      DataFilterOrder                 matlab.ui.control.Spinner
      OrderLabel_5                    matlab.ui.control.Label
      DataLowPassHz                   matlab.ui.control.NumericEditField
      LowPassfqLabel_5                matlab.ui.control.Label
      Panel2Data                      matlab.ui.container.Panel
      SecondaryDataFilterOrder        matlab.ui.control.Spinner
      OrderLabel_2                    matlab.ui.control.Label
      SecondaryDataLowPassHz          matlab.ui.control.NumericEditField
      LowPassfqLabel_4                matlab.ui.control.Label
      SecondaryDataFilterLength       matlab.ui.control.Spinner
      LengthLabel_2                   matlab.ui.control.Label
      SecondaryDataFilterType         matlab.ui.control.DropDown
      FilterDropDown_2Label           matlab.ui.control.Label
      Tab2                            matlab.ui.container.Tab
      Tab2Grid1                       matlab.ui.container.GridLayout
      Tab2BottomGrid                  matlab.ui.container.GridLayout
      Panel_10                        matlab.ui.container.Panel
      GridLayout7                     matlab.ui.container.GridLayout
      Panel_12                        matlab.ui.container.Panel
      Tab2_BtnXStepForw               matlab.ui.control.Button
      Tab2_dXSlider                   matlab.ui.control.Slider
      DisplayoptionsPanel             matlab.ui.container.Panel
      Tab2_YLim1                      matlab.ui.control.NumericEditField
      Y1Label                         matlab.ui.control.Label
      Tab2_XLim1                      matlab.ui.control.NumericEditField
      X1Label                         matlab.ui.control.Label
      ConnectwithpeaksCheckBox        matlab.ui.control.CheckBox
      ShowFittedStartsCheckBox        matlab.ui.control.CheckBox
      DebugBtnTab2                    matlab.ui.control.Button
      BtnYStepDownAx4                 matlab.ui.control.Button
      BtnYStepUpAx4                   matlab.ui.control.Button
      DropDown                        matlab.ui.control.DropDown
      ShowunfilteredCheckBox          matlab.ui.control.CheckBox
      ShowSpecRejected                matlab.ui.control.CheckBox
      ShowRejectedallCheckBox         matlab.ui.control.CheckBox
      ShowFitsCheckBox                matlab.ui.control.CheckBox
      Tab2_dYSlider                   matlab.ui.control.Slider
      Panel_6                         matlab.ui.container.Panel
      Panel_11                        matlab.ui.container.Panel
      Tab2_BtnXStepBack               matlab.ui.control.Button
      GridLayout6                     matlab.ui.container.GridLayout
      TabGroup2                       matlab.ui.container.TabGroup
      TextTab                         matlab.ui.container.Tab
      GridLayout8                     matlab.ui.container.GridLayout
      TextArea                        matlab.ui.control.TextArea
      CountEventsTab                  matlab.ui.container.Tab
      CountallButton                  matlab.ui.control.Button
      TotalEditField                  matlab.ui.control.NumericEditField
      TotalEditFieldLabel             matlab.ui.control.Label
      Interval2EditField              matlab.ui.control.NumericEditField
      Interval2Label                  matlab.ui.control.Label
      Interval1EditField              matlab.ui.control.NumericEditField
      Interval1EditFieldLabel_2       matlab.ui.control.Label
      CountcurrenteventsButton        matlab.ui.control.Button
      FormatSpecTab                   matlab.ui.container.Tab
      MarkerLineWidthSpinner          matlab.ui.control.Spinner
      LineSpinnerLabel                matlab.ui.control.Label
      MarkerSizeSpinner               matlab.ui.control.Spinner
      MarkerSizeSpinnerLabel          matlab.ui.control.Label
      FitsLineWidthSpinner            matlab.ui.control.Spinner
      FitsSpinner_2Label              matlab.ui.control.Label
      TracesLineWidthSpinner          matlab.ui.control.Spinner
      LineWidthSpinnerLabel           matlab.ui.control.Label
      Tab                             matlab.ui.container.Tab
      TextArea_2                      matlab.ui.control.TextArea
      GoToTab                         matlab.ui.container.Tab
      GoToTraceNoEditField            matlab.ui.control.NumericEditField
      GototracenoEditFieldLabel       matlab.ui.control.Label
      Tab2PanelLeftTop                matlab.ui.container.Panel
      RefitAllDataBtn                 matlab.ui.control.Button
      GoFirstfileIdxBtn               matlab.ui.control.Button
      GoLastfileIdxBtn                matlab.ui.control.Button
      RefitDataButton                 matlab.ui.control.Button
      UpdateanalyseddataButton        matlab.ui.control.Button
      GoPreviousfileIdxBtn            matlab.ui.control.Button
      GoNextfileIdxBtn                matlab.ui.control.Button
      LoaddataStructButton            matlab.ui.control.Button
      BadFitSwitch                    matlab.ui.control.Switch
      Tab2DataFilterPanel             matlab.ui.container.Panel
      Filter2fq_kHz                   matlab.ui.control.NumericEditField
      fqkHzEditField_2Label           matlab.ui.control.Label
      Filter2Order                    matlab.ui.control.Spinner
      OrderSpinner_2Label             matlab.ui.control.Label
      Filter2Length                   matlab.ui.control.Spinner
      LengthSpinner_2Label            matlab.ui.control.Label
      Filter2Selection                matlab.ui.control.DropDown
      Filter2DropDownLabel            matlab.ui.control.Label
      Filter1fq_kHz                   matlab.ui.control.NumericEditField
      fqkHzEditFieldLabel             matlab.ui.control.Label
      Order1Order                     matlab.ui.control.Spinner
      OrderSpinnerLabel               matlab.ui.control.Label
      Filter1Length                   matlab.ui.control.Spinner
      LengthSpinnerLabel              matlab.ui.control.Label
      Filter1Selection                matlab.ui.control.DropDown
      Filter1DropDownLabel            matlab.ui.control.Label
      FittingParametersPanel          matlab.ui.container.GridLayout
      FittingparametersPanel          matlab.ui.container.Panel
      UseFindpeakstoconfirmpeakCheckBox  matlab.ui.control.CheckBox
      RobustmethodCheckBox            matlab.ui.control.CheckBox
      FitSmoothData                   matlab.ui.control.CheckBox
      FitdecayWindow                  matlab.ui.control.NumericEditField
      DecaySearchwindowsLabel         matlab.ui.control.Label
      SgolaySpan                      matlab.ui.control.NumericEditField
      SpanfilterLabel                 matlab.ui.control.Label
      MinRsqSpinner                   matlab.ui.control.Spinner
      MinRsqSpinnerLabel              matlab.ui.control.Label
      FitMethodDropDown               matlab.ui.control.DropDown
      FitMethodDropDownLabel          matlab.ui.control.Label
      ConfirmMinisPanel               matlab.ui.container.Panel
      AcceptRejectButton              matlab.ui.control.StateButton
      FinishButton                    matlab.ui.control.Button
      UndolastclickButton             matlab.ui.control.Button
      AddeventsButton                 matlab.ui.control.StateButton
      ProgressPanel                   matlab.ui.container.Panel
      GridLayout4                     matlab.ui.container.GridLayout
      ShowprogressBtn                 matlab.ui.control.StateButton
      ProgressGauge                   matlab.ui.control.Gauge
      CopytraceparametersPanel        matlab.ui.container.Panel
      CopyConstraintsCheckBox         matlab.ui.control.CheckBox
      CopySGspanCheckBox              matlab.ui.control.CheckBox
      CopyDecaySearchWindowCheckBox   matlab.ui.control.CheckBox
      CopyFitSmoothValCheckBox        matlab.ui.control.CheckBox
      CopyTriggervalCheckBox          matlab.ui.control.CheckBox
      Panel_5                         matlab.ui.container.Panel
      GoHere_interval2                matlab.ui.control.Button
      GoHere_interval1                matlab.ui.control.Button
      Interval2end                    matlab.ui.control.NumericEditField
      Interval2start                  matlab.ui.control.NumericEditField
      Interval2EditFieldLabel         matlab.ui.control.Label
      FinishHere_interval2            matlab.ui.control.Button
      Interval1Start                  matlab.ui.control.NumericEditField
      Interval1EditFieldLabel         matlab.ui.control.Label
      Interval1End                    matlab.ui.control.NumericEditField
      FinishHere_interval1            matlab.ui.control.Button
      Tab2PlotNow                     matlab.ui.control.Button
      FitNowButton                    matlab.ui.control.Button
      ConstraintsPanel                matlab.ui.container.Panel
      MinDecayTauLabel_ms             matlab.ui.control.Label
      MaxDecayTauLabel_ms             matlab.ui.control.Label
      MaxDecayTau                     matlab.ui.control.NumericEditField
      MaxDecayTauLabel                matlab.ui.control.Label
      MinDecayTau                     matlab.ui.control.NumericEditField
      MinDecayTauLabel                matlab.ui.control.Label
      MinAmpLabel_3                   matlab.ui.control.Label
      MinAmpLabel_2                   matlab.ui.control.Label
      MaxAmpLabel_pA                  matlab.ui.control.Label
      MaxAmp                          matlab.ui.control.NumericEditField
      MaxAmpLabel                     matlab.ui.control.Label
      MinAmpLabel_pA                  matlab.ui.control.Label
      MinAmp                          matlab.ui.control.NumericEditField
      MinAmpLabel                     matlab.ui.control.Label
      NextEventTriggerPanel           matlab.ui.container.Panel
      SettriggervalueDropDownLabel_2  matlab.ui.control.Label
      SumDerivativeCheckBox           matlab.ui.control.CheckBox
      SumDownOnlyCheckBox             matlab.ui.control.CheckBox
      TriggerNextEventDropDown        matlab.ui.control.DropDown
      SettriggervalueDropDownLabel    matlab.ui.control.Label
      ax4                             matlab.ui.control.UIAxes
      Tab3                            matlab.ui.container.Tab
      MatFileNameEditField            matlab.ui.control.EditField
      FilenameEditFieldLabel          matlab.ui.control.Label
      NewdataStructCheckBox           matlab.ui.control.CheckBox
      SaveAllButton                   matlab.ui.control.Button
      TextAreaSaveOut                 matlab.ui.control.TextArea
      MoveDoubleRecsCheckBox          matlab.ui.control.CheckBox
      AppendtodataStructCheckBox      matlab.ui.control.CheckBox
      SaveMinisWithData               matlab.ui.control.CheckBox
      ExportFullMinis                 matlab.ui.control.CheckBox
      SaveOptionsListBox              matlab.ui.control.ListBox
      SaveoptionsListBoxLabel         matlab.ui.control.Label
      HeadersSeparateCheckBox         matlab.ui.control.CheckBox
      MoveAbfFileToAnalysedCheckBox   matlab.ui.control.CheckBox
      MiniGridTopPart                 matlab.ui.container.GridLayout
      ClearTraceMarkBadButton         matlab.ui.control.Button
      ClearTraceDeleteButton          matlab.ui.control.Button
      ClearTraceButton                matlab.ui.control.Button
      PanBtn                          matlab.ui.control.StateButton
      ZoomInBtn                       matlab.ui.control.StateButton
      ZoomresetBtn                    matlab.ui.control.Button
      PreviousFileDropBtn             matlab.ui.control.Button
      NextFileDropBtn                 matlab.ui.control.Button
      LoadSampleDataTab2Btn           matlab.ui.control.Button
      LoadSampleDataButton            matlab.ui.control.Button
      ClearDataBtn                    matlab.ui.control.Button
      DeleteAbfBtn                    matlab.ui.control.Button
      MarkBadTraceBtn                 matlab.ui.control.Button
      Lamp                            matlab.ui.control.Lamp
      LoadFileListBtn                 matlab.ui.control.Button
      FilesDrop                       matlab.ui.control.DropDown
      LoadAbfButton                   matlab.ui.control.Button
      LoadMatFileButton               matlab.ui.control.Button
   end


   properties (Access = public)
      fileSI=[];
      yDataFilt=[];
      yData=[];
      dataDerFilt=[];
      whereStarts=[];
      wherePeaks=[];
      Method2Data=[];
      FitDatas=[]; % Description
      FitVals=[]; % Description
      Idxs=[]; % Description
      FitInterval=[];
      ManualSelectionData=struct();
      CurrTrace='';
      ax1
      ax2
      ax3

   end


   methods (Access = public)

      function GoLoadABFfiles(app,pathName,fileName)
         app.Lamp.Color=[1 0 0];
         [app.yData,si,~] = abfload(fullfile(pathName,fileName)); %load data
         app.CurrTrace=fileName;
         app.fileSI=si*1e-6; %normal si units
         app.ax4.UserData.fileSI=app.fileSI;
         xData=(1:length(app.yData)).*app.fileSI;

         %add folder to path and generate helper dirs
         cd(pathName)
         if ~isfolder('Delete abf files')
            mkdir('Delete abf files')
         end
         if ~isfolder('BadTraces')
            mkdir('BadTraces')
         end

         fullfolderpath=genpath(pathName);
         addpath(fullfolderpath);
         app.LoaddataStructButton.UserData.fileIdx=[]; %this fileIdx is reserved for analysed data
         app.ax1.UserData.CurrTrace=fileName; %prevent confusion with plotted from dropdown
         app.Lamp.Color=[0 1 0];
      end

      function DeterminePeaks_PlotAx3(app)
         app.Lamp.Color=[1 0.3 0];

         app.wherePeaks=[];
         app.whereStarts=[];

         if ~app.OnlyderMethodBtn.Value
            DeterminePeaks_PlotAx3_DownOnlyMethod(app);

         elseif app.OnlyderMethodBtn.Value
            disp('analysing using only derivative method...')

            fileSI_ms=app.fileSI*1e3;

            %Make logical values wether below/above treshold
            DerBelowTresh=app.dataDerFilt<app.OnsetTresh.Value;

            %find instances of n consequtive 1's starting with 000
            [app.whereStarts, ~] = regexp(char(DerBelowTresh'+'0'), char([zeros(1,app.preZeros.Value) ones(1,app.windowLength.Value)]+'0'));
            app.whereStarts=app.whereStarts(:);

            %remove double detections
            %app.whereStarts=[app.whereStarts; app.whereStarts(end)];
            app.whereStarts(diff(app.whereStarts)<5)=[];

            %find event peaks
            DerAboveTresh=app.dataDerFilt>app.derMaxDecay.Value; %When is derivative above treshold (identify decays)
            IntervalToPeak=round(app.derMaxRise.Value/fileSI_ms);

            app.wherePeaks=nan(size(app.whereStarts));

            for peakIdx=1:length(app.whereStarts)
               if app.whereStarts(peakIdx)+round(app.derMaxRise.Value/fileSI_ms) < length(DerAboveTresh)
                  if app.whereStarts(peakIdx)+round(app.derMaxRise.Value/fileSI_ms)<length(app.yDataFilt)
                     range=app.whereStarts(peakIdx):app.whereStarts(peakIdx)+round(app.derMaxRise.Value/fileSI_ms);
                     range=range(range>0);
                     [~,loc_sor] = findpeaks(app.yDataFilt(range).*-1,range,'SortStr','descend');
                     if ~isempty(loc_sor)
                        app.wherePeaks(peakIdx,1)=loc_sor(1);
                     end
                  end
               end
            end

            app.whereStarts=app.whereStarts(~isnan(app.wherePeaks));
            app.wherePeaks=app.wherePeaks(~isnan(app.wherePeaks));
            app.Method2Data.xDataDer=(1:length(app.dataDerFilt)).*app.fileSI;
            app.whereStarts=app.whereStarts(1:min(length(app.wherePeaks),length(app.whereStarts)));
            app.wherePeaks=app.wherePeaks(1:min(length(app.wherePeaks),length(app.whereStarts)));

            app.Lamp.Color=[1 1 0];
            hold(app.ax3,'on')
            plot(app.ax3, (1:length(app.dataDerFilt))'.*app.fileSI,(app.dataDerFilt),'Color', [0.4 0.4 0.4], ...
               'DisplayName', 'der','LineWidth',0.6,'Tag','TreshDpndnt_plotAx3funcDownOnly');
            plot(app.ax3, app.Method2Data.xDataDer(DerAboveTresh),app.dataDerFilt(DerAboveTresh),'Color', [0.6667 0.333 1], ...
               'DisplayName', 'BelowTresh','Marker', '.','Tag','TreshDpndnt_PlotAx3AboveDer');
            plot(app.ax3, app.Method2Data.xDataDer(DerBelowTresh),app.dataDerFilt(DerBelowTresh),'Color', [0 0.6667 0.6667], ...
               'DisplayName', 'BelowTresh','Marker', '.','Tag','TreshDpndnt_PlotAx3BelowDer');
            hold(app.ax3,'on')
            line(app.ax3, [1 length(app.yDataFilt)*app.fileSI], [app.derMaxDecay.Value app.derMaxDecay.Value], ...
               'Color', [0.6 0.6 0.6], 'linestyle', '-.','DisplayName','derTresh','Tag','TreshDpndnt_LineAx3DerTresh');
            linkaxes([app.ax1 app.ax2 app.ax3],'x')

            hold(app.ax3,'on')
            scatter(app.ax3, app.wherePeaks.*app.fileSI,app.yDataFilt(app.wherePeaks)-mean(app.yDataFilt),[],[1 0 1],"*",'Tag','TreshDpndnt_ScatAx3wherePeaks');
            scatter(app.ax3, app.whereStarts.*app.fileSI,app.yDataFilt(app.whereStarts)-mean(app.yDataFilt),[],[0 1 0],"+",'Tag','TreshDpndnt_ScatAx3whereStarts');


            h=findobj(app.ax1,'Tag','TreshDpndnt_ScatAx1whereStarts');
            if ~isempty(h)
               delete(h)
            end

            hold(app.ax1,'on')
            h=scatter(app.ax1, app.wherePeaks.*app.fileSI,app.yDataFilt(app.wherePeaks),[],[1 0 1],"*",'Tag','TreshDpndnt_ScatAx1wherePeaks');
            h.LineWidth=1.1;

            h2=scatter(app.ax1, app.whereStarts.*app.fileSI,app.yDataFilt(app.whereStarts),[],[0 1 0],"+",'Tag','TreshDpndnt_ScatAx1whereStarts');
            h2.LineWidth=1.1;

            app.Lamp.Color=[0 1 0];
            linkaxes([app.ax1 app.ax2 app.ax3],'x')

            %                 hold(app.ax1,'on')
            %                 scatter(app.ax1, app.wherePeaks.*app.fileSI,app.yDataFilt(app.wherePeaks),[],[1 0 1],"*",'Tag','TreshDpndnt_ScatAx1wherePeaks');
            %                 scatter(app.ax1, app.whereStarts.*app.fileSI,app.yDataFilt(app.whereStarts),[],[0 1 0],"+",'Tag','TreshDpndnt_ScatAx1whereStarts');
         end

         app.Lamp.Color=[0 1 0];
      end

      function DeterminePeaks_PlotAx3_DownOnlyMethod(app)

         app.Lamp.Color=[1 0 0];
         drawnow

         app.wherePeaks=[];
         app.whereStarts=[];


         %Make down-only filter

         funcDownOnly = zeros(size(app.yDataFilt));
         for icnt = 1:length(app.dataDerFilt)
            if app.dataDerFilt(icnt) < 0
               funcDownOnly(icnt+1) = funcDownOnly(icnt) + app.dataDerFilt(icnt);
            end
         end

         dDownOnly =diff(funcDownOnly);
         d3DownOnly=diff(diff(dDownOnly));
         switch app.ButtonGroup.SelectedObject.Text
            case '1st Derivative'
               disp('Tresholding 1st derivative of down-only function')
               % find where derivative of DownOnly is changing from negative to positive
               peaks = find((dDownOnly(2:length(dDownOnly)) ./ dDownOnly(1:length(dDownOnly) -1) < 0 | dDownOnly(2:length(dDownOnly)) == 0) & dDownOnly(1:length(dDownOnly) - 1) < 0);
               numStarts = 0;
               whereNull = find(funcDownOnly == 0);
               app.whereStarts = zeros(length(peaks), 1);
               app.wherePeaks = app.whereStarts;

               for icnt = 1:length(peaks)
                  if app.ShowProgressCheck.Value
                     tol=1e-3;
                     if any(abs([0:.1:1]-(icnt/length(peaks))) < tol ) %#ok<NBRAK>
                        app.Gauge_semicirc2.Value=(icnt/(length(peaks)))*100;
                        drawnow
                     end
                  end

                  if abs(funcDownOnly(peaks(icnt))) > abs(app.derTresh.Value)
                     numStarts = numStarts + 1;
                     app.whereStarts(numStarts) = whereNull(find(whereNull < peaks(icnt), 1, 'last'));
                     app.wherePeaks(numStarts) = peaks(icnt);
                  end


               end %of icnt loop
               %             case 'Mixed'
               %                disp('Tresholding 3st derivative of down-only function with peaks found in 1st')
               %
               %                % find where derivative of DownOnly is changing from negative to positive
               %                peaks = find((dDownOnly(2:length(dDownOnly)) ./ dDownOnly(1:length(dDownOnly) -1) < 0 | dDownOnly(2:length(dDownOnly)) == 0) & dDownOnly(1:length(dDownOnly) - 1) < 0);
               %                numStarts = 0;
               %                whereNull = find(funcDownOnly == 0);
               %                app.whereStarts = zeros(length(peaks), 1);
               %                app.wherePeaks = app.whereStarts;
               %
               %                for icnt = 1:length(peaks)
               %                   if d3DownOnly(peaks(icnt))<(app.der3Tresh.Value*-1)
               %                      numStarts = numStarts + 1;
               %                      app.whereStarts(numStarts) = whereNull(find(whereNull < peaks(icnt), 1, 'last'));
               %                      app.wherePeaks(numStarts) = peaks(icnt);
               %                   end
               %                end %of icnt loop

            case 'MovSum'
               disp('Moving sum of derivative')

               diffyDataFilt=diff(app.yDataFilt);
               diffyDataFilt=[diffyDataFilt(1);diffyDataFilt];
               filtdiffyDataFilt=smooth(diffyDataFilt, app.LocalSmoothRange.Value,'sgolay');
               RoughBaseline=medfilt1(app.yDataFilt, 2500);
               MaxRisePhaseTime=0.005;
               app.Idxs=[];

               smoothyDataAvg=smooth(app.yData,app.LocalSmoothRange.Value,'moving');
               smoothyDataAvg=smooth(smoothyDataAvg,app.LocalSmoothRange.Value-1,'sgolay');
               idxs=find(movsum(filtdiffyDataFilt<app.DiffTresh.Value,[app.DiffSumLength.Value 0])>app.DiffSumTresh.Value);
               idxs=flipud(idxs);
               idxs=idxs([-9;diff(idxs)]<-5);
               idxs=flipud(idxs);

               idxs(idxs>(0.1/app.fileSI));


               for nIdx=1:length(idxs)
                  %find peak index
                  Range=idxs(nIdx,1):idxs(nIdx,1)+30;
                  [~,peakIdx]=min(app.yDataFilt(Range));
                  app.Idxs(nIdx,1)=NaN;
                  app.Idxs(nIdx,2)=Range(peakIdx);
               end

               for indexEPSC=1:length(app.Idxs)
                  %check decay phase
                  decayPhase=smoothyDataAvg(app.Idxs(indexEPSC,2):app.Idxs(indexEPSC,2)+round(0.0015/app.fileSI));
                  if ~any(movsum(diff(decayPhase)>app.DecayTreshold.Value,[app.DecaySumLength.Value 0])>app.DecaySumTresh.Value)
                     app.Idxs(indexEPSC,2)=NaN;
                  end
               end
               app.Idxs=app.Idxs(~isnan(app.Idxs(:,2)),:);

               for indexEPSC=1:length(app.Idxs)
                  RisePhaseXrev=[];
                  RisePhaseYrev=[];
                  NewRiseIndex=[];
                  IPOINTS=[];

                  try
                     RisePhaseXrev=fliplr(app.Idxs(indexEPSC,2)-round(MaxRisePhaseTime/app.fileSI):app.Idxs(indexEPSC,2));
                  catch
                     fprintf('error at indexEPSC:%d\n',indexEPSC);
                  end
                  RisePhaseXrev=RisePhaseXrev(RisePhaseXrev>0);
                  RisePhaseYrev=smoothyDataAvg(RisePhaseXrev);
                  RisePhaseYrev=RisePhaseYrev-max(RisePhaseYrev);


                  NewRiseIndex=RisePhaseXrev(find(movsum(diff(RisePhaseYrev)<app.RiseTreshold.Value,[0 app.RiseSumLength.Value])>app.RiseSumTresh.Value,1,'first'));
                  if isempty(NewRiseIndex)
                     NewRiseIndex=RisePhaseXrev(find(movsum(diff(RisePhaseYrev)<0.1,[0 app.RiseSumLength.Value+1])>3,1,'first'));
                  end

                  if isempty(NewRiseIndex)
                     NewRiseIndex=RisePhaseXrev(find(movsum(diff(RisePhaseYrev)<app.RiseTreshold.Value+0.1,[0 app.RiseSumLength.Value])>app.RiseSumTresh.Value,1,'first'));
                  end
                  if isempty(NewRiseIndex)
                     NewRiseIndex=RisePhaseXrev(find(movsum(diff(RisePhaseYrev)<app.RiseTreshold.Value+0.1,[0 app.RiseSumLength.Value]+1)>app.RiseSumTresh.Value,1,'first'));
                  end
                  if isempty(NewRiseIndex)
                     NewRiseIndex=RisePhaseXrev(find(movsum(diff(RisePhaseYrev)<app.RiseTreshold.Value+0.2,[0 app.RiseSumLength.Value])>app.RiseSumTresh.Value,1,'first'));
                  end
                  if isempty(NewRiseIndex)
                     NewRiseIndex=RisePhaseXrev(find(movsum(diff(RisePhaseYrev)<app.RiseTreshold.Value+0.2,[0 app.RiseSumLength.Value]+1)>app.RiseSumTresh.Value,1,'first'));
                  end

                  if  isempty(NewRiseIndex)
                     app.Idxs(indexEPSC,1)=NaN;
                  else
                     app.Idxs(indexEPSC,1)=NewRiseIndex;
                  end


               end


               app.Idxs=app.Idxs(~any(isnan(app.Idxs),2),:);

               app.whereStarts = app.Idxs(:,1);
               app.wherePeaks = app.Idxs(:,2);


            case '3rd Derivative'
               disp('Tresholding 3rd derivative')
               app.wherePeaks=find(d3DownOnly<(app.der3Tresh.Value*-1));
               whereNull = find(funcDownOnly == 0);
               app.whereStarts=zeros(size(app.wherePeaks));

               for icnt=1:length(app.wherePeaks)
                  app.whereStarts(icnt,1)=whereNull(find(whereNull < app.wherePeaks(icnt), 1, 'last'));
               end

               if isempty(app.whereStarts)
                  for icnt=1:length(app.wherePeaks)
                     app.whereStarts(icnt,1)=whereNull(find(whereNull < app.wherePeaks(icnt), 1, 'last'));
                  end
               end
         end %of switch

         if isempty(app.whereStarts)
            disp('analysing 3rd derivative by default')

            app.wherePeaks=find(d3DownOnly<(app.der3Tresh.Value*-1));
            whereNull = find(funcDownOnly == 0);

            app.whereStarts=zeros(size(app.wherePeaks));

            for icnt=1:length(app.wherePeaks)
               app.whereStarts(icnt,1)=whereNull(find(whereNull < app.wherePeaks(icnt), 1, 'last'));
            end
         end

         if any(app.wherePeaks>length(app.yDataFilt))
            app.wherePeaks(app.wherePeaks>length(app.yDataFilt),:)=[];
         end

         if any(app.whereStarts>length(app.yDataFilt))
            app.whereStarts(app.whereStarts>length(app.yDataFilt),:)=[];
         end

         app.whereStarts=nonzeros(app.whereStarts);
         app.wherePeaks=nonzeros(app.wherePeaks);

         app.whereStarts=app.whereStarts(1:min(size(app.wherePeaks,1),size(app.whereStarts,1)));
         app.wherePeaks=app.wherePeaks(1:min(size(app.wherePeaks,1),size(app.whereStarts,1)));
         assert(~any(app.wherePeaks-app.whereStarts<0))
         SubstryDataFilt=app.yDataFilt-medfilt1(app.yDataFilt, 5000);

         app.Lamp.Color=[1 1 0];
         %Plot ax3, first remove other data if its there
         if ~isempty(app.ax3.Children)
            delete(app.ax3.Children)
         end
         hold(app.ax3,'on')

         %Plot treshold of chosen method
         switch app.ButtonGroup.SelectedObject.Text
            case '1st Derivative'
               line(app.ax3, [1 length(app.yDataFilt)].*app.fileSI, [app.derTresh.Value app.derTresh.Value], ...
                  'Color', [0.6 0.6 0.6], 'linestyle', '-.','DisplayName','derTresh','Tag','TreshDpndnt_LineAx3DerTresh');
            case 'Mixed'
               line(app.ax3, [1 length(app.yDataFilt)].*app.fileSI, [app.LocalSmoothRange.Value app.LocalSmoothRange.Value], ...
                  'Color', [0.6 0.6 0.6], 'linestyle', '-.','DisplayName','derMixedTresh','Tag','TreshDpndnt_LineAx3DerTresh');
            case '3rd Derivative'
               disp('Plotting 3dTresh')
               line(app.ax3, [1 length(app.yDataFilt)].*app.fileSI, -[app.der3Tresh.Value app.der3Tresh.Value], ...
                  'Color', [0.6 0.6 0.6], 'linestyle', '-.','DisplayName','der3Tresh','Tag','TreshDpndnt_LineAx3DerTresh');
         end

         %Plot substracted yDataFilt
         plot(app.ax3, (1:length(SubstryDataFilt)).*app.fileSI,SubstryDataFilt,'Color',[0.4 0.4 0.4],'DisplayName','yData','Tag','PlotAx3yDataFilt');
         hold(app.ax3,'on')

         switch app.ButtonGroup.SelectedObject.Text %plot ax3 derivative or 3rd der, depending on chosen method
            case '1st Derivative'

               plot(app.ax3, (1:length(dDownOnly))'.*app.fileSI,dDownOnly,'Color', [0.6667 0.333 1], ...
                  'DisplayName', 'Up-only fun','LineWidth',0.6,'Tag','plotAx3dDownOnly');
            case 'MovSum'
               hold(app.ax3,'on')
               %Derivative of down Only Function in Purple
               plot(app.ax3, (1:length(filtdiffyDataFilt))'.*app.fileSI,movsum(filtdiffyDataFilt<app.DiffTresh.Value, ...
                  [0 app.DiffSumLength.Value]),'Color', [0 0.6667 0.6667], ...
                  'DisplayName', 'Movsumtreshold', 'LineWidth',0.6,'Tag','TreshDpndnt_PlotAx3dDownOnly');

               %3rd Derivative of down only in Turqoise
               %                plot(app.ax3, (1:length(d3DownOnly))'.*app.fileSI,(d3DownOnly),'Color', [0 0.6667 0.6667], ...
               %                   'DisplayName', 'Derivative of U-O fun', 'LineWidth',0.6,'Tag','TreshDpndnt_PlotAx3dDownOnly');
            case '3rd Derivative'
               plot(app.ax3, (1:length(d3DownOnly))'.*app.fileSI,(d3DownOnly),'Color', [0 0.6667 0.6667], ...
                  'DisplayName', 'Derivative of U-O fun', 'LineWidth',0.6,'Tag','TreshDpndnt_PlotAx3dDownOnly');
         end

         hold(app.ax3,'on') %plot starts and peaks
         scatter(app.ax3, app.wherePeaks.*app.fileSI,SubstryDataFilt(app.wherePeaks),[],[1 0 1],"*",'Tag','TreshDpndnt_ScatAx3wherePeaks');
         scatter(app.ax3, app.whereStarts.*app.fileSI,SubstryDataFilt(app.whereStarts),[],[0 1 0],"+",'Tag','TreshDpndnt_ScatAx3whereStarts');


         %Ax1: first remove scatter (peaks and starts) from earlier analysis
         h2=findobj(app.ax1,'Type','Scatter');
         if ~isempty(h2)
            for i=1:length(h2)
               delete(h2(i));
            end
         end

         hold(app.ax1,'on')
         scatter(app.ax1, app.wherePeaks.*app.fileSI,app.yDataFilt(app.wherePeaks),[],[1 0 1],"*",'Tag','TreshDpndnt_ScatAx1wherePeaks');
         scatter(app.ax1, app.whereStarts.*app.fileSI,app.yDataFilt(app.whereStarts),[],[0 1 0],"+",'Tag','TreshDpndnt_ScatAx1whereStarts');
         linkaxes([app.ax1 app.ax2 app.ax3],'x');
         app.ax3.YLimMode='auto';
         app.ax2.YLimMode='auto';


         if app.PlotDersAx2.Value
            delete(app.ax2.Children);
            hold(app.ax2,'on')

            switch app.ButtonGroup.SelectedObject.Text
               case '1st Derivative'
                  line(app.ax2, [1 length(app.yDataFilt)].*app.fileSI, [app.derTresh.Value app.derTresh.Value], ...
                     'Color', [0.6 0.6 0.6], 'linestyle', '-.','DisplayName','derTresh','Tag','TreshDpndnt_LineAx3DerTresh');
               case 'Mixed'
                  line(app.ax2, [1 length(app.yDataFilt)].*app.fileSI, [app.LocalSmoothRange.Value app.LocalSmoothRange.Value], ...
                     'Color', [0.6 0.6 0.6], 'linestyle', '-.','DisplayName','derMixedTresh','Tag','TreshDpndnt_LineAx3DerTresh');
               case '3rd Derivative'
                  line(app.ax2, [1 length(app.yDataFilt)].*app.fileSI, -[app.der3Tresh.Value app.der3Tresh.Value], ...
                     'Color', [0.6 0.6 0.6], 'linestyle', '-.','DisplayName','der3Tresh','Tag','TreshDpndnt_LineAx3DerTresh');
            end

            plot(app.ax2, (1:length(SubstryDataFilt)).*app.fileSI,SubstryDataFilt,'Color',[0.3 0.3 0.3],'DisplayName','yData','Tag','PlotAx2yDataFilt');
            plot(app.ax2, (1:length(d3DownOnly)).*app.fileSI,d3DownOnly,'Color',[131,249,2]/285,'DisplayName','Der3funcDownOnly','Tag','PlotAx2Der3funcDownOnly');
            plot(app.ax2, (1:length(funcDownOnly)).*app.fileSI,funcDownOnly,'Color',[0.6667 0.333 1],'DisplayName','funcDownOnly','Tag','PlotAx2funcDownOnly');
            plot(app.ax2, (1:length(dDownOnly)).*app.fileSI,dDownOnly,'Color',[0 0.6667 0.6667],'DisplayName','Der1funcDownOnly','Tag','PlotAx2Der1funcDownOnly');
         end % of plot DersAx2
         app.Lamp.Color=[0 1 0];
      end %of function

      function FitPreProcess(app)
         app.Lamp.Color=[1 0 0];
         app.ProgressGauge.Value=0;
         app.whereStarts=app.whereStarts(1:min(length(app.wherePeaks),length(app.whereStarts)));
         app.wherePeaks=app.wherePeaks(1:min(length(app.wherePeaks),length(app.whereStarts)));

         [app.wherePeaks,uidxswherepeaks,~] = unique(app.wherePeaks,'stable');
         app.whereStarts=app.whereStarts(uidxswherepeaks);

         app.FitInterval(1,1:2)=[app.Interval1Start.Value app.Interval1End.Value];
         WhithinInterval1=false(size(app.wherePeaks,1),1);
         WhithinInterval2=WhithinInterval1;
         WhithinInterval1=and(app.wherePeaks>app.FitInterval(1,1)./app.fileSI,app.wherePeaks<app.FitInterval(1,2)./app.fileSI);

         if diff([app.Interval2start.Value app.Interval2end.Value])>0 %if we have an interval 2
            app.FitInterval(2,1:2)=[app.Interval2start.Value app.Interval2end.Value];
            WhithinInterval2=and(app.wherePeaks>app.FitInterval(2,1)./app.fileSI,app.wherePeaks<app.FitInterval(2,2)./app.fileSI);
         else
            WhithinInterval2=false(size(WhithinInterval1));
         end

         app.Idxs=nan(sum((WhithinInterval1 | WhithinInterval2)),2);
         app.Idxs(:,1)=app.whereStarts(WhithinInterval1 | WhithinInterval2);
         app.Idxs(:,2)=app.wherePeaks(WhithinInterval1 | WhithinInterval2);

         %Remove wrong event identificaitons by only taking peaks that are below data median
         yDatamedFilt=medfilt1(app.yDataFilt, 1000);
         realIdxs=app.yDataFilt(app.Idxs(:,2))<yDatamedFilt(app.Idxs(:,2));
         app.Idxs=app.Idxs(realIdxs,:);

         yDatamedFilt=medfilt1(app.yDataFilt, 2000);
         realIdxs2=app.yDataFilt(app.Idxs(:,2))<yDatamedFilt(app.Idxs(:,2));
         app.Idxs=app.Idxs(realIdxs2,:);

         app.Idxs=app.Idxs(diff(app.yDataFilt(app.Idxs),[],2)<-2,:);
         app.Idxs=app.Idxs([true; diff(app.Idxs(:,2))>10],:);


         app.FitDatas.isMini=true(size(app.Idxs,1),2);
         app.FitVals=nan(size(app.Idxs,1),12);


      end

      function Fitminis(app)
         if length(app.FitDatas.IsManSelec)==size(app.Idxs,1)
            app.FitDatas.IsManSelec=app.FitDatas.IsManSelec([true; diff(app.Idxs(:,2))>10],:);
         else
            app.Idxs=app.Idxs([true; diff(app.Idxs(:,2))>10],:);
            app.FitDatas.IsManSelec=false(size(app.Idxs,1),1);
         end
         app.Idxs=app.Idxs([true; diff(app.Idxs(:,2))>10],:);
         app.FitDatas.isMini=true(size(app.Idxs));
         app.FitDatas.BadFits=false(size(app.Idxs,1),4);
         app.FitDatas.decayFits=cell(size(app.Idxs,1),1);
         app.FitDatas.riseY=cell(size(app.Idxs,1),1);


         app.TextArea.Value=sprintf('Fitting %d mEPSCs..',size(app.Idxs,1));
         app.ProgressGauge.Value=0;
         drawnow

         if isempty(app.yDataFilt)
            GoFilteryData_Tab2(app)
         end

         switch app.TriggerNextEventDropDown.Value
            case 'Very Low'
               TriggerVal_Derivative=-5;
            case 'Low'
               TriggerVal_Derivative=-10;
            case 'Medium'
               TriggerVal_Derivative=-15;
            case 'High'
               TriggerVal_Derivative=-20;
            case 'Very High'
               TriggerVal_Derivative=-35;
            case 'Ultra High'
               TriggerVal_Derivative=-50;
         end


         %select only events which peaks are at least 3pA below rough %baseline
         RoughBaseline=medfilt1(app.yDataFilt, 2500);
         %          app.Idxs=app.Idxs(app.yDataFilt(app.Idxs(:,2))-RoughBaseline(app.Idxs(:,2))<-1.75,:);
         %             if isfield(app.FitDatas,'IsManSelec')&&~isempty(app.FitDatas.IsManSelec)
         %                app.FitDatas.IsManSelec=app.FitDatas.IsManSelec(app.yDataFilt(app.Idxs(:,2))-RoughBaseline(app.Idxs(:,2))<-4,:);
         %             end
         %          app.Idxs=app.Idxs(app.yDataFilt(app.Idxs(:,2))-RoughBaseline(app.Idxs(:,2))<-4,:);


         smoothyDataAvg=smooth(app.yData,(13*100e-6)/app.fileSI,'moving');
         smoothyDataAvg=smooth(smoothyDataAvg,(19*100e-6)/app.fileSI+1,'sgolay');
         smoothyDataAvg=smooth(smoothyDataAvg,(13*100e-6)/app.fileSI+1,'moving');

         diff_yDatamovavg=smooth(diff(smoothyDataAvg),round(0.0019/app.fileSI),'sgolay');
         diff_yDatamovavg=[diff_yDatamovavg; diff_yDatamovavg(end)];


         downOnly=zeros(size(diff_yDatamovavg));
         for j = 2:length(downOnly)
            if diff_yDatamovavg(j-1) <= 0
               downOnly(j) = downOnly(j-1) + diff_yDatamovavg(j-1);
            end
         end


         for indexEPSC=1:size(app.Idxs,1)
            if app.ShowprogressBtn.Value==1
               app.ProgressGauge.Value=(indexEPSC/(size(app.Idxs,1)))*100;
               drawnow
               app.TextArea.Value=sprintf('Fitting mEPSC %d of %d',indexEPSC,size(app.Idxs,1));
               drawnow
            end


            %1. Make sure we're really at the peak
            if app.UseFindpeakstoconfirmpeakCheckBox.Value
               range=[];
               range=app.Idxs(indexEPSC,2)-4:app.Idxs(indexEPSC,2)+12;
               range=range(range>0);
               range=range(range<length(app.yData));


               [~,loc_sor] = findpeaks((app.yData(range).*-1),'SortStr','descend');

               if isempty(loc_sor)
                  range=app.Idxs(indexEPSC,2)-8:app.Idxs(indexEPSC,2)+33;
                  range=range(range>0);
                  range=range(range<length(app.yData));
                  [~,loc_sor] = findpeaks((app.yData(range).*-1),'SortStr','descend');
               end

               if ~isempty(loc_sor)
                  idx=loc_sor(1);
               else %if no peaks with both ranges, find minimum value with shorter range

                  range=[];
                  range=app.Idxs(indexEPSC,2)-3:app.Idxs(indexEPSC,2)+10;
                  range=range(range>0);
                  range=range(range<length(app.yData));

               end

            else %if findpeaks is not checked, find minimal value
               range=[];
               range=app.Idxs(indexEPSC,2)-8:app.Idxs(indexEPSC,2)+10;
               range=range(range>0);
               range=range(range<length(app.yData));
               [~,idx]=min(app.yData(range));

            end
            app.Idxs(indexEPSC,2)=range(idx);
            loc_sor=[];
            range=[];


            %window to search for start, make dependent on rough amp
            MaxRisePhaseTime=0.005;
            LocalBaseline=RoughBaseline(app.Idxs(indexEPSC,2));

            if and(app.yData(app.Idxs(indexEPSC,2))-LocalBaseline>-30,median(diff(app.Idxs(:,2)))<MaxRisePhaseTime/app.fileSI)
               MaxRisePhaseTime=0.035;
            end


            if indexEPSC>1 %if last mini is in risephasetime, shorten max risephasetime
               if app.Idxs(indexEPSC,2)-(MaxRisePhaseTime/app.fileSI)<app.Idxs(indexEPSC-1,2)
                  MaxRisePhaseTime=((app.Idxs(indexEPSC,2)-app.Idxs(indexEPSC-1,2))-1)*app.fileSI;
               end
            end


            RisePhaseXrev=fliplr(app.Idxs(indexEPSC,2)-round(MaxRisePhaseTime/app.fileSI):app.Idxs(indexEPSC,2));

            if and(app.yData(app.Idxs(indexEPSC,2))-LocalBaseline<-20,length(RisePhaseXrev)>27)
                RisePhaseXrev=RisePhaseXrev(round(13*100e-6/app.fileSI):end);

            elseif and(app.yData(app.Idxs(indexEPSC,2))-LocalBaseline<-12,length(RisePhaseXrev)>21)
                RisePhaseXrev=RisePhaseXrev(round(9*100e-6/app.fileSI):end);

            elseif length(RisePhaseXrev)>13
               RisePhaseXrev=RisePhaseXrev(round(6*100e-6/app.fileSI):end);
            elseif length(RisePhaseXrev)>9
               RisePhaseXrev=RisePhaseXrev(round(4*100e-6/app.fileSI):end);
            else
               RisePhaseXrev=RisePhaseXrev(round(2*100e-6/app.fileSI):end);
            end

            RisePhaseYrev=smoothyDataAvg(RisePhaseXrev);
            RisePhaseYrev=RisePhaseYrev-max(RisePhaseYrev);

            if any(RisePhaseYrev(round(5e-04/app.fileSI):end-round(0.002/app.fileSI))<0.92*RisePhaseYrev(1))
               IPOINTS = findchangepts(RisePhaseYrev, 'Statistic', 'linear');
               RisePhaseXrev=RisePhaseXrev(IPOINTS+1):-1:RisePhaseXrev(IPOINTS+1)-round(MaxRisePhaseTime/app.fileSI);
               RisePhaseYrev=smoothyDataAvg(RisePhaseXrev);
            end

            %          NewRiseIndex=RisePhaseXrev(find(movsum(diff(RisePhaseYrev)<0.3,[0 round(6*100e-6/app.fileSI)])>round(6*100e-6/app.fileSI),1,'first'));
            NewRiseIndex=RisePhaseXrev(find(movsum(diff(RisePhaseYrev)<0.3,[0 8])>8,1,'first'));
            
            if isempty(NewRiseIndex)
               NewRiseIndex=RisePhaseXrev(find(movsum(diff(RisePhaseYrev)<0.3,[0 8])>7,1,'first'));
            end


            if isempty(NewRiseIndex)
               NewRiseIndex=RisePhaseXrev(find(movsum(diff(RisePhaseYrev)<0.3,[0 9])>6,1,'first'));
            end

            if isempty(NewRiseIndex)
               NewRiseIndex=RisePhaseXrev(find(movsum(diff(RisePhaseYrev)<0.35,[0 8])>5,1,'first'));
            end

            if isempty(NewRiseIndex)
               NewRiseIndex=RisePhaseXrev(find(movsum(diff(RisePhaseYrev)<0.25,[0 8])>8,1,'first'));
            end


            if isempty(NewRiseIndex)
               NewRiseIndex=RisePhaseXrev(find(movsum(diff(RisePhaseYrev)<0.25,[0 8])>7,1,'first'));
            end


            if isempty(NewRiseIndex)
               NewRiseIndex=RisePhaseXrev(find(movsum(diff(RisePhaseYrev)<0.3,[0 round(6*100e-6/app.fileSI)])>round(5*100e-6/app.fileSI),1,'first'));
            end

            if isempty(NewRiseIndex)
               NewRiseIndex=RisePhaseXrev(find(movsum(diff(RisePhaseYrev)<0.4,[0 round(4*100e-6/app.fileSI)])>round(4*100e-6/app.fileSI),1,'first'));
            end


            if isempty(NewRiseIndex)
               NewRiseIndex=RisePhaseXrev(find(movsum(diff(RisePhaseYrev)<0.4,[0 round(4*100e-6/app.fileSI)])>round(4*100e-6/app.fileSI),1,'first'));
            end


            if ~isempty(NewRiseIndex)
               app.Idxs(indexEPSC,1)=NewRiseIndex;
            else
               %Lets try to find miniOnset:
               miniRiseWindowRange=app.Idxs(indexEPSC,2)-round(0.0035/app.fileSI):app.Idxs(indexEPSC,2);
               miniRiseWindowRange=miniRiseWindowRange(:);
               miniRiseWindow=app.yData(miniRiseWindowRange);

               diff_RiseWindow=diff(sgolayfilt(miniRiseWindow,2, round(ceil((0.0017/app.fileSI)/2)*2+1)));
               diff_RiseWindow=[diff_RiseWindow; diff_RiseWindow(end)]; %#ok<AGROW>

               RiseWindow_DownOnly=zeros(size(diff_RiseWindow));
               for j = 2:length(RiseWindow_DownOnly)
                  if diff_RiseWindow(j-1) <= 0
                     RiseWindow_DownOnly(j) = RiseWindow_DownOnly(j-1) + diff_RiseWindow(j-1);
                  end
               end

               OnsetRangeIdx=find(movsum(diff_RiseWindow(1:end-5),[0 round(7*1e-4/app.fileSI)])<0.75*TriggerVal_Derivative,1,'first');
               if isempty(OnsetRangeIdx)
                  OnsetRangeIdx=find(movsum(diff_RiseWindow(1:end-5),[0 round(7*1e-4/app.fileSI)])<0.6*TriggerVal_Derivative,1,'first');
               end

               if isempty(OnsetRangeIdx)
                  OnsetRangeIdx=find(RiseWindow_DownOnly(2:end-5)==0,1,'last'); %too sensitive to noise
               end

               if isempty(OnsetRangeIdx)
                  OnsetRangeIdx=find(movsum(diff_RiseWindow(1:end-5),[0 round(7*1e-4/app.fileSI)])<0.4*TriggerVal_Derivative,1,'first');
               end

               if and(~isempty(OnsetRangeIdx),OnsetRangeIdx>2)
                  [~,idx]=max(miniRiseWindow(OnsetRangeIdx-2:OnsetRangeIdx+2));
                  app.Idxs(indexEPSC,1)=miniRiseWindowRange(OnsetRangeIdx+idx)+2;
               else
                  [~,MaxRangeIdx]=max(miniRiseWindow);
                  app.Idxs(indexEPSC,1)=miniRiseWindowRange(MaxRangeIdx)+2;
               end
            end

            %if rising speed (pA/s) is too low, use other method
            if  app.yData(app.Idxs(indexEPSC,2))-app.yData(app.Idxs(indexEPSC,1))/((app.Idxs(indexEPSC,2)-app.Idxs(indexEPSC,1))*app.fileSI)<5000 %#ok<NOPRT>
               if indexEPSC>2
                  miniRiseWindowRange=max(app.Idxs(indexEPSC,2)-round(0.0035/app.fileSI),app.Idxs(indexEPSC-1,2)):app.Idxs(indexEPSC,2);
                  %rise windopw is 0.035 ms back, or starts at last mini peak
               else
                  miniRiseWindowRange=app.Idxs(indexEPSC,2)-round(0.0035/app.fileSI):app.Idxs(indexEPSC,2);
               end

               miniRiseWindowRange=miniRiseWindowRange(:);
               miniRiseWindow=app.yData(miniRiseWindowRange);

               diff_RiseWindow=diff(sgolayfilt(miniRiseWindow,2, round(ceil((0.0017/app.fileSI)/2)*2+1)));
               diff_RiseWindow=[diff_RiseWindow; diff_RiseWindow(end)]; %#ok<AGROW>

               RiseWindow_DownOnly=zeros(size(diff_RiseWindow));
               for j = 2:length(RiseWindow_DownOnly)
                  if diff_RiseWindow(j-1) <= 0
                     RiseWindow_DownOnly(j) = RiseWindow_DownOnly(j-1) + diff_RiseWindow(j-1);
                  end
               end

               OnsetRangeIdx=find(movsum(diff_RiseWindow(1:end-5),[0 round(7*1e-4/app.fileSI)])<0.75*TriggerVal_Derivative,1,'first');
               if isempty(OnsetRangeIdx)
                  OnsetRangeIdx=find(movsum(diff_RiseWindow(1:end-5),[0 round(7*1e-4/app.fileSI)])<0.6*TriggerVal_Derivative,1,'first');
               end

               if isempty(OnsetRangeIdx)
                  OnsetRangeIdx=find(RiseWindow_DownOnly(2:end-5)==0,1,'last'); %too sensitive to noise
               end

               if isempty(OnsetRangeIdx)
                  OnsetRangeIdx=find(movsum(diff_RiseWindow(1:end-5),[0 round(7*1e-4/app.fileSI)])<0.4*TriggerVal_Derivative,1,'first');
               end

               if and(~isempty(OnsetRangeIdx),OnsetRangeIdx>2)
                  [~,idx]=max(miniRiseWindow(OnsetRangeIdx-2:OnsetRangeIdx+2));
                  app.Idxs(indexEPSC,1)=miniRiseWindowRange(OnsetRangeIdx+idx)+2;
               else
                  [~,MaxRangeIdx]=max(miniRiseWindow);
                  app.Idxs(indexEPSC,1)=miniRiseWindowRange(MaxRangeIdx)+2;
               end


               %             ConfirmRange=app.Idxs(indexEPSC,1)-4:app.Idxs(indexEPSC,1)+4;
               %             ConfirmRange=ConfirmRange(ConfirmRange<(app.Idxs(indexEPSC,2)-5));
               %             [~,MaxValConfirm]=max(app.yData(ConfirmRange));
               %
               %             if ~isempty(MaxValConfirm)
               %             app.Idxs(indexEPSC,1)=ConfirmRange(MaxValConfirm);
               %             end
               %
               %             MaxValConfirm=[];
               %             ConfirmRange=[];
            end

            ConfirmRange=app.Idxs(indexEPSC,1)-7:app.Idxs(indexEPSC,1)+7;
            [~,MaxValConfirm]=max(app.yData(ConfirmRange));

            app.Idxs(indexEPSC,1)=ConfirmRange(MaxValConfirm);
            MaxValConfirm=[];
            ConfirmRange=[];


            if app.yData(app.Idxs(indexEPSC,1))<app.yData(app.Idxs(indexEPSC,2))
               RangeNew=app.Idxs(indexEPSC,1):app.Idxs(indexEPSC,2)-3;

               [~,MaxIdx]=max(app.yData(RangeNew));
               if ~isempty(MaxIdx)
                  app.Idxs(indexEPSC,1)=RangeNew(MaxIdx);
               end

               RangeNew=[];
               MaxIdx=[];
            end

            NewRiseIndex=[];


            % find index of maximum fit range
            decayEndIdx=[];
            decayStartIdx=[];
            decayEndGrandIdx=[];
            FitCurdecayWindow=app.FitdecayWindow.Value;


            if abs(app.yData(app.Idxs(indexEPSC,2))-app.yData(app.Idxs(indexEPSC,1)))<10
               FitCurdecayWindow=app.FitdecayWindow.Value*0.7;
            elseif abs(app.yData(app.Idxs(indexEPSC,2))-app.yData(app.Idxs(indexEPSC,1)))<17
               FitCurdecayWindow=app.FitdecayWindow.Value*0.8;
            elseif abs(app.yData(app.Idxs(indexEPSC,2))-app.yData(app.Idxs(indexEPSC,1)))<25
               FitCurdecayWindow=app.FitdecayWindow.Value*0.9;
            end

            %if next event is within FitCurdecayWindow, reduce
            %windowlength

            if indexEPSC<size(app.Idxs,1)
               if app.Idxs(indexEPSC,2)+(FitCurdecayWindow/app.fileSI)>app.Idxs(indexEPSC+1,1)
                  FitCurdecayWindowNew=(app.Idxs(indexEPSC+1,1)-app.Idxs(indexEPSC,2)-1)*app.fileSI;
                  if FitCurdecayWindowNew>0
                     FitCurdecayWindow=FitCurdecayWindowNew;
                  end
               end
            end


            % if decayfitwindow is larger than whats left in trace, use
            % end of trace
            if (app.Idxs(indexEPSC,2)+(FitCurdecayWindow/app.fileSI))>length(app.yDataFilt)
               decayEndGrandIdx=length(app.yDataFilt);
            else %use decayFitWindow (=max range to find end index)
               decayEndGrandIdx=app.Idxs(indexEPSC,2)+(FitCurdecayWindow/app.fileSI);
            end

            decayY=app.yData(app.Idxs(indexEPSC,2):decayEndGrandIdx);

            %subtract baseline before mini onset
            decayY=decayY-mean(app.yDataFilt(app.Idxs(indexEPSC,1)-4:app.Idxs(indexEPSC,1)));
            decayY=decayY(:);


            app.FitVals(indexEPSC,1)=mean(app.yDataFilt(app.Idxs(indexEPSC,1)-4:app.Idxs(indexEPSC,1)));%local baseline = app.FitVals(:,1)
            app.FitVals(indexEPSC,2)=app.yData(app.Idxs(indexEPSC,2))-app.FitVals(indexEPSC,1); %Amplitude = app.FitVals(:,2)
            app.FitDatas.riseY{indexEPSC}=app.yData(app.Idxs(indexEPSC,1):app.Idxs(indexEPSC,2))-app.FitVals(indexEPSC,1);

            if or(abs(app.FitVals(indexEPSC,2))<abs(app.MinAmp.Value), abs(app.FitVals(indexEPSC,2))>abs(app.MaxAmp.Value))
               app.FitDatas.isMini(indexEPSC,2)=false; %Dont fit if amp < minAmp or amp>MaxAmp
            end

            %             if onset is lower than peak than something is really wrong
            if app.yData(app.Idxs(indexEPSC,2))>app.yData(app.Idxs(indexEPSC,1))
               app.FitDatas.isMini(indexEPSC,1)=false;
               continue
            end


            MaxSumIdx=[];
            RangeIdxMax=[];
            rangeIdxs=[];
            downOnlyCurDecayStart=[];

            downOnlyCurDecay=downOnly(app.Idxs(indexEPSC,2):decayEndGrandIdx);

            if ~downOnlyCurDecay(1)==0
               downOnlyCurDecayStart=find(downOnlyCurDecay==0,1,'first');
            else
               downOnlyCurDecayStart=1;
            end


            TriggerVal_Derivative=(TriggerVal_Derivative*100e-6)/app.fileSI;
            TriggerVal_DownOnly=round(TriggerVal_Derivative*2);

            sumDownonly_CurDecay=movsum(downOnlyCurDecay(downOnlyCurDecayStart:end),[round(4*1e-4/app.fileSI) 0]);
            MaxSumIdx=find(sumDownonly_CurDecay<TriggerVal_DownOnly,1,'first')-downOnlyCurDecayStart; %where trailing movsum of Downonly is first below -3.5
            NextRiseIdx=find(movsum(diff(decayY),[round(6*1e-4/app.fileSI) 0])<TriggerVal_Derivative,1,'first')-round(3*1e-4/app.fileSI); %where trailing sum of derivative is <-8

            if ~app.SumDerivativeCheckBox.Value
               NextRiseIdx=[];
            end

            if ~app.SumDownOnlyCheckBox.Value
               MaxSumIdx=[];
            end

            if and(~isempty(NextRiseIdx),~isempty(MaxSumIdx))
               MaxSumIdx=min(NextRiseIdx,MaxSumIdx);
            elseif and(~isempty(NextRiseIdx),isempty(MaxSumIdx))
               MaxSumIdx=NextRiseIdx;
            end

            if ~isempty(MaxSumIdx)
               %new event started probably earlier, so find max value
               %of yData before event
               MaxSumIdx=min(MaxSumIdx,length(decayY));
               rangeIdxs=MaxSumIdx-15:MaxSumIdx;
               rangeIdxs=rangeIdxs(rangeIdxs>0);
               [~,RangeIdxMax]=max(decayY(rangeIdxs));
               MaxSumIdx=rangeIdxs(RangeIdxMax);
               % terminate decayY before new event
            else
               MaxSumIdx=numel(decayY);
            end


            decayY=decayY(1:MaxSumIdx);
            MaxSumIdx=[];
            NextRiseIdx=[];

            if length(decayY)<5
               app.FitDatas.isMini(indexEPSC,2)=false;
               continue
            end


            if app.SgolaySpan.Value>length(decayY)
               CurSpan=length(decayY)-1;
            elseif ceil(app.SgolaySpan.Value*length(decayY))<3
               CurSpan=2.000001/length(decayY);
            else
               CurSpan=app.SgolaySpan.Value;
            end
            decayY_smooth=smooth(decayY, CurSpan, 'sgolay');


            AmpRecov=[0.97 0.03];

            if or(isempty(decayY)||length(decayY)<5,~any(decayY>(AmpRecov(1)*decayY(1))))%no recovery
               app.FitDatas.isMini(indexEPSC,2)=false; %.. again
               continue
            end

            decayStartIdx=find(decayY_smooth>(AmpRecov(1)*decayY(1)),1,'first'); %find idx
            decayEndIdx=find(movsum(decayY_smooth>(AmpRecov(2)*(min(decayY))),2)==2,1,'first')-1; %find 1st instance where recovery is AmpRecov(2) for at least 2 datapoints

            if isempty(decayStartIdx)
               decayStartIdx=3;
            end

            if isempty(decayEndIdx)
               if any(decayY_smooth>((AmpRecov(2))*decayY_smooth(1)))
                  decayEndIdx=find(decayY_smooth>((AmpRecov(2))*decayY_smooth(1)),1,'first');
                  app.FitVals(indexEPSC,14)=AmpRecov(2);
                  %                elseif any(decayY_smooth>((AmpRecov(2)+0.05)*decayY_smooth(1)))
                  %                   decayEndIdx=find(decayY_smooth>((AmpRecov(2)+0.05)*decayY_smooth(1)),1,'first');
                  %                   app.FitVals(indexEPSC,14)=AmpRecov(2)+0.05;
                  %                elseif any(decayY_smooth>((AmpRecov(2)+0.1)*decayY_smooth(1)))
                  %                   decayEndIdx=find(decayY_smooth>((AmpRecov(2)+0.1)*decayY_smooth(1)),1,'first');
                  %                   app.FitVals(indexEPSC,14)=AmpRecov(2)+0.1;
                  %                elseif any(decayY_smooth>((AmpRecov(2)+0.15)*decayY_smooth(1)))
                  %                   decayEndIdx=find(decayY_smooth>((AmpRecov(2)+0.15)*decayY_smooth(1)),1,'first');
                  %                   app.FitVals(indexEPSC,14)=AmpRecov(2)+0.15;
                  %                elseif any(decayY_smooth>((AmpRecov(2)+0.2)*decayY_smooth(1)))
                  %                   decayEndIdx=find(decayY_smooth>((AmpRecov(2)+0.2)*decayY_smooth(1)),1,'first');
                  %                   app.FitVals(indexEPSC,14)=AmpRecov(2)+0.2;
               end
            else
               app.FitVals(indexEPSC,14)=AmpRecov(2);%if initial decayEndIdx was not empty
            end

            if ~isempty(decayEndIdx)
               decayY=decayY(decayStartIdx:decayEndIdx);
               decayY_smooth=decayY_smooth(decayStartIdx:decayEndIdx);
            end

            app.FitVals(indexEPSC,11)=length(decayY); %DecayFitLength
            app.FitVals(indexEPSC,13)=decayStartIdx;


            if numel(decayY)>5
               decayX=(1:length(decayY)).*app.fileSI;

               if app.FitSmoothData.Value
                  decayY=decayY_smooth(:);
               else
                  decayY=decayY(:);
               end

               decayX=decayX(:);

               app.FitVals(indexEPSC,6)=(sum(app.FitDatas.riseY{indexEPSC})+sum(decayY_smooth))*-app.fileSI; %app.FitVals 6 = charge
               pars0 = [decayY(1) 0.002 mean(decayY_smooth(end-2:end))];


               switch app.FitMethodDropDown.Value
                  case 'Fit NLLS'
                     opts=fitoptions('Method','nonlinearleastsquares');


                     if app.RobustmethodCheckBox.Value
                        opts.Robust='LAR';
                     else
                        opts.Robust='Off';
                     end


                     opts.StartPoint=pars0;
                     %                      opts.Lower=[1.1*decayY(1) 0.0001 decayY(end)-0.1*abs(app.FitVals(indexEPSC,2))];
                     %                      opts.Upper=[0.9*decayY(1) 0.05 decayY(end)+0.1*abs(app.FitVals(indexEPSC,2))];

                     opts.Lower=[1.10*decayY(1) 0.0001 0.9*decayY(1)];
                     %                      opts.Upper=[0.90*decayY(1) 0.05 mean(decayY(end-3:end))+0.15*abs(app.FitVals(indexEPSC,2))];
                     opts.Upper=[5 0.05 500];

                     if any(opts.Lower>opts.Upper)
                        opts.Lower=opts.Lower-5;
                        opts.Upper=opts.Upper+5;
                     end


                     opts.Weights=ones(size(decayY));
                     %                      opts.Weights(1:min(20,length(decayY)))=linspace(30,1,min(20,length(decayY)))';
                     opts.Weights(1)=50;

                     %                      dbstop if error


                     try
                        [fit0bj,gof]=fit(decayX, decayY,'a*exp(-x/b)+c',opts);
                     catch
                        fprintf('error encountered index:%d\n', indexEPSC);
                        app.FitVals(indexEPSC,11)=NaN; %DecayFitLength
                        app.FitVals(indexEPSC,13)=NaN;
                        app.FitDatas.isMini(indexEPSC,2)=false; %did not fit, so no miniselection based on kinetics
                        continue

                     end


                     app.FitVals(indexEPSC,3:5)=coeffvalues(fit0bj);
                     residuals=decayY-feval(fit0bj,decayX);
                     [~, Rsq_adj]=CalcRsq(decayY,residuals,coeffvalues(fit0bj));
                     app.FitDatas.decayFits{indexEPSC}=[(app.Idxs(indexEPSC,2)+decayStartIdx:(app.Idxs(indexEPSC,2)+decayStartIdx+length(decayY)-1))'.*app.fileSI feval(fit0bj,decayX)+app.FitVals(indexEPSC,1)];
                     app.FitVals(indexEPSC,9)=Rsq_adj;
                  case 'fminsearch' %doenst work dont use
                     expfun = @(b,decayX) b(2)+pars0(1).* exp(b(1) .* -decayX);             % Objective Funciton
                     SSECF = @(b) sum((decayY - expfun(b,decayX)).^2);           % Sum-Squared-Error Cost Function
                     [b_1, sse_1] = fminsearch(SSECF, pars0(2:3)); %#ok<ASGLU>
                     app.FitDatas.decayFits{indexEPSC}=[(app.Idxs(indexEPSC,2)+decayStartIdx:(app.Idxs(indexEPSC,2)+decayStartIdx+length(decayY)-1))'.*app.fileSI b_1(2)+pars0(1).* exp(b_1(1) .* -decayX)+app.FitVals(indexEPSC,1)];
                     app.FitVals(indexEPSC,4:5)=b_1;
                     app.FitVals(indexEPSC,4)=(1/app.FitVals(indexEPSC,4));

                  case 'lsqcurvefit'

                     lowerBound=[1.2*decayY(1) 0.0001 -0.6*abs(app.FitVals(indexEPSC,2))];
                     upperBound=[0.8*decayY(1) 0.05 0.15*abs(app.FitVals(indexEPSC,2))];

                     lsqOpts=optimoptions('lsqcurvefit');

                     lsqOpts.FunctionTolerance=1e-9;
                     lsqOpts.MaxIterations=600;
                     lsqOpts.StepTolerance=1e-10;
                     lsqOpts.OptimalityTolerance=1e-10;
                     lsqOpts.Display='off';
                     lsqOpts.Diagnostics='off';
                     lsqOpts.DiffMaxChange;
                     lsqOpts.DiffMinChange;

                     F = @(x,decayX)x(1)*exp(-decayX/x(2)) + x(3);
                     [lsq1.xfunc,lsq1.resnorm,lsq1.residual,lsq1.exitflag,lsq1.output,lsq1.lambda]=lsqcurvefit(F, pars0, decayX, decayY,lowerBound,upperBound,lsqOpts);
                     app.FitVals(indexEPSC,3:5)=lsq1.xfunc;
                     app.FitDatas.decayFits{indexEPSC,1}=[(app.Idxs(indexEPSC,2)+decayStartIdx:(app.Idxs(indexEPSC,2)+decayStartIdx+length(decayY)-1))'.*app.fileSI F(lsq1.xfunc,decayX)+app.FitVals(indexEPSC,1)];
                     [~, app.FitVals(indexEPSC,9)]=CalcRsq(decayY,lsq1.residual,lsq1.xfunc);
               end
            else
               app.FitDatas.isMini(indexEPSC,2)=false; %did not fit, so no miniselection based on kinetics
            end
         end
         app.Lamp.Color=[0 1 0];
      end


      function FitPostProces(app)


         app.FitDatas.isMini(~app.FitDatas.isMini(:,1),2)=app.FitDatas.isMini(~app.FitDatas.isMini(:,1),1); %if not a mini in col1 than not in col2

         if isempty(app.FitDatas.isMini) %we dont have any minis
            app.FitVals=nan(1,9);
            app.FitDatas.decayFits={};
            app.Idxs=[];
            app.FitDatas.isMini=[];
            app.FitDatas.BadFits=[];
         else
            app.FitDatas.decayFits=app.FitDatas.decayFits(:);

            if size(app.Idxs,1)>size(app.FitDatas.isMini,1)
               app.FitDatas.isMini(end+1:size(app.Idxs,1),:)=deal(true);
               app.FitDatas.isMini(end+1:size(app.Idxs,1),2)=deal(false);
            elseif size(app.Idxs,1)<size(app.FitDatas.isMini,1)
               app.FitDatas.isMini=app.FitDatas.isMini(1:size(app.Idxs,1),:);
            end

            if size(app.FitVals,1)>size(app.Idxs,1)
               app.FitVals=app.FitVals(1:size(app.Idxs,1),:);
            elseif size(app.FitVals,1)<size(app.Idxs,1)
               app.FitVals(end+1:size(app.Idxs,1),:)=deal(nan);
            end


            if size(app.Idxs,1)<numel(app.FitDatas.decayFits)
               app.FitDatas.decayFits=app.FitDatas.decayFits(1:size(app.Idxs,1));
            end

            app.FitVals(:,10)=abs(app.FitVals(:,3)-app.FitVals(:,2))./abs(app.FitVals(:,2)); %normalised diff between measured amp and fitted amp

            app.FitDatas.BadFits=false(length(app.FitDatas.isMini),4); %Generate BadFits logical variable
            app.FitDatas.BadFits(:,1)=app.FitVals(:,4)>app.MaxDecayTau.Value/1000; %Badfits1=fitted decay time too long
            app.FitDatas.BadFits(:,2)=app.FitVals(:,10)>0.30; %bad fit if fitted amp deviates more than 30% from real amp
            app.FitDatas.BadFits(:,3)=app.FitVals(:,11)<5; % badfit if fitlength is less than 5
            app.FitDatas.BadFits(:,4)=app.FitVals(:,9)<app.MinRsqSpinner.Value;   %badfit if Rsq_adj value isnt reached
            app.FitVals(:,8)=logical(any(app.FitDatas.BadFits,2));   %app.FitVals(:,8)==BadFit(any)
            app.FitVals(:,7)=diff(app.Idxs(1:end,:),[],2).*app.fileSI; %RiseTime

            if isfield(app.FitDatas,'IsManSelec')&&~isempty(app.FitDatas.IsManSelec)
               if size(app.FitDatas.IsManSelec,1)==size(app.FitVals,1)
                  app.FitVals(:,12)=app.FitDatas.IsManSelec;
               else
                  disp('Manual Selection column size differs, cannot add')
               end
            else
               app.FitVals(:,12)=false(size(app.FitVals,1),1); %12= manually selected or not
            end

         end
         app.TextArea.Value=sprintf('Finished fitting %d minis',size(app.Idxs,1));
         drawnow
      end


      function  GoMoveXback(app,axhand,XsliderVal)
         if (axhand.XLim(1)-XsliderVal)<=0
            axhand.XLim=[0 XsliderVal];
         else
            axhand.XLim=axhand.XLim-XsliderVal;
         end
      end

      function GoMoveXForw(app,axhand,XsliderVal) %#ok<*INUSL>
         axhand.XLim=axhand.XLim+0.9*XsliderVal;
      end


      %
      function LoadDataStructData(app,fileIdx)
         disp('loading');
         cd(app.LoaddataStructButton.UserData.matpathName);
         MATOBJ=app.LoaddataStructButton.UserData.FileObj;
         ds=MATOBJ.dataStruct(1,fileIdx);

         app.MiniFigure.UserData.filePath=fullfile(app.LoaddataStructButton.UserData.matpathName,ds.filename);
         app.yData=ds.yData;
         app.CurrTrace=ds.filename;

         if isfield(ds,'yDataFilt')&&~isempty(ds.yDataFilt)
            app.yDataFilt=ds.yDataFilt;
         else
            app.yDataFilt=[];
         end
         app.fileSI=ds.fileSI;
         app.FitInterval=ds.FitInterval;
         app.Interval1Start.Value=app.FitInterval(1,1);
         app.Interval1End.Value=app.FitInterval(1,2);
         app.Interval2start.Value=app.FitInterval(2,1);
         app.Interval2end.Value=app.FitInterval(2,2);

         app.Idxs=ds.Idxs;
         app.FitDatas.isMini=ds.isMini;
         app.FitDatas.BadFits=ds.BadFits;
         app.FitVals=ds.FitVals;
         app.FitDatas.decayFits=ds.decayFits;

         if isfield(ds,'FitParameters')

            if and(app.CopyTriggervalCheckBox.Value,~isempty(ds.FitParameters.TriggerVal))
               app.TriggerNextEventDropDown.Value=ds.FitParameters.TriggerVal;
            end

            if and(app.CopyFitSmoothValCheckBox.Value,~isempty(ds.FitParameters.FitSmooth))
               app.FitSmoothData.Value=ds.FitParameters.FitSmooth;
            end

            if and(app.CopyDecaySearchWindowCheckBox.Value,~isempty(ds.FitParameters.DecaySearchWindow))
               app.FitdecayWindow.Value=ds.FitParameters.DecaySearchWindow;
            end

            if and(app.CopySGspanCheckBox.Value,~isempty(ds.FitParameters.SgolaySpan))
               app.SgolaySpan.Value=ds.FitParameters.SgolaySpan;
            end

            if and(app.CopyConstraintsCheckBox.Value,~isempty(ds.FitParameters.Constraints))
               app.MinAmp.Value=ds.FitParameters.Constraints(1,1);
               app.MaxAmp.Value=ds.FitParameters.Constraints(1,2);
               app.MinDecayTau.Value=ds.FitParameters.Constraints(2,1);
               app.MaxDecayTau.Value=ds.FitParameters.Constraints(2,2);
            end
         end

         %Check values; some eventstarts are NaN, change to zero (will
         %be changed later)
         app.Idxs(isnan(app.Idxs(:,1)),1)=1;
         app.FitDatas.isMini=app.FitDatas.isMini(~any([isnan(app.Idxs(:,1)) isnan(app.Idxs(:,2))],2),:);
         app.FitDatas.BadFits=app.FitDatas.BadFits(~any([isnan(app.Idxs(:,1)) isnan(app.Idxs(:,2))],2),:);
         app.FitVals=app.FitVals(~any([isnan(app.Idxs(:,1)) isnan(app.Idxs(:,2))],2),:);
         app.FitDatas.decayFits=app.FitDatas.decayFits(~any([isnan(app.Idxs(:,1)) isnan(app.Idxs(:,2))],2),:);
         app.Idxs=app.Idxs(~any([isnan(app.Idxs(:,1)) isnan(app.Idxs(:,2))],2),:);


         if isempty(app.yDataFilt)
            switch app.Filter1Selection.Value
               case 'None' % no filtering
                  app.yDataFilt = app.yData;
               case 'Moving Average' % window filtering
                  app.yDataFilt = movingAverage(app.yData, app.Filter1Length.Value);
               case 'Median Filter' % median filtering
                  app.yDataFilt = medfilt1(app.yData, app.Filter1Length.Value);
               case 'Sgolay' % savitsky-golay
                  app.yDataFilt = sgolayfilt(app.yData, app.Order1Order.Value, app.Filter1Length.Value);
               case 'Low Pass' % Lowpass
                  app.yDataFilt=lowpass(app.yData,app.Filter1fq_kHz.Value*1000,1/app.fileSI,'ImpulseResponse','iir','Steepness',0.82);
               case 'Lowess' % Local regression using weighted linear least squares and a 1st degree polynomial model.
                  app.yDataFilt = smooth(app.yData, app.Filter1Length.Value,'lowess');
               case 'Loess' % Local regression using weighted linear least squares and a 2nd degree polynomial model.
                  app.yDataFilt = smooth(app.yData, app.Filter1Length.Value,'loess');
            end %of switch
            yDataFilt2=app.yDataFilt;
            switch app.Filter2Selection.Value    % second filter
               case 'None' % no filtering
                  app.yDataFilt = yDataFilt2;
               case 'Moving Average' % window filtering
                  app.yDataFilt = movingAverage(yDataFilt2, app.Filter2Length.Value);
               case 'Median Filter' % median filtering
                  app.yDataFilt = medfilt1(yDataFilt2, app.Filter2Length.Value);
               case 'Sgolay' % savitsky-golay
                  app.yDataFilt = sgolayfilt(yDataFilt2, app.Filter2Order.Value, app.Filter2Length.Value);
               case 'Low Pass' % Lowpass
                  app.yDataFilt=lowpass(yDataFilt2,app.Filter2fq_kHz.Value*1000,1/app.fileSI,'ImpulseResponse','iir','Steepness',0.82);
               case 'Lowess' % Local regression using weighted linear least squares and a 1st degree polynomial model.
                  app.yDataFilt = smooth(yDataFilt2, app.Filter2Length.Value,'lowess');
               case 'Loess' % Local regression using weighted linear least squares and a 2nd degree polynomial model.
                  app.yDataFilt = smooth(yDataFilt2, app.Filter2Length.Value,'loess');
            end %of switch
         end % of isempty(ydatafilt)

         %delete all ax4 data
         delete(app.ax4);
         app.ax4 = uiaxes(app.Tab2Grid1,'FontName','PT Sans','Tag','TagAx4');
         xlabel(app.ax4, 'Time (s)')
         ylabel(app.ax4, 'I (pA)')
         app.ax4.Layout.Row = 1;
         app.ax4.Layout.Column = 1;
         app.ax4.UserData.ClickedPoints=[];
         app.ax4.UserData.Remarks=[];

         app.Interval1EditField.BackgroundColor=[1 1 1];
         app.Interval2EditField.BackgroundColor=[1 1 1];


         hold(app.ax4,'on')
         plot(app.ax4,(1:length(app.yData)).*app.fileSI, sgolayfilt(app.yData,2,ceil(((9*100e-6)/app.fileSI)/2)*2+1),'Color',[0.75 0.75 0.75],'LineWidth', ...
            app.MiniFigure.UserData.tailoredLineWidth,'Tag','PlotAx4origyData');
         plot(app.ax4,(1:length(app.yDataFilt)).*app.fileSI,app.yDataFilt,'Color',[0.3 0.35 0.35],'LineWidth',app.TracesLineWidthSpinner.Value,'Tag','PlotAx4yDataFilt');
         scatter(app.ax4, app.Idxs(app.FitDatas.isMini(:,1),2).*app.fileSI,app.yData(app.Idxs(app.FitDatas.isMini(:,1),2)), app.MarkerSizeSpinner.Value, ...
            [0.0 0.95 0.0],"o",'LineWidth',1.2,'Tag','ScatFittedMinis');

         app.ax4.UserData.CurrTrace=ds.filename;
         if isfield(ds,'Remarks')
            app.ax4.UserData.Remarks=ds.Remarks;
         end

         if app.ShowFitsCheckBox.Value
            visibilityStr='on';
         else
            visibilityStr='off';
         end


         app.ax4.XLim=[app.Interval1Start.Value app.Interval1Start.Value+app.Tab2_dXSlider.Value];
         %             app.ax4.XLim=[app.Interval1Start.Value app.Interval1End.Value];
         app.ax4.YLim=[median(app.yData)-0.66666*app.Tab2_dYSlider.Value median(app.yData)+0.3333*app.Tab2_dYSlider.Value];


         app.TextArea.Value=sprintf('Trace %d of %d;  %d minis in %.2f + %.2f sec',app.LoaddataStructButton.UserData.fileIdx,...
            app.LoaddataStructButton.UserData.dataStructLength, sum(app.FitDatas.isMini(:,1)), abs(diff(app.FitInterval(1,:))),abs(diff(app.FitInterval(2,:))));


         if isfield(ds,'Updated')
            if ~isempty(ds.Updated)
               app.TextArea_2.Value=ds.Updated;
               app.TextArea.Value{end+1}=sprintf('Last updated on %s',ds.Updated);
            else
               app.TextArea_2.Value={'not updated'};
            end
         end


         if app.LoaddataStructButton.UserData.fileIdx==1
            app.GoPreviousfileIdxBtn.FontColor=[0.8 0.8 0.8];
            app.GoFirstfileIdxBtn.FontColor=[0.8 0.8 0.8];
         else
            app.GoPreviousfileIdxBtn.FontColor=[0 0 0];
            app.GoFirstfileIdxBtn.FontColor=[0 0 0];
         end


         if app.LoaddataStructButton.UserData.fileIdx==app.LoaddataStructButton.UserData.dataStructLength
            app.GoNextfileIdxBtn.FontColor=[0.8 0.8 0.8];
            app.GoLastfileIdxBtn.FontColor=[0.8 0.8 0.8];

         else
            app.GoNextfileIdxBtn.FontColor=[0 0 0];
            app.GoLastfileIdxBtn.FontColor=[0 0 0];
         end


         if app.ShowFitsCheckBox.Value
            GoShowFits(app)
         end


      end


      function GoFilteryData_Tab2(app)
         switch app.Filter1Selection.Value
            case 'None' % no filtering
               app.yDataFilt = app.yData;
            case 'Moving Average' % window filtering
               app.yDataFilt = movingAverage(app.yData, app.Filter1Length.Value);
            case 'Median Filter' % median filtering
               app.yDataFilt = medfilt1(app.yData, app.Filter1Length.Value);
            case 'Sgolay' % savitsky-golay
               app.yDataFilt = sgolayfilt(app.yData, app.Order1Order.Value, app.Filter1Length.Value);
            case 'Low Pass' % Lowpass
               app.yDataFilt=lowpass(app.yData,app.Filter1fq_kHz.Value*1000,1/app.fileSI,'ImpulseResponse','iir','Steepness',0.82);
            case 'Lowess' % Local regression using weighted linear least squares and a 1st degree polynomial model.
               app.yDataFilt = smooth(app.yData, app.Filter1Length.Value,'lowess');
            case 'Loess' % Local regression using weighted linear least squares and a 2nd degree polynomial model.
               app.yDataFilt = smooth(app.yData, app.Filter1Length.Value,'loess');
         end
         yDataFilt2=app.yDataFilt;

         % second filter
         switch app.Filter2Selection.Value
            case 'None' % no filtering
               app.yDataFilt = yDataFilt2;
            case 'Moving Average' % window filtering
               app.yDataFilt = movingAverage(yDataFilt2, app.Filter2Length.Value);
            case 'Median Filter' % median filtering
               app.yDataFilt = medfilt1(yDataFilt2, app.Filter2Length.Value);
            case 'Sgolay' % savitsky-golay
               app.yDataFilt = sgolayfilt(yDataFilt2, app.Filter2Order.Value, app.Filter2Length.Value);
            case 'Low Pass' % Lowpass
               app.yDataFilt=lowpass(yDataFilt2,app.Filter2fq_kHz.Value*1000,1/app.fileSI,'ImpulseResponse','iir','Steepness',0.82);
            case 'Lowess' % Local regression using weighted linear least squares and a 1st degree polynomial model.
               app.yDataFilt = smooth(yDataFilt2, app.Filter2Length.Value,'lowess');
            case 'Loess' % Local regression using weighted linear least squares and a 2nd degree polynomial model.
               app.yDataFilt = smooth(yDataFilt2, app.Filter2Length.Value,'loess');
         end
         disp('finished loading')
      end


   end


   methods (Access = private)
      function mycallback(app,src,event) %no idea what this is doing here so Ill just leave it for now
         if event.SelectedOption
            app.ax4.UserData.ConfirmClear=1;
            display(event.SelectedOption);
         end
      end
   end

   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function MakeInitialisationVars(app)

         scrnsz=get(0,'screensize');
         if all(scrnsz==[1 1 2560 1440])
            app.MiniFigure.Position=[26 1 2477 1336];
            set(0, 'DefaultLineLineWidth', 1.2);
            app.MiniFigure.UserData.tailoredLineWidth=1.05;
         elseif scrnsz(3)>2000
            app.MiniFigure.Position(3:4)=round(scrnsz(3:4)/1.15);
            app.MiniFigure.UserData.tailoredLineWidth=0.8;
         else
            app.MiniFigure.Position=[20 20 1584 1046];
            set(0, 'DefaultLineLineWidth', 0.6)
            app.MiniFigure.UserData.tailoredLineWidth=0.6;
         end

         set( 0, 'DefaultUicontrolFontName','PT Sans' );

         %             delete(app.ax1);
         app.ax1 = uiaxes(app.Tab1Grid1);
         app.ax1.Layout.Row = 1;
         app.ax1.Layout.Column = 1;
         xlabel(app.ax1, 'Time(s)')
         ylabel(app.ax1, 'I (pA)')
         app.ax1.FontName = 'PT Sans';
         app.ax1.ColorOrder = [0 0.447 0.741;0.85 0.325 0.098;0.929 0.694 0.125;0.494 0.184 0.556;0.466 0.674 0.188;0.301 0.745 0.933;0.635 0.078 0.184];

         app.ax2 = uiaxes(app.Tab1Grid1);
         app.ax2.Layout.Row = 2;
         app.ax2.Layout.Column = 1;
         xlabel(app.ax2, 'Time (s)')
         ylabel(app.ax2, 'dI/dt (pA/ms)')
         %             app.ax2.PlotBoxAspectRatio = [4.03977272727273 1 1];
         app.ax2.FontName = 'PT Sans';
         app.ax2.ColorOrder = [0 0.447 0.741;0.85 0.325 0.098;0.929 0.694 0.125;0.494 0.184 0.556;0.466 0.674 0.188;0.301 0.745 0.933;0.635 0.078 0.184];


         %             delete(app.ax3);
         app.ax3 = uiaxes(app.Tab1Grid1);
         app.ax3.Layout.Row = 3;
         app.ax3.Layout.Column = 1;
         xlabel(app.ax3, 'Time(s)')
         ylabel(app.ax3, 'I (pA)')
         app.ax3.FontName = 'PT Sans';
         app.ax3.ColorOrder = [0 0.447 0.741;0.85 0.325 0.098;0.929 0.694 0.125;0.494 0.184 0.556;0.466 0.674 0.188;0.301 0.745 0.933;0.635 0.078 0.184];

         delete(app.ax4);
         app.ax4 = uiaxes(app.Tab2Grid1);
         xlabel(app.ax4, 'Time (s)')
         ylabel(app.ax4, 'I (pA)')
         zlabel(app.ax4, 'Z')
         app.ax4.FontName = 'PT Sans';
         app.ax4.ColorOrder = [0 0.447 0.741;0.85 0.325 0.098;0.929 0.694 0.125;0.494 0.184 0.556;0.466 0.674 0.188;0.301 0.745 0.933;0.635 0.078 0.184];
         app.ax4.Layout.Row = 1;
         app.ax4.Layout.Column = 1;
         app.ax4.Tag = 'TagAx4';
         app.ax4.UserData.ClickedPoints=[];
         app.ax4.UserData.AddedMiniCoor=[];
         app.ax2.PlotBoxAspectRatioMode='auto';
         app.ax4.UserData.Remarks=[];

      end

      % Button pushed function: LoadAbfButton
      function GetABFfiles(app, event)

         [fileName,pathName] = uigetfile('*.abf');
         app.MiniFigure.UserData.filePath = fullfile(pathName,fileName);

         if ~isempty(nonzeros([fileName,pathName]))
            GoLoadABFfiles(app,pathName,fileName)
         end

      end

      % Button pushed function: LoadSampleDataButton
      function LoadSampleTrace(app, event)
         fileName='200526_P400L_LIP2835_Cell02_0000.abf';
         pathName='/Users/maaike/Library/Mobile Documents/com~apple~CloudDocs/P401L_SYT1/GFPvsPL/2021_ANALYSIS/2020/P400L/minis/';
         app.MiniFigure.UserData.filePath = fullfile(pathName,fileName);
         GoLoadABFfiles(app,pathName,fileName)

      end

      % Button pushed function: LoadFileListBtn
      function LoadFileList(app, event)
         extList = {'*.abf'; '*.mat'; '*.*'};
         extList = {'*.*'};

         [filename,pathName] = uigetfile(extList,'Select Data (.abf or .mat)',...
            'MultiSelect', 'on');
         if ~pathName %User cancelled
            return
         end

         if iscell(filename) %multiple files selected
            filename = sort(filename(:));
            pathNames=repmat({pathName},size(filename));
            allFiles=[pathNames filename];
         else
            allFiles={};
            allFiles{1,1}=pathName;
            allFiles{1,2}=filename;
         end

         for i=1:size(allFiles,1)
            [~,allFiles{i,2}, allFiles{i,3}]=fileparts([allFiles{i,1} allFiles{i,2}]);
         end

         if length(unique(allFiles(:,3)))>1 %
            disp('Please select fileset with uniform extension (i.e. either abf or mat, not both)');
            return
         end

         if strcmpi(allFiles{1,3},'.abf')
            curDir=pwd;
            cd(pathName)  %Set data directory
            if ~exist('Data in Progress','dir')
               mkdir('Data in Progress');
            end
            addpath('Data in Progress')

            for i=1:size(allFiles,1)
               [data,si,header] = abfload(fullfile(allFiles{i,1},[allFiles{i,2} allFiles{i,3}]));
               if ~exist('Data in Progress','dir')
                  mkdir('Data in Progress');
               end
               addpath('Data in Progress')
               save(fullfile(pathName,'Data in Progress', [allFiles{i,2},'.mat']),'-v7.3','data','si','header');
               allFiles{i,3}=regexprep(allFiles{i,2},'(?<=LIP)\d{4}','xxxx');
               if sum(contains({allFiles{:,2}},allFiles{i,2}(1:end-5),'IgnoreCase',1))>1 %#ok<CCAT1>
                  allFiles{i,3}=[allFiles{i,3} '*'];
               end
            end

            app.FilesDrop.UserData.FileType='abf';


         elseif strcmpi(allFiles{1,3},'.mat')
            if contains(pathName,'Data in Progress')
               addpath('Data in Progress')
               pathName=replace(pathName,[filesep 'Data in Progress' filesep],filesep); %Go to parent directory
               for i=1:length(allFiles)
                  allFiles{i,1}=replace(pathName,[filesep 'Data in Progress' filesep],filesep);
                  allFiles{i,3}=regexprep(allFiles{i,2},'(?<=LIP)\d{4}','xxxx');
                  if sum(contains({allFiles{:,2}},allFiles{i,2}(1:end-5),'IgnoreCase',1))>1 %#ok<CCAT1>
                     allFiles{i,3}=[allFiles{i,3} '*'];
                  end
               end
            end
            cd(pathName)
            app.FilesDrop.UserData.FileType='mat';
         end

         app.FilesDrop.Items=allFiles(:,3);
         app.FilesDrop.ItemsData=allFiles(:,2);
         app.FilesDrop.UserData.pathName=pathName;

         HaveChangedFileDrop(app, event)
         app.FilesDrop.Layout.Column = [5 14];

         %             %         if numel(app.FilesDrop.Items)==1&&strcmpi(app.FilesDrop.Value,'No data yet') %first data loaded
         %             app.FilesDrop.Items=allFiles(:,3);
         %             selectInput = true(size(allFiles(:,3)));
         %             app.FilesDrop.Value=allFiles(1,3);
         %             %         else %added data, add new entries
         %             %                 selectInput = ~ismember(allFiles(:,1),app.FilesDrop.Items);
         %             %     app.FilesDrop.Items = sort([app.FilesDrop.Items;allFiles(selectInput,1)]);
         %             %        end

      end

      % Button pushed function: ClearTraceMarkBadButton
      function ClearAndMarkBadCurrentListTrace(app, event)
         CurItemIdx=find(strcmpi(app.FilesDrop.ItemsData,app.FilesDrop.Value));

         cd(app.FilesDrop.UserData.pathName)
         if ~isfolder('BadTraces')
            mkdir('BadTraces')
         end

         delete(fullfile(app.FilesDrop.UserData.pathName,'Data in Progress',[app.FilesDrop.Value '.mat']));
         movefile(fullfile(app.FilesDrop.UserData.pathName,[app.FilesDrop.Value '.abf']), 'BadTraces',"f");
         app.FilesDrop.ItemsData(CurItemIdx)=[];
         app.FilesDrop.Items(CurItemIdx)=[];
         app.FilesDrop.Value=app.FilesDrop.ItemsData(CurItemIdx);
         HaveChangedFileDrop(app, event)
         app.FilesDrop.Layout.Column = [5 14];
      end

      % Button pushed function: ClearTraceDeleteButton
      function ClearAndDeleteCurrentListTrace(app, event)
         CurItemIdx=find(strcmpi(app.FilesDrop.ItemsData,app.FilesDrop.Value));
         delete(fullfile(app.FilesDrop.UserData.pathName,'Data in Progress',[app.FilesDrop.Value '.mat']));
         cd(app.FilesDrop.UserData.pathName)
         if ~isfolder('Delete abf files')
            mkdir('Delete abf files')
         end
         movefile(fullfile(app.FilesDrop.UserData.pathName,[app.FilesDrop.Value '.abf']), 'Delete abf files',"f");
         app.FilesDrop.ItemsData(CurItemIdx)=[];
         app.FilesDrop.Items(CurItemIdx)=[];
         app.FilesDrop.Value=app.FilesDrop.ItemsData(CurItemIdx);
         HaveChangedFileDrop(app, event)

      end

      % Button pushed function: ClearTraceButton
      function ClearCurrentListTrace(app, event)
         CurItemIdx=find(strcmpi(app.FilesDrop.ItemsData,app.FilesDrop.Value));
         if CurItemIdx==length(app.FilesDrop.ItemsData)
            nextItemIdx=1;
         else
            nextItemIdx=CurItemIdx+1;
         end

         app.FilesDrop.Value=app.FilesDrop.ItemsData(nextItemIdx);
         HaveChangedFileDrop(app, event)

         app.FilesDrop.ItemsData(CurItemIdx)=[];
         app.FilesDrop.Items(CurItemIdx)=[];
         app.FilesDrop.Value=app.FilesDrop.ItemsData(CurItemIdx);
         HaveChangedFileDrop(app, event)
      end

      % Button pushed function: LoadMatFileButton
      function GoLoadMatfiles(app, event)
         app.Lamp.Color=[1 0 0];
         load '/Users/maaike/Library/Mobile Documents/com~apple~CloudDocs/P401L_SYT1/GFPvsPL/2021_ANALYSIS/miniAnalysis.mat' dataStruct
         app.yData=dataStruct(1).data;
         app.CurrTrace=datastruct(1).filename;
         si=dataStruct(1).si;
         clearvars dataStruct
         app.LoaddataStructButton.UserData.fileIdx=[];
         app.Lamp.Color=[0 1 0];
      end

      % Button pushed function: ClearDataBtn
      function ClearAllData(app, event)
         app.MiniFigure.UserData=[];
         delete(app.ax1);
         app.ax1 = uiaxes(app.Tab1Grid1,'FontName','PT Sans','Tag','TagAx1');
         xlabel(app.ax3, 'Time(s)')
         ylabel(app.ax3, 'I (pA)')
         app.ax1.Layout.Row = 1;
         app.ax1.Layout.Column = 1;

         delete(app.ax2)
         app.ax2 = uiaxes(app.Tab1Grid1);
         xlabel(app.ax2, 'Time (s)')
         ylabel(app.ax2, 'dI/dt (pA/ms)')
         app.ax2.FontName = 'PT Sans';
         app.ax2.Layout.Row = 2;
         app.ax2.Layout.Column = 1;
         app.ax2.PlotBoxAspectRatioMode='auto';

         delete(app.ax3)
         app.ax3 = uiaxes(app.Tab1Grid1);
         xlabel(app.ax3, 'Time(s)')
         ylabel(app.ax3, 'I (pA)')
         app.ax3.FontName = 'PT Sans';
         app.ax3.Layout.Row = 3;
         app.ax3.Layout.Column = 1;


         delete(app.ax4);
         app.ax4 = uiaxes(app.Tab2Grid1,'FontName','PT Sans','Tag','TagAx4');
         xlabel(app.ax4, 'Time (s)')
         ylabel(app.ax4, 'I (pA)')
         app.ax4.Layout.Row = 1;
         app.ax4.Layout.Column = 1;
         app.ax4.UserData.ClickedPoints=[];
         app.ax4.UserData.Remarks=[];

         scrnsz=get(0,'screensize');

         if all(scrnsz==[1 1 2560 1440])
            app.MiniFigure.Position=[26 1 2477 1336];
            set(0, 'DefaultLineLineWidth', 1.2);
            app.MiniFigure.UserData.tailoredLineWidth=1.05;
         elseif scrnsz(3)>2000
            app.MiniFigure.Position(3:4)=round(scrnsz(3:4)/1.15);
            app.MiniFigure.UserData.tailoredLineWidth=0.8;
         else
            app.MiniFigure.Position=[20 20 1584 1046];
            set(0, 'DefaultLineLineWidth', 0.6)
            app.MiniFigure.UserData.tailoredLineWidth=0.6;
         end

         delete(app.FilesDrop);
         app.FilesDrop = uidropdown(app.MiniGridTopPart);
         app.FilesDrop.Items = {'No data yet'};
         app.FilesDrop.ValueChangedFcn = createCallbackFcn(app, @HaveChangedFileDrop, true);
         app.FilesDrop.FontName = 'PT Sans';
         app.FilesDrop.Layout.Row = 1;
         app.FilesDrop.Layout.Column = [5 9];
         app.FilesDrop.Value = 'No data yet';


         app.fileSI=[];

         app.yData=[];
         app.CurrTrace='';
         app.yDataFilt=[];
         app.dataDerFilt=[];

         app.whereStarts=[];
         app.wherePeaks=[];

         app.Method2Data=[];

         app.FitDatas=[];
         app.FitVals=[];
         app.Idxs=[];
         app.ManualSelectionData=[];
         app.LoaddataStructButton.UserData=[];

         app.TextArea.Value={'Happy analysing :)'};

      end

      % Button pushed function: DeleteAbfBtn
      function MoveDeleteABFfile(app, event)

         [FPath,Fname,Fext] = fileparts(app.MiniFigure.UserData.filePath);
         cd(FPath);

         if ~isfolder('Delete abf files')
            mkdir('Delete abf files')
         end
         movefile([Fname Fext], 'Delete abf files',"f");
      end

      % Value changed function: FilesDrop
      function HaveChangedFileDrop(app, event)
         if ~isempty(app.ax1.Children)
            delete(app.ax1.Children);
         end

         %             yData = maai_retrieveEphys(app.FilesDrop.Value, {'dataStruct'}, app.FilesDrop.UserData.pathName);
         %             fileSI = maai_retrieveEphys(app.FilesDrop.Value, {'si'}, app.FilesDrop.UserData.pathName)*1e-6;
         %
         if isfield(app.FilesDrop.UserData,'FileType')
            if strcmpi(app.FilesDrop.UserData.FileType,'abf')
               [app.yData,si,~] = abfload([app.FilesDrop.UserData.pathName filesep app.FilesDrop.Value '.abf']); %load data
               app.CurrTrace=app.FilesDrop.Value;
               app.fileSI=si*1e-6; %normal si units
               xData=(1:length(app.yData)).*app.fileSI;
               yData=app.yData;
               fileSI=app.fileSI;
            end
         else


            dataStruct=load([app.FilesDrop.UserData.pathName filesep app.FilesDrop.Value '.mat']);
            dataStruct=dataStruct.dataStruct;
            yData=dataStruct.yData;
            fileSI=dataStruct.fileSI;
         end

         app.ax1.UserData.CurrTrace=fullfile(app.FilesDrop.UserData.pathName,app.FilesDrop.Value);

         plot(app.ax1,(1:length(yData))*fileSI,sgolayfilt(yData,3,21),'Color',[0.0 0.333 0.8333],'LineWidth',app.MiniFigure.UserData.tailoredLineWidth,'Tag','PlottedNameDropFile')
         app.ax1.YLim=[floor((quantile(app.ax1.Children(1).YData,0.01)*1.4286)/100)*100 ceil(quantile(app.ax1.Children(1).YData,0.999)/100)*100];
         app.YLim1val.Value=round(app.ax1.YLim(1));

         if and(round(abs(diff(app.ax1.YLim)))>app.dYSlider.Limits(1),round(abs(diff(app.ax1.YLim)))<app.dYSlider.Limits(2))
            app.dYSlider.Value=round(abs(diff(app.ax1.YLim)));
         else
            app.dYSlider.Value=400;
         end
         if app.dXSlider.Value>6
            app.dXSlider.Value=5;
         end


         if (app.XLim1val.Value+app.dXSlider.Value)>(length(app.ax1.Children(1).YData)*app.fileSI)
            app.XLim1val.Value=0.1;
            app.ax1.XLim=[0.1 app.dXSlider.Value];
         end


         app.ax1.XLim=[app.XLim1val.Value app.XLim1val.Value+app.dXSlider.Value];


         app.FilesDrop.Layout.Column = [5 14];

         if ~isempty(app.ax2.Children)
            delete(app.ax2.Children);
         end

         if ~isempty(app.ax3.Children)
            delete(app.ax3.Children);
         end


      end

      % Button pushed function: MarkBadTraceBtn
      function MoveBadTraceABFfile(app, event)
         [FPath,Fname,Fext] = fileparts(app.MiniFigure.UserData.filePath);
         cd(FPath);

         if ~isfolder('BadTraces')
            mkdir('BadTraces')
         end

         movefile([Fname Fext], 'BadTraces',"f")
      end

      % Button pushed function: FilterTraceButton
      function FilterTraceButtonPushed(app, event)

         if ~isempty(app.ax4.Children)
            delete(app.ax4);
            app.ax4 = uiaxes(app.Tab2Grid1);
            xlabel(app.ax4, 'Time (s)')
            ylabel(app.ax4, 'I (pA)')
            zlabel(app.ax4, 'Z')
            app.ax4.FontName = 'PT Sans';
            app.ax4.ColorOrder = [0 0.447 0.741;0.85 0.325 0.098;0.929 0.694 0.125;0.494 0.184 0.556;0.466 0.674 0.188;0.301 0.745 0.933;0.635 0.078 0.184];
            app.ax4.Layout.Row = 1;
            app.ax4.Layout.Column = 1;
            app.ax4.Tag = 'TagAx4';
            app.ax4.UserData.ClickedPoints=[];
            app.ax4.UserData.AddPoints=[];
            app.ax4.UserData.AddedMiniCoor=[];
            app.ax4.UserData.RejectMiniCoords=[];
            app.ax4.UserData.AddFormerlyRejectedData=[];
            app.ax4.PlotBoxAspectRatioMode='auto';
            app.ax4.UserData.Remarks=[];
            app.TextAreaSaveOut.Value='';


            app.Method2Data=[];
            app.FitDatas=[]; % Description
            app.FitVals=[]; % Description
            app.Idxs=[]; % Description

            app.FitInterval=[];

            app.ManualSelectionData=struct();
         end

         if isfield(app.ax1.UserData,'CurrTrace')&&~isempty(app.ax1.UserData.CurrTrace)
            app.yData = maai_retrieveEphys(app.FilesDrop.Value, {'data'}, app.FilesDrop.UserData.pathName);
            si = maai_retrieveEphys(app.FilesDrop.Value, {'si'}, app.FilesDrop.UserData.pathName);
            app.fileSI=si*1e-6;
            app.MiniFigure.UserData.filePath=fullfile(app.FilesDrop.UserData.pathName,[app.FilesDrop.Value '.abf']);
            app.CurrTrace=app.FilesDrop.Value;
         else
            if isempty(app.yData)
               if isempty(app.ax1.Children)
                  disp('Warning, no data loaded');
               else
                  app.yData = maai_retrieveEphys(app.FilesDrop.Value, {'data'}, app.FilesDrop.UserData.pathName);
                  si = maai_retrieveEphys(app.FilesDrop.Value, {'si'}, app.FilesDrop.UserData.pathName);
                  app.fileSI=si*1e-6;
                  app.MiniFigure.UserData.filePath=fullfile(app.FilesDrop.UserData.pathName,[app.FilesDrop.Value '.abf']);
                  app.CurrTrace=app.FilesDrop.Value;
               end
            end
         end

         if isempty(app.fileSI)
            app.fileSI=si*1e-6;
         end

         xData=(1:length(app.yData)).*app.fileSI;

         fileSI_ms=app.fileSI*1e3;

         if ~isempty(app.ax1.Children)
            delete(app.ax1.Children);
         end


         % filter the raw data
         switch app.DataFilterType.Value
            case 'None' % no filtering
               app.yDataFilt = app.yData;
            case 'Moving Average' % window filtering
               app.yDataFilt = movingAverage(app.yData, app.DataFilterLength.Value);
            case 'Median Filter' % median filtering
               app.yDataFilt = medfilt1(app.yData, app.DataFilterLength.Value);
            case 'Sgolay' % savitsky-golay
               app.yDataFilt = sgolayfilt(app.yData, app.DataFilterOrder.Value, app.DataFilterLength.Value);
            case 'Low Pass' % Lowpass
               app.yDataFilt=lowpass(app.yData,app.DataLowPassHz.Value,1/app.fileSI,'ImpulseResponse','iir','Steepness',0.82);
            case 'Lowess' % Local regression using weighted linear least squares and a 1st degree polynomial model.
               app.yDataFilt = smooth(app.yData, app.DataFilterLength.Value,'lowess');
            case 'Loess' % Local regression using weighted linear least squares and a 2nd degree polynomial model.
               app.yDataFilt = smooth(app.yData, app.DataFilterLength.Value,'loess');
         end
         yDataFilt2=app.yDataFilt;


         % second filter
         switch app.SecondaryDataFilterType.Value
            case 'None' % no filtering
               app.yDataFilt = yDataFilt2;
            case 'Moving Average' % window filtering
               app.yDataFilt = movingAverage(yDataFilt2, app.SecondaryDataFilterLength.Value);
            case 'Median Filter' % median filtering
               app.yDataFilt = medfilt1(yDataFilt2, app.SecondaryDataFilterLength.Value);
            case 'Sgolay' % savitsky-golay
               app.yDataFilt = sgolayfilt(yDataFilt2, app.SecondaryDataFilterOrder.Value, app.SecondaryDataFilterLength.Value);
            case 'Low Pass' % Lowpass
               app.yDataFilt=lowpass(yDataFilt2,app.SecondaryDataLowPassHz.Value,1/app.fileSI,'ImpulseResponse','iir','Steepness',0.82);
            case 'Lowess' % Local regression using weighted linear least squares and a 1st degree polynomial model.
               app.yDataFilt = smooth(yDataFilt2, app.SecondaryDataFilterLength.Value,'lowess');
            case 'Loess' % Local regression using weighted linear least squares and a 2nd degree polynomial model.
               app.yDataFilt = smooth(yDataFilt2, app.SecondaryDataFilterLength.Value,'loess');

         end

         plot(app.ax1,xData,app.yData,'Color',[0.85 0.851 0.85],'LineWidth',app.MiniFigure.UserData.tailoredLineWidth,'Tag','PlotAx1UnfilteredyData');
         hold(app.ax1,'on');
         plot(app.ax1,xData,app.yDataFilt,'Color',[0.3 0.35 0.35],'LineWidth',app.MiniFigure.UserData.tailoredLineWidth,'Tag','PlotAx1yDataFilt');


         app.ax1.XLim=[app.XLim1val.Value app.XLim1val.Value+app.dXSlider.Value];

         clearvars yDataFilt2
      end

      % Callback function
      function GoPlotNow(app, event)
         app.Lamp.Color=[1 0 0];
         DeterminePeaks_PlotAx3(app)
         app.Lamp.Color=[0 1 0];
      end

      % Button pushed function: FilterDerGoButton
      function GoFilterDerivative(app, event)
         if ~isempty(app.ax2.Children)
            delete(app.ax2.Children);
         end

         if ~isempty(app.ax3.Children)
            delete(app.ax3.Children);
         end

         app.Lamp.Color=[1 0.3 0];
         tempDiff=nan(size(app.yDataFilt));
         app.dataDerFilt=[];


         if app.SlidingDerivative.Value==1 %which method for derivative
            tempDiff=nan(size(app.yDataFilt));
            for i=2:length(app.yDataFilt)-1
               tempDiff(i,1)=(app.yDataFilt(i+1)-app.yDataFilt(i-1))/2;
            end
            tempDiff(1)=tempDiff(2);
            tempDiff(end)=tempDiff(end-1);
         else
            tempDiff=diff(app.yDataFilt);
            tempDiff=[tempDiff(1);tempDiff];
         end

         %Filter the derivative
         switch app.DerFilterType.Value
            case 'None' % no filtering
               app.dataDerFilt = tempDiff;
            case 'Moving Average' % window filtering
               app.dataDerFilt=movmean(tempDiff,app.DerFilterLength.Value,'Endpoints','shrink');
            case 'Median Filter' % median filtering
               app.dataDerFilt = medfilt1(tempDiff, app.DerFilterLength.Value);
            case 'Sgolay' % savitsky-golay
               app.dataDerFilt = sgolayfilt(tempDiff, 2, app.DerFilterLength.Value);
            case 'Low Pass' % Lowpass
               app.dataDerFilt=lowpass(tempDiff,app.DerLowPassHz.Value,1/app.fileSI,'ImpulseResponse','iir','Steepness',0.82);
            case 'Lowess' % Local regression using weighted linear least squares and a 1st degree polynomial model.
               app.dataDerFilt = smooth(tempDiff, app.DerFilterLength.Value,'lowess');
            case 'Loess' % Local regression using weighted linear least squares and a 2nd degree polynomial model.
               app.dataDerFilt = smooth(tempDiff, app.DerFilterLength.Value,'loess');
         end

         dataDerFilt2=app.dataDerFilt; %#ok<*ADPROPLC>
         %Apply second filter to derivative (optional)
         switch app.SecondaryDerFilterType.Value
            case 'None' % no filtering
               app.dataDerFilt = dataDerFilt2;
            case 'Moving Average' % window filtering
               app.dataDerFilt=movmean(dataDerFilt2,app.SecondaryDerFilterLength.Value,'Endpoints','shrink');
            case 'Median Filter' % median filtering
               app.dataDerFilt = medfilt1(dataDerFilt2, app.SecondaryDerFilterLength.Value);
            case 'Sgolay' % savitsky-golay
               app.dataDerFilt = sgolayfilt(dataDerFilt2, 2, app.SecondaryDerFilterLength.Value);
            case 'Low Pass' % Lowpass
               app.dataDerFilt=lowpass(dataDerFilt2,app.SecondaryDerLowPassHz.Value,1/app.fileSI,'ImpulseResponse','iir','Steepness',0.82);
            case 'Lowess' % Local regression using weighted linear least squares and a 1st degree polynomial model.
               app.dataDerFilt = smooth(dataDerFilt2, app.SecondaryDerFilterLength.Value,'lowess');
            case 'Loess' % Local regression using weighted linear least squares and a 2nd degree polynomial model.
               app.dataDerFilt = smooth(dataDerFilt2, app.SecondaryDerFilterLength.Value,'loess');
         end

         app.ax1.XLim=[app.XLim1val.Value app.XLim1val.Value+app.dXSlider.Value];


         hPlottedtempDiff=findobj(app.ax2,'Tag','plotAx2tempDiff');
         hPlottedDerFilt=findobj(app.ax2,'Tag','plotAx2dataDerFilt');
         if ~isempty(hPlottedtempDiff) %if we already have plotted data, delete it
            delete(hPlottedtempDiff);
         end
         if ~isempty(hPlottedDerFilt)
            delete(hPlottedDerFilt);
         end

         hold(app.ax2,'on')
         plot(app.ax2, (1:length(tempDiff)).*app.fileSI, tempDiff,'Color',[0.75 0.75 0.75],'LineWidth',app.MiniFigure.UserData.tailoredLineWidth,'Tag','plotAx2tempDiff');
         hold(app.ax2,'on')
         plot(app.ax2, (1:length(app.dataDerFilt)).*app.fileSI,app.dataDerFilt,'Color',[0.0 0.333 0.8333],'LineWidth',app.MiniFigure.UserData.tailoredLineWidth,'Tag','plotAx2dataDerFilt');
         linkaxes([app.ax1 app.ax2 app.ax3],'x');

         %          if app.OnlyderMethodBtn.Value
         %             hOnsetTreshPlot=findobj('Tag','PlotAx2_OnsetTresh');
         %             if ~isempty(hOnsetTreshPlot) %if we already have plotted data, delete it
         %                delete(hOnsetTreshPlot)
         %             end
         %             hold(app.ax2,'on')
         %             plot(app.ax2,[app.fileSI length(app.dataDerFilt)*app.fileSI],[app.OnsetTresh.Value app.OnsetTresh.Value],'LineStyle',"--","Color",[0.7 0.7 0.7],'LineWidth',1,'Tag','PlotAx2_OnsetTresh');
         %          end

         DeterminePeaks_PlotAx3(app); %actual function for determination
         app.Lamp.Color=[0 1 0]; %green light when finished
      end

      % Callback function: XLim1val, dXSlider, dXSlider
      function SetXstartAnddX(app, event)
         app.ax1.XLim=[app.XLim1val.Value app.XLim1val.Value+app.dXSlider.Value];
         linkaxes([app.ax1 app.ax2 app.ax3],'x');


      end

      % Button pushed function: BtnXStepForw
      function MoveXforwards(app, event)
         GoMoveXForw(app,app.ax1,app.dXSlider.Value)
         linkaxes([app.ax1 app.ax2 app.ax3],'x');
         app.XLim1val.Value=app.ax1.XLim(1);

      end

      % Button pushed function: BtnXStepBack
      function MoveXback(app, event)
         GoMoveXback(app,app.ax1,app.dXSlider.Value)
         linkaxes([app.ax1 app.ax2 app.ax3],'x');

         app.XLim1val.Value=app.ax1.XLim(1);
      end

      % Button pushed function: BtnYStepUp
      function Tab1_MoveYLimUp(app, event)
         app.ax1.YLim=app.ax1.YLim+0.35*app.dYSlider.Value;
      end

      % Button pushed function: BtnYStepDown
      function Tab1_MoveYLimDown(app, event)
         app.ax1.YLim=app.ax1.YLim-0.25*app.dYSlider.Value;
      end

      % Callback function
      function SetX1anddX(app, event)
         app.ax1.XLim(2)=app.ax1.XLim(1)+app.dXSlider.Value;
         linkaxes([app.ax1 app.ax2 app.ax3],'x');

      end

      % Button pushed function: ReAnalyse
      function ReanalysePeaks(app, event)
         app.Lamp.Color=[1 0.3 0];
         app.ax4.UserData=[];  % if we have selection data, delete it
         app.FitVals=[];
         app.Idxs=[];
         app.FitDatas=[];
         app.wherePeaks=[];
         app.whereStarts=[];
         hTreshdepAx1 = findobj(app.ax1,'-regexp','Tag','TreshDpndnt_.');
         delete(hTreshdepAx1)
         hTreshdepAx3 = findobj(app.ax3,'-regexp','Tag','TreshDpndnt_.');
         delete(hTreshdepAx3)
         DeterminePeaks_PlotAx3(app); %re-analyse
         app.Lamp.Color=[0 1 0];

      end

      % Value changed function: ShowUnfiltereddataCheckBox
      function ShowHideUnfilteredData(app, event)
         hAx1Unf=findobj(app.ax1,'Tag','PlotAx1UnfilteredyData');
         if ~isempty(hAx1Unf)
            switch app.ShowUnfiltereddataCheckBox.Value
               case 1
                  hAx1Unf.Visible='on';
               case 0
                  hAx1Unf.Visible='off';
            end
         end
      end

      % Callback function: YLim1val, dYSlider, dYSlider
      function SetYLimsTab1(app, event)
         app.ax1.YLim=[app.YLim1val.Value app.YLim1val.Value+app.dYSlider.Value];

         if quantile(app.ax1.Children(1).YData,0.01)<app.ax1.YLim(1)||quantile(app.ax1.Children(1).YData,0.99)>app.ax1.YLim(2)
            app.ax1.YLim=[round(median(app.ax1.Children(1).YData))-(0.7*app.dYSlider.Value) round(median(app.ax1.Children(1).YData))+(0.3*app.dYSlider.Value)];
            app.YLim1val.Value=round(app.ax1.YLim(1));
            disp('settings yLims')
         end

      end

      % Callback function
      function SetdYSlider(app, event)


         %             xLims=app.ax1.XLim;
         Y1_orig=app.ax1.YLim(1);
         app.ax1.YLim=[Y1_orig Y1_orig+app.dYSlider.Value];
         %             app.ax1.YLim(2)=app.ax1.YLim(1)+app.dYSlider.Value;
         %             app.ax1.XLim=xLims;
         %             linkaxes([app.ax1 app.ax2 app.ax3],'x');


      end

      % Value changed function: ShowPeaksCheckBox
      function Ax1ShowPeaks(app, event)
         h_ScatAx1wherePeaks=findobj(app.ax1,'Tag','TreshDpndnt_ScatAx1wherePeaks');
         if ~isempty(h_ScatAx1wherePeaks)
            switch app.ShowPeaksCheckBox.Value
               case 1
                  h_ScatAx1wherePeaks.Visible='on';
               case 0
                  h_ScatAx1wherePeaks.Visible='off';
            end
         end
      end

      % Value changed function: ShowStartsCheckBox
      function Ax1ShowStarts(app, event)
         h_ScatAx1whereStarts=findobj(app.ax1,'Tag','TreshDpndnt_ScatAx1whereStarts');
         if ~isempty(h_ScatAx1whereStarts)
            switch app.ShowStartsCheckBox.Value
               case 1
                  h_ScatAx1whereStarts.Visible='on';
               case 0
                  h_ScatAx1whereStarts.Visible='off';
            end
         end
      end

      % Callback function
      function ChangePlotAx2(app, event)
         app.Lamp.Color=[1 1 0]; %dont use this function, outdated
         switch app.PlotAx2DropDown.Value
            case 'Derivative'
               tempDiff=nan(size(app.yDataFilt));
               for i=2:length(app.yDataFilt)-1
                  tempDiff(i,1)=(app.yDataFilt(i+1)-app.yDataFilt(i-1))/2;
               end
               tempDiff(1)=tempDiff(2);
               tempDiff(end)=tempDiff(end-1);

               plot(app.ax2, (1:length(tempDiff)).*app.fileSI, tempDiff,'Color',[0.35 0.35 0.35],'LineWidth',app.MiniFigure.UserData.tailoredLineWidth,'Tag','plotAx2tempDiff');
               hold on
               plot(app.ax2, (1:length(app.dataDerFilt)).*app.fileSI,app.dataDerFilt,'LineWidth',app.MiniFigure.UserData.tailoredLineWidth,'Tag','plotAx2dataDerFilt');
               linkaxes([app.ax1 app.ax2 app.ax3],'x');
            case 'Method 1'

               funcDownOnly1 = zeros(size(app.dataDerFilt));
               for icnt = 1:length(app.dataDerFilt)-1
                  if app.dataDerFilt(icnt) >= 0
                     funcDownOnly1(icnt+1) = funcDownOnly1(icnt) + app.dataDerFilt(icnt);
                  end
               end

               % find where derivative of this function is changing from negative to positive
               dDownOnly =diff(funcDownOnly1);
               peaks = find((dDownOnly(2:length(dDownOnly)) ./ dDownOnly(1:length(dDownOnly) -1) < 0 | dDownOnly(2:length(dDownOnly)) == 0) & dDownOnly(1:length(dDownOnly) - 1) < 0);

               DeterminePeaks_PlotAx3(app);
               app.Lamp.Color=[0 1 0];

            case 'Method 2'
               %Make logical values wether below/above treshold
               app.Method2Data.DerBelowTresh=app.dataDerFilt<app.OnsetTresh.Value;

               %find instances of n consequtive 1's starting with 000
               [app.Method2Data.whereStarts, ~] = regexp(char(app.Method2Data.DerBelowTresh'+'0'), char([zeros(1,app.preZeros.Value) ones(1,app.windowLength.Value)]+'0'));
               app.Method2Data.whereStarts=app.Method2Data.whereStarts+app.preZeros.Value; %Counting starts at begin of pre-onset zeros
               app.Method2Data.whereStarts=app.Method2Data.whereStarts(:);

               %remove double detections
               %app.Method2Data.whereStarts=[app.Method2Data.whereStarts; app.Method2Data.whereStarts(end)];
               app.Method2Data.whereStarts(diff(app.Method2Data.whereStarts)<10)=[];

               %find event peaks
               app.Method2Data.DerAboveTresh=app.dataDerFilt>app.derMaxDecay.Value; %When is derivative above treshold (identify decays)
               app.Method2Data.IntervalToPeak=round(app.derMaxRise.Value/fileSI_ms);
               app.Method2Data.wherePeaks=nan(size(app.Method2Data.whereStarts));

               for peakIdx=1:length(app.Method2Data.whereStarts)
                  if app.Method2Data.whereStarts(peakIdx)+round(app.derMaxRise.Value/fileSI_ms) < length(app.Method2Data.DerAboveTresh)
                     [app.Method2Data.TimeToPeak, ~] = regexp(char(app.Method2Data.DerAboveTresh(app.Method2Data.whereStarts(peakIdx)+1:app.Method2Data.whereStarts(peakIdx)+round(app.derMaxRise.Value/fileSI_ms))'+'0'),...
                        char([0 ones(1,app.derMaxDecay.Value)]+'0'));
                     if ~isempty(app.Method2Data.TimeToPeak)
                        app.Method2Data.wherePeaks(peakIdx,1)=app.Method2Data.whereStarts(peakIdx)+app.Method2Data.TimeToPeak(1,1)-1;
                     end
                  end
                  clearvars TimeToPeak
               end

               app.Method2Data.BadPeaks=app.Method2Data.whereStarts(isnan(app.Method2Data.wherePeaks));
               app.Method2Data.whereStarts=app.Method2Data.whereStarts(~isnan(app.Method2Data.wherePeaks));
               app.Method2Data.wherePeaks=app.Method2Data.wherePeaks(~isnan(app.Method2Data.wherePeaks));

               xData=(1:length(app.yData)).*app.fileSI;
               app.Lamp.Color=[1 1 0];
               hold(app.ax2,'on')
               plot(app.ax2, xData,app.yDataFilt-mean(app.yDataFilt),'Color',[0.5 0.5 0.5],'DisplayName','yData','Tag','Plotax2yDataFilt');
               line(app.ax2, [app.fileSI length(app.yDataFilt)*app.fileSI], -[app.OnsetTresh.Value app.OnsetTresh.Value], ...
                  'Color', [0.6 0.6 0.6], 'linestyle', '-.','DisplayName','derTresh','Tag','Lineax2DerTresh');
               line(app.ax2, [app.fileSI length(app.yDataFilt)*app.fileSI], [app.derMaxDecay.Value app.derMaxDecay.Value], ...
                  'Color', [0.6 0.6 0.6], 'linestyle', '-.','DisplayName','derTresh','Tag','Lineax2DerTresh');
               scatter(app.ax2, app.Method2Data.wherePeaks.*app.fileSI,app.yDataFilt(app.Method2Data.wherePeaks),[],[1 0 1],"*",'Tag','Scatax2wherePeaks');
               scatter(app.ax2, app.Method2Data.whereStarts.*app.fileSI,app.yDataFilt(app.Method2Data.whereStarts),[],[0 1 0],"+",'Tag','Scatax2whereStarts');

               hold(app.ax1,'on')
               scatter(app.ax1, app.Method2Data.wherePeaks.*app.fileSI,app.yDataFilt(app.Method2Data.wherePeaks),[],[0 0 1],"*",'Tag','ScatAx1wherePeaksMethod2');
               scatter(app.ax1, app.Method2Data.whereStarts.*app.fileSI,app.yDataFilt(app.Method2Data.whereStarts),[],[1 1 0],"+",'Tag','ScatAx1whereStartsMethod2');
         end
      end

      % Value changed function: ShowStarts2CheckBox
      function Ax1ShowStarts2(app, event)
         hpeaksAx11=findobj(app.ax1,'Tag','ScatAx1wherePeaks');
         if ~isempty(hpeaksAx11)
            switch app.ShowStarts2CheckBox.Value
               case 1
                  hpeaksAx11.Ax1Scatterobj2b.Visible='on';
               case 0
                  hpeaksAx11.Visible='off';
            end
         end
      end

      % Value changed function: ShowPeaks2CheckBox
      function Ax1ShowPeaks2(app, event)
         h_ScatAx1whereStarts=findobj(app.ax1,'Tag','ScatAx1whereStarts');
         if ~isempty(h_ScatAx1whereStarts)
            switch app.ShowPeaks2CheckBox.Value
               case 1
                  h_ScatAx1whereStarts.Visible='on';
               case 0
                  h_ScatAx1whereStarts.Visible='off';
            end
         end
      end

      % Selection change function: TabGroup
      function TabGroupSelectionChanged(app, event)
         if app.TabGroup.SelectedTab == app.Tab1
            enableDefaultInteractivity(app.ax3)
            enableDefaultInteractivity(app.ax2)
            enableDefaultInteractivity(app.ax1)
         elseif app.TabGroup.SelectedTab == app.Tab2

            app.dataDerFilt=[];
            app.Order1Order.Value=app.DataFilterOrder.Value;
            app.Filter1fq_kHz.Value=app.DataLowPassHz.Value/1000;
            app.Filter2fq_kHz.Value=app.SecondaryDataLowPassHz.Value/1000;

            if and(isempty(app.FitInterval),~isempty(app.yData))
               app.Interval2end.Value=round((length(app.yData)*app.fileSI-0.2)/10)*10;
            end

         end
      end

      % Button pushed function: LoadSampleDataTab2Btn
      function LoadSampleDataTab2(app, event)
         load('/Users/maaike/Library/Mobile Documents/com~apple~CloudDocs/MATLAB/maaiEPSC_resources/SampleData_Tab2.mat') %#ok<LOAD>

         si=dataStruct.si;
         app.fileSI=dataStruct.fileSI;
         app.ax4.UserData.fileSI=app.fileSI;
         app.yData=dataStruct.yData;

         app.whereStarts=dataStruct.whereStarts;
         app.wherePeaks=dataStruct.wherePeaks;
         fileName='201019_P400L_LIP3035_Cell02_0001.abf';
         app.CurrTrace=fileName(1:end-4);
         pathName='/Users/maaike/Library/Mobile Documents/com~apple~CloudDocs/P401L_SYT1/GFPvsPL/2021_ANALYSIS/2020/P400L/minis/';
         app.MiniFigure.UserData.filePath = fullfile(pathName,fileName);
      end

      % Button pushed function: Tab2PlotNow
      function PlotAx4Now(app, event)


         accRejBtnVal=app.AcceptRejectButton.Value;
         AddEventBtnVal=app.AddeventsButton.Value;

         app.AcceptRejectButton.Value=0;
         app.AddeventsButton.Value=0;

         hFormeryDataPlot=findobj(app.ax4,'Tag','PlotAx4yDataFilt'); %See if we already have data here
         if ~isempty(hFormeryDataPlot)
            curXLims=app.ax4.XLim;
            delete(hFormeryDataPlot); %if we do, delete it, (cause it'll interfere with Addition mode later on)
         else
            curXLims=[];

         end

         % filter the raw data
         switch app.Filter1Selection.Value
            case 'None' % no filtering
               app.yDataFilt = app.yData;
            case 'Moving Average' % window filtering
               app.yDataFilt = movingAverage(app.yData, app.Filter1Length.Value);
            case 'Median Filter' % median filtering
               app.yDataFilt = medfilt1(app.yData, app.Filter1Length.Value);
            case 'Sgolay' % savitsky-golay
               app.yDataFilt = sgolayfilt(app.yData, app.Order1Order.Value, app.Filter1Length.Value);
            case 'Low Pass' % Lowpass
               app.yDataFilt=lowpass(app.yData,app.Filter1fq_kHz.Value*1000,1/app.fileSI,'ImpulseResponse','iir','Steepness',0.82);
            case 'Lowess' % Local regression using weighted linear least squares and a 1st degree polynomial model.
               app.yDataFilt = smooth(app.yData, app.Filter1Length.Value,'lowess');
            case 'Loess' % Local regression using weighted linear least squares and a 2nd degree polynomial model.
               app.yDataFilt = smooth(app.yData, app.Filter1Length.Value,'loess');
            case 'rLowess' % Robust Lowess (linear fit)
               app.yDataFilt = smooth(app.yData, app.Filter1Length.Value,'rlowess');
         end
         yDataFilt2=app.yDataFilt;

         % second filter
         switch app.Filter2Selection.Value
            case 'None' % no filtering
               app.yDataFilt = yDataFilt2;
            case 'Moving Average' % window filtering
               app.yDataFilt = movingAverage(yDataFilt2, app.Filter2Length.Value);
            case 'Median Filter' % median filtering
               app.yDataFilt = medfilt1(yDataFilt2, app.Filter2Length.Value);
            case 'Sgolay' % savitsky-golay
               app.yDataFilt = sgolayfilt(yDataFilt2, app.Filter2Order.Value, app.Filter2Length.Value);
            case 'Low Pass' % Lowpass
               app.yDataFilt=lowpass(yDataFilt2,app.Filter2fq_kHz.Value*1000,1/app.fileSI,'ImpulseResponse','iir','Steepness',0.82);
            case 'Lowess' % Local regression using weighted linear least squares and a 1st degree polynomial model.
               app.yDataFilt = smooth(yDataFilt2, app.Filter2Length.Value,'lowess');
            case 'Loess' % Local regression using weighted linear least squares and a 2nd degree polynomial model.
               app.yDataFilt = smooth(yDataFilt2, app.Filter2Length.Value,'loess');
            case 'rLowess' %Robust Lowess (linear fit)
               app.yDataFilt = smooth(yDataFilt2, app.Filter2Length.Value,'rlowess');

         end
         yDataFilt2=[];
         hyDataPlot=findobj(app.ax4,'Tag','PlotAx4origyData'); %See if we already have data here

         if ~isempty(hyDataPlot)
            delete(hyDataPlot)
         end

         hold(app.ax4,'on') %plot ~original data and filtered trace
         plot(app.ax4,(1:length(app.yData)).*app.fileSI, sgolayfilt(app.yData,2,9),'Color',[0.9 0.9 0.9],'LineWidth', ...
            app.MiniFigure.UserData.tailoredLineWidth,'Tag','PlotAx4origyData');

         plot(app.ax4,(1:length(app.yDataFilt)).*app.fileSI,app.yDataFilt,'Color',[0.3 0.35 0.35],'LineWidth',app.TracesLineWidthSpinner.Value,'Tag','PlotAx4yDataFilt');
         app.ax4.XLim=[app.Interval1Start.Value app.Interval1Start.Value+app.Tab2_dXSlider.Value];
         app.ProgressGauge.Value=0;

         app.ax4.YLim=[mean(app.yDataFilt)-app.Tab2_dYSlider.Value*0.66 mean(app.yDataFilt)+app.Tab2_dYSlider.Value*0.33];

         if ~isempty(curXLims)
            app.ax4.XLim=curXLims;
         end

         %Reorder objects so as to have scatter on top of traces
         hCh=app.ax4.Children;
         ChObjects=cell(size(hCh));
         for i=1:length(hCh)
            ChObjects{i,1}=hCh(i).Tag;
         end

         if any(contains(ChObjects,'ScatFittedMinis'))
            IdxScat=find(contains(ChObjects,'ScatFittedMinis'))';
            IdxLine=find(~contains(ChObjects,'ScatFittedMinis'))';
            app.ax4.Children = app.ax4.Children([IdxScat IdxLine]);

         end


         app.AcceptRejectButton.Value=accRejBtnVal;
         app.AddeventsButton.Value=AddEventBtnVal;

      end

      % Value changed function: ShowunfilteredCheckBox
      function ShowHideUnfilteredAx4(app, event)
         hyDataPlot=findobj(app.ax4,'Tag','PlotAx4origyData');
         switch app.ShowunfilteredCheckBox.Value
            case 0
               hyDataPlot.Visible='off';
            case 1
               hyDataPlot.Visible='on';
         end
      end

      % Button pushed function: Tab2_BtnXStepBack
      function MoveXBack_ax4(app, event)
         GoMoveXback(app,app.ax4,app.Tab2_dXSlider.Value)
         app.Tab2_XLim1.Value=app.ax4.XLim(1);
      end

      % Button pushed function: Tab2_BtnXStepForw
      function MoveXForw_ax4(app, event)
         GoMoveXForw(app,app.ax4,app.Tab2_dXSlider.Value)
         app.Tab2_XLim1.Value=app.ax4.XLim(1);
      end

      % Callback function: Tab2_YLim1, Tab2_dYSlider, Tab2_dYSlider
      function SetdYSlider_ax4(app, event)

         app.ax4.YLim=[app.Tab2_YLim1.Value app.Tab2_YLim1.Value+app.Tab2_dYSlider.Value];

         if quantile(app.yData,0.01)<app.ax4.YLim(1)||quantile(app.yData,0.99)>app.ax4.YLim(2)
            app.ax4.YLim=[round(median(app.yData))-(0.7*app.Tab2_dYSlider.Value) round(median(app.yData))+(0.3*app.Tab2_dYSlider.Value)];
            app.Tab2_YLim1.Value=round(app.ax4.YLim(1));

         end
      end

      % Callback function: Tab2_dXSlider, Tab2_dXSlider
      function SetAx4dXslider(app, event)
         app.ax4.XLim=[app.ax4.XLim(1) app.ax4.XLim(1)+app.Tab2_dXSlider.Value];
         %             app.ax4.XLim=[app.Tab2_XLim1.Value app.Tab2_XLim1.Value+app.Tab2_dXSlider.Value];
      end

      % Value changed function: Tab2_XLim1
      function SetX1ax4(app, event)
         app.ax4.XLim=[app.Tab2_XLim1.Value app.Tab2_XLim1.Value+app.Tab2_dXSlider.Value];
      end

      % Callback function
      function HaveChangedFitInterval(app, event)
         app.FitInterval(1,1:2)=[app.Interval1Start.Value app.Interval1End.Value]; %FitInterval(1,:)=Values in seconds
         %             app.FitInterval(2,1:2)=[round(app.Interval1Start.Value/app.fileSI) round(app.Interval1End.Value/app.fileSI)];  %FitInterval(2,:)=Values in Idxs
      end

      % Button pushed function: FitNowButton
      function GoFitNow(app, event)
         app.Lamp.Color=[1 0.3 0];

         hScatold=findobj(app.ax4,'Type','Scatter');
         if ~isempty(hScatold)
            delete(hScatold);
         end

         FitPreProcess(app);
         Fitminis(app);
         FitPostProces(app);
         app.FitDatas.AddedMiniCoor=[NaN NaN];
         app.FitDatas.AddedMiniPoints=[NaN NaN];
         hold(app.ax4,'on')

         if strcmpi(app.BadFitSwitch.Value,'Include')
            scatter(app.ax4, app.Idxs(app.FitDatas.isMini(:,1),2).*app.fileSI,app.yData(app.Idxs(app.FitDatas.isMini(:,1),2)), app.MarkerSizeSpinner.Value, ...
               [0.0 0.95 0.0],"o",'LineWidth',app.MarkerLineWidthSpinner.Value,'Tag','ScatFittedMinis');

         elseif strcmpi(app.BadFitSwitch.Value,'Exclude')
            scatter(app.ax4, app.Idxs(app.FitDatas.isMini(:,2),2).*app.fileSI,app.yData(app.Idxs(app.FitDatas.isMini(:,2),2)), app.MarkerSizeSpinner.Value, ...
               [0.0 0.95 0.0],"o",'LineWidth',app.MarkerLineWidthSpinner.Value,'Tag','ScatFittedMinis');
            scatter(app.ax4, app.Idxs(~app.FitDatas.isMini(:,2),2).*app.fileSI,app.yData(app.Idxs(~app.FitDatas.isMini(:,2),2)), app.MarkerSizeSpinner.Value, ...
               [0.8 0.8 0.0],"x",'LineWidth',app.MarkerLineWidthSpinner.Value,'Tag','ScatRejectedMinis');


         else
            scatter(app.ax4, app.Idxs(app.FitDatas.isMini(:,1),2).*app.fileSI,app.yData(app.Idxs(app.FitDatas.isMini(:,1),2)), app.MarkerSizeSpinner.Value, ...
               [0.0 0.95 0.0],"o",'LineWidth',app.MarkerLineWidthSpinner.Value,'Tag','ScatFittedMinis');
         end
         hScat=findobj(app.ax4,'Type','Scatter');

         if ~isempty(hScat)
            for n=1:length(hScat)
               hScat(n).SizeData=app.MarkerSizeSpinner.Value;
               hScat(n).LineWidth=1;
            end
         end
         app.Lamp.Color=[0 1 0];
      end

      % Value changed function: ShowFitsCheckBox
      function GoShowFits(app, event)
         h_PlottedMiniFits=findobj(app.ax4,'Tag','PlottedMiniFits'); %see if we already have plotobject
         h_PlottedAddedMiniFits=findobj(app.ax4,'Tag','PlottedAddedMiniFits'); %plotobj for added minis

         switch app.ShowFitsCheckBox.Value
            case 1
               % first, delete any old fits
               if ~isempty(h_PlottedMiniFits)
                  delete(findall(app.ax4,'Tag','PlottedMiniFits'));
                  delete(findall(app.ax4,'Tag','PlottedAddedMiniFits'));
               end


               %Plot decayfits
               hold(app.ax4,'on')
               for i=1:length(app.FitDatas.decayFits) %Loop through minis, plot if ~empty
                  if ~isempty(app.FitDatas.decayFits{i,1})
                     if app.FitVals(i,9)<0.7 %(if needed, generate handles with Tag&Color)
                        FitCol=[1 0.5765 0];%orange for badfit
                     elseif app.FitVals(i,9)>0.7
                        FitCol=[0.9373    0.0588    0.6549];%Magenta
                        %                         FitCol=[ 0    0.6510    0.6118]; %blueGreenish
                     else
                        FitCol=[0.1294    0.6980    0.6078];%Greenish
                     end
                     plot(app.ax4,app.FitDatas.decayFits{i, 1}(:,1),app.FitDatas.decayFits{i, 1}(:,2),'Color',FitCol,'LineWidth',...
                        app.FitsLineWidthSpinner.Value,'Tag','PlottedMiniFits');
                     FitCol=[];
                  end
               end

            case 0
               delete(findall(app.ax4,'Tag','PlottedMiniFits'));
               delete(findall(app.ax4,'Tag','PlottedAddedMiniFits'));

         end
      end

      % Value changed function: ShowRejectedallCheckBox
      function GoShowRejected(app, event)
         h_ScatRejectedMinis=findobj(app.ax4,'Tag','ScatRejectedMinis');
         if ~isempty(h_ScatRejectedMinis)
            switch app.ShowRejectedallCheckBox.Value
               case 1
                  h_ScatRejectedMinis.Visible='on';
               case 0
                  set(h_ScatRejectedMinis,'Visible','off')
            end
         end
      end

      % Value changed function: DropDown
      function GoShowSpecifiedRejected(app, event)
         h_ScatSpecifiedRejectedMinis=findobj(app.ax4,'Tag','ScatSpecifiedRejectedMinis');
         if ~isempty(h_ScatSpecifiedRejectedMinis)
            delete(h_ScatSpecifiedRejectedMinis)
         end
         switch app.DropDown.Value
            case 'Too long 𝞃'
               BadFitIdx=1;
            case 'Fit amp deviation >30%'
               BadFitIdx=2;
            case 'Too few decay datapoints'
               BadFitIdx=3;
            case 'Rsq<Min Rsq'
               BadFitIdx=4;
         end
         hold(app.ax4,'on')
         scatter(app.ax4, app.Idxs(app.FitDatas.BadFits(:,BadFitIdx),2).*app.fileSI,app.yData(app.Idxs(app.FitDatas.BadFits(:,BadFitIdx),2)), ...
            [],[1 0.3 0],"o",'LineWidth',app.MarkerLineWidthSpinner.Value,'Tag','ScatSpecifiedRejectedMinis','Visible','on');
         app.ShowSpecRejected.Value=1;
      end

      % Value changed function: ShowSpecRejected
      function ToggleSpecifiedRejected(app, event)
         h_ScatSpecifiedRejectedMinis=findobj(app.ax4,'Tag','ScatSpecifiedRejectedMinis');
         if ~isempty(h_ScatSpecifiedRejectedMinis)
            switch app.ShowSpecRejected.Value
               case 1
                  h_ScatSpecifiedRejectedMinis.Visible='on';
               case 0
                  h_ScatSpecifiedRejectedMinis.Visible='off';
            end
         end
      end

      % Value changed function: AcceptRejectButton
      function TurnOnSelectionMode(app, event)
         %Make sure that graphics objects are in correct order (scatter on top, unfiltered yData at the bottom)
         hCh=app.ax4.Children;
         hChTypes=get(hCh,'Type');
         [~,sortidx]=sort(hChTypes); %alphabetical order so line on top of scatter -> flip
         app.ax4.Children=app.ax4.Children(flipud(sortidx));
         %Now get filtered data on top  (=lower idx) of nonfiltered:
         hCh=app.ax4.Children;
         hChTags = get(hCh,'Tag');
         idxOrigyData=find(strcmpi(hChTags,'PlotAx4origyData'));
         idxyDataFilt=find(strcmpi(hChTags,'PlotAx4yDataFilt'));

         if idxyDataFilt>idxOrigyData
            app.ax4.Children([idxOrigyData; idxyDataFilt])=app.ax4.Children([idxyDataFilt;idxOrigyData]);
         end

         hScatOrange=findobj(app.ax4,'Tag','ScatRejectedMinis');
         hScatGreen=findobj(app.ax4,'Tag','ScatFittedMinis');
         switch app.AcceptRejectButton.Value
            case 1
               set(hScatOrange,'HitTest',1);
               set(hScatOrange,'ButtonDownFcn',@clickPointFcn);
               set(hScatGreen,'HitTest',1);
               set(hScatGreen,'ButtonDownFcn',@clickPointFcn);
               disableDefaultInteractivity(app.ax4);


               targetMiniNoInt1=125;
               targetMiniNoInt2=125;

               if and((abs(diff([app.Interval1Start.Value app.Interval1End.Value]))+abs(diff([app.Interval2start.Value app.Interval2end.Value])))>1,size(app.Idxs,1)>300) %dont know what I meant with the first argument
                  app.TextArea.Value{end+1}=sprintf('For %d minis:',targetMiniNoInt1+targetMiniNoInt2);
                  if app.Interval2start.Value==0
                     if size(app.Idxs,1)>targetMiniNoInt1
                        app.TextArea.Value{end+1}=sprintf('Analyse interval 1 till %.2g s',app.Idxs(targetMiniNoInt1,2)*app.fileSI);
                     end
                  else
                     startidx_int2=find(app.Idxs>app.Interval2start.Value/app.fileSI,1,'first');

                     if startidx_int2>targetMiniNoInt1
                        app.TextArea.Value{end+1}=sprintf('Analyse interval 1 till %.2g s',app.Idxs(targetMiniNoInt1,2)*app.fileSI);
                     else
                        app.TextArea.Value(end+1)={'Fully analyse interval 1'};
                        targetMiniNoInt2=(targetMiniNoInt1+targetMiniNoInt2)-startidx_int2;
                     end

                     if size(app.Idxs,1)>(startidx_int2+targetMiniNoInt2)
                        app.TextArea.Value{end+1}=sprintf('Analyse interval 2 till %.2g s',app.Idxs(startidx_int2+targetMiniNoInt2,2)*app.fileSI);
                     else

                        app.TextArea.Value{end+1}='Increase interval 2';
                     end
                  end
                  drawnow
               end


            case 0
               set(hScatGreen,'HitTest',0);
               set(hScatOrange,'HitTest',0);
               enableDefaultInteractivity(app.ax4);
         end

         function clickPointFcn(hObj,point,handles)
            switch hObj.Tag
               case 'ScatRejectedMinis' %Accept formerly rejected minis
                  %Turn previous selection into 'o'
                  handles.h_previous=findobj(hObj.Parent, 'Tag','recentAddedPointSelection');
                  if ~isempty(handles.h_previous)
                     set(handles.h_previous,'Marker','o');
                     set(handles.h_previous,'CData',[0 0.95 0.05]);
                     set(handles.h_previous,'Tag','AddedPointSelection');
                     set(handles.h_previous,'LineWidth',hObj.LineWidth);

                  end


                  hold(hObj.Parent,'on')
                  % Add '+' to mark current selection
                  % (app.MarkerLineWidthSpinner.Value not available)
                  scatter(hObj.Parent,point.IntersectionPoint(1),point.IntersectionPoint(2), 'g+', ...
                     'SizeData',50,'LineWidth',1.2,'CData',[0 0.95 0.05],'tag','recentAddedPointSelection')

                  [~,idx]=min(sum(abs([hObj.XData' hObj.YData']-point.IntersectionPoint(1:2)),2)); %get idx of datapoint

                  if ~isfield(hObj.Parent.UserData,'ClickedPoints')
                     hObj.Parent.UserData.ClickedPoints=[];
                  end

                  hObj.Parent.UserData.ClickedPoints(end+1,:)=[point.IntersectionPoint(1:2) 1];

                  %Delete marker for rejected state
                  hObj.XData(idx)=[];
                  hObj.YData(idx)=[];

               case 'ScatFittedMinis'  %Reject formerly accepted minis
                  delete(findall(hObj.Parent, 'Tag','recentRejectPointSelection')) %delete last reject marker
                  hold(hObj.Parent,'on')

                  % Add 'x' to mark selection
                  scatter(hObj.Parent,point.IntersectionPoint(1),point.IntersectionPoint(2), 'rx', ...
                     'SizeData',50,'LineWidth',1.1,'tag','recentRejectPointSelection')

                  [~,idx]=min(sum(abs([hObj.XData' hObj.YData']-point.IntersectionPoint(1:2)),2)); %get idx of datapoint


                  if ~isfield(hObj.Parent.UserData,'ClickedPoints')
                     hObj.Parent.UserData.ClickedPoints=[];
                  end

                  %hObj.Parent.UserData.ClickedPoints(end+1,:)=[hObj.XData(idx) hObj.YData(idx) 0];
                  hObj.Parent.UserData.ClickedPoints(end+1,:)=[point.IntersectionPoint(1:2) 0];

                  hObj.XData(idx)=[]; %Delete green marker
                  hObj.YData(idx)=[]; %Delete green marker
            end
            delete(findall(hObj.Parent,'Type','hggroup')); %try to delete persistent datatips
            hObj.Parent.UserData.RecentClickObj=hObj.Tag;
            hold(hObj.Parent,'off')
         end

      end

      % Value changed function: AddeventsButton
      function TurnOnAdditionMode(app, event)
         hscat=findobj(app.ax4,'Type','Scatter');
         %             uistack(hscat,'top');
         %Make sure that graphics objects are in correct order (scatter on top, unfiltered yData at the bottom)
         hCh=app.ax4.Children;
         hChTypes=get(hCh,'Type');
         [~,sortidx]=sort(hChTypes); %alphabetical order so line on top of scatter -> flip
         app.ax4.Children=app.ax4.Children(flipud(sortidx));
         %Now get filtered data on top  (=lower idx) of nonfiltered:
         hCh=app.ax4.Children;
         hChTags = get(hCh,'Tag');
         idxOrigyData=find(strcmpi(hChTags,'PlotAx4origyData'));
         idxyDataFilt=find(strcmpi(hChTags,'PlotAx4yDataFilt'));
         if idxyDataFilt>idxOrigyData
            app.ax4.Children([idxOrigyData; idxyDataFilt])=app.ax4.Children([idxyDataFilt;idxOrigyData]);
         end

         hMiniTrace=findobj(app.ax4,'Tag','PlotAx4origyData');
         hMiniTraceFilt=findobj(app.ax4,'Tag','PlotAx4yDataFilt');

         switch app.AddeventsButton.Value
            case 1
               %                     set(hMiniTrace,'ButtonDownFcn',@clickDataTraceFcn);
               set(hMiniTraceFilt,'ButtonDownFcn',@clickDataTraceFcn);

               %                     set(hMiniTrace,'HitTest',1);
               set(hMiniTraceFilt,'HitTest',1);

               disableDefaultInteractivity(app.ax4);
               if ~isfield(app.ax4.UserData,'fileSI')
                  app.ax4.UserData.fileSI=app.fileSI;
               end

            case 0
               hMiniTrace.HitTest=0;
               hMiniTraceFilt.HitTest=0;

               enableDefaultInteractivity(app.ax4);
         end

         function clickDataTraceFcn(hObj,point) %#ok<*ADAPPREF>

            hScatGreen=findobj(hObj.Parent,'Tag','ScatFittedMinis');
            hPlusGreen=findobj(hObj.Parent,'Tag','recentTraceSelectionPoint');

            if ~isempty(hPlusGreen)
               hScatGreen.XData(end+1)=hPlusGreen.XData(end);
               hScatGreen.YData(end+1)=hPlusGreen.YData(end);
               delete(findall(hObj.Parent, 'Tag','recentTraceSelectionPoint'))
            end

            delete(findall(hObj.Parent, 'Tag','recentAddedPoint'))
            idx=round(point.IntersectionPoint(1)/app.fileSI); %this is in trace so idx corresponds to time value
            h_FiltDataLine=findobj(hObj.Parent.Children,'Tag','PlotAx4yDataFilt');

            if diff(hObj.Parent.XLim)>1.5
               range =idx-1:idx+40;
            else
               range =idx-1:idx+20;
            end

            %             [~,idxMin]=min(h_FiltDataLine.YData(range)-point.IntersectionPoint(2));
            %             idx=range(idxMin);
            %
            %
            %             range =idx-2:idx+18;
            [~,loc_sor] = findpeaks((h_FiltDataLine.YData(range).*-1),range,'SortStr','descend');

            if isempty(loc_sor)
               range =idx-7:idx+33;
               [~,loc_sor] = findpeaks((h_FiltDataLine.YData(range).*-1),range,'SortStr','descend');
            end

            if ~isempty(loc_sor)
               idx=loc_sor(1);
            end

            if ~isfield(hObj.Parent.UserData,'ClickedPoints')
               hObj.Parent.UserData.ClickedPoints=[];
            end

            % write point into clicked point with 1 for added mini
            hObj.Parent.UserData.ClickedPoints(end+1,:)=[point.IntersectionPoint(1:2) 1];

            %Plot clicked point ("+") and peak point ("o").
            hold(hObj.Parent,'on')

            MarkerSize=app.MarkerSizeSpinner.Value;
            MarkerLineWidth=app.MarkerLineWidthSpinner.Value;
            scatter(hObj.Parent,point.IntersectionPoint(1),point.IntersectionPoint(2),MarkerSize, '+','CData',[0 1 0],'LineWidth',MarkerLineWidth, 'Tag','recentTraceSelectionPoint');
            scatter(hObj.Parent,h_FiltDataLine.XData(idx),h_FiltDataLine.YData(idx),25,[0 0.6 0.86] ,"o",'LineWidth',MarkerLineWidth, 'Tag','recentAddedPoint');

            hObj.Parent.UserData.RecentClickObj=hObj.Tag;
            delete(findall(hObj.Parent,'Type','hggroup'));  %try to delete persistent datatips
            hold(hObj.Parent,'off')
         end

      end

      % Button pushed function: UndolastclickButton
      function GoUndoLastClick(app, event)

         switch app.ax4.UserData.ClickedPoints(end,3)
            case 0 %removed mini
               hObj=findobj(app.ax4,'Tag','ScatFittedMinis','Marker','o');
               for i=1:length(hObj)
                  hObj(i).XData(end+1)=app.ax4.UserData.ClickedPoints(end,1);
                  hObj(i).YData(end+1)=app.ax4.UserData.ClickedPoints(end,2);
                  %                        size(app.Idxs,1)-sum(app.ax4.UserData.ClickedPoints(:,3)==0)
               end
               %
               delete(findall(app.ax4, 'Tag','recentRejectPointSelection')); %delete reject marker
               app.ax4.UserData.ClickedPoints=app.ax4.UserData.ClickedPoints(1:end-1,:);

            case 1 %added mini


               delete(findall(findobj(app.ax4, 'Tag','recentTraceSelectionPoint','Marker','+')));
               delete(findall(findobj(app.ax4, 'Tag','recentAddedPointSelection','Marker','+')));
               app.ax4.UserData.ClickedPoints=app.ax4.UserData.ClickedPoints(1:end-1,:);
               hRecentAddedPoint=findobj(app.ax4, 'Tag','recentAddedPoint','Marker','o');
               hRecentAddedPoint.XData(end)=[];
               hRecentAddedPoint.YData(end)=[];


         end
         app.ax4.UserData.RecentClickObj='';

         %             switch app.ax4.UserData.RecentClickObj
         %                 case 'ScatRejectedMinis' %last action was accepting rejected mini
         %                     app.ax4.UserData.AddedMiniCoor=app.ax4.UserData.AddedMiniCoor(end-1,:);
         %                     app.ax4.UserData.AddFormerlyRejectedData=app.ax4.UserData.AddFormerlyRejectedData(end-1,:);
         %                     delete(findall(app.ax4, 'Tag','recentAddedPointSelection'));
         %                 case 'ScatFittedMinis' %last action was rejecting green mini
         %                     app.ax4.UserData.RejectDataPoints=app.ax4.UserData.RejectDataPoints(end-1,:);
         %                     app.ax4.UserData.RejectMiniCoords=app.ax4.UserData.RejectMiniCoords(end-1,:);
         %                     delete(findall(app.ax4,'Tag','recentRejectPointSelection'))
         %                 case 'PlotAx4yDataFilt' %last action was adding point on dataTrace
         %                     app.ax4.UserData.AddedMiniCoor=app.ax4.UserData.AddedMiniCoor(end-1,:);
         %                     app.ax4.UserData.AddPoints=app.ax4.UserData.AddPoints(end-1,:);
         %                     app.ax4.UserData.ClickedPoints=app.ax4.UserData.ClickedPoints(end-1,:);
         %                     delete(findall(app.ax4, 'Tag','recentTraceSelectionPoint'))
         %                     app.ax4.UserData.RecentClickObj='';
         %             end
      end

      % Button pushed function: FinishButton
      function FinishAddition_FitNewPoints(app, event)


         switch app.TriggerNextEventDropDown.Value
            case 'Very Low'
               TriggerVal_Derivative=-5;
            case 'Low'
               TriggerVal_Derivative=-10;
            case 'Medium'
               TriggerVal_Derivative=-15;
            case 'High'
               TriggerVal_Derivative=-20;
            case 'Very High'
               TriggerVal_Derivative=-35;
            case 'Ultra High'
               TriggerVal_Derivative=-50;
         end

         smoothyDataAvg=smooth(app.yData,(10*100e-6)/app.fileSI,'moving');
         smoothyDataAvg=smooth(smoothyDataAvg,(9*100e-6)/app.fileSI+1,'sgolay');


         app.AcceptRejectButton.Value=0;
         app.AddeventsButton.Value=0;

         app.Lamp.Color=[1 0.3 0];
         drawnow
         ClickedPoints=app.ax4.UserData.ClickedPoints;

         if ~isempty(ClickedPoints)
            AddPoints=ClickedPoints(logical(ClickedPoints(:,3)),1:2);
            DelPoints=ClickedPoints(~logical(ClickedPoints(:,3)),1:2);

            %Start with removing DelPoints (ClickedPoints marked '0')
            [~,~,IB] = intersect(round(DelPoints(:,1)./app.fileSI),app.Idxs(:,2));
            if ~(length(IB)==length(DelPoints))
               fprintf('Warning: only %d of %d events to delete were found\n', length(IB),length(DelPoints));
            end

            accRejMini=ones(size(app.Idxs,1),1);
            accRejMini(IB)=0;
            accRejMini=logical(accRejMini);

            if any(accRejMini==0)
               app.FitDatas.decayFits=app.FitDatas.decayFits(accRejMini(1:length(app.FitDatas.decayFits)));
               app.FitDatas.isMini=app.FitDatas.isMini(accRejMini,:);
               app.Idxs=app.Idxs(accRejMini,:);
               app.FitVals=app.FitVals(accRejMini,:);
               app.FitDatas.BadFits=app.FitDatas.BadFits(accRejMini,:);
            end
         end

         %Now for adding AddPoints (ClickedPoints marked '1')
         %first, get derivative of filtered data again

         if isempty(app.yDataFilt)
            GoFilteryData_Tab2(app)
         end

         yDataMovavg=smooth(app.yDataFilt, round(0.0013/app.fileSI),'moving');
         diff_yDatamovavg=smooth(diff(yDataMovavg),round(0.0019/app.fileSI),'sgolay');
         diff_yDatamovavg=[diff_yDatamovavg; diff_yDatamovavg(end)];

         downOnly=zeros(size(diff_yDatamovavg));
         for j = 2:length(downOnly)
            if diff_yDatamovavg(j-1) <= 0
               downOnly(j) = downOnly(j-1) + diff_yDatamovavg(j-1);
            end
         end
         sumDownonly=movsum(downOnly,[round(3*1e-4/app.fileSI) 0]);

         app.dataDerFilt=[];
         tempDiff=nan(size(app.yDataFilt));
         for i=2:length(app.yDataFilt)-1
            tempDiff(i,1)=(app.yDataFilt(i+1)-app.yDataFilt(i-1))/2;
         end
         tempDiff(1)=tempDiff(2);
         tempDiff(end)=tempDiff(end-1);

         app.dataDerFilt = sgolayfilt(tempDiff, 3, ceil(((21*100e-6)/app.fileSI)/2)*2+1);

         %Make down-only filter
         funcDownOnly = zeros(size(app.yDataFilt));
         for icnt = 1:length(app.dataDerFilt)
            if app.dataDerFilt(icnt) < 0
               funcDownOnly(icnt+1) = funcDownOnly(icnt) + app.dataDerFilt(icnt);
            end
         end
         whereNull = find(funcDownOnly == 0);

         addIdxs=nan(size(AddPoints,1), 2);
         addIdxs(:,2)=round(AddPoints(:,1)./app.fileSI);

         %check addPoints for deletePoints
         [~,~,IB2] = intersect(round(DelPoints(:,1)./app.fileSI),addIdxs(:,2));

         if ~isempty(IB2)
            accRejMini2=true(size(addIdxs,1),1);
            accRejMini2(IB2)=false;
            if any(accRejMini2==0)
               addIdxs=addIdxs(accRejMini2,:);
               AddPoints=AddPoints(accRejMini2,:);
            end
         end

         addFitVals=nan(size(addIdxs,1), 14);
         addBadFits=zeros(size(addIdxs,1), 4);
         addDecayFits=cell(size(addIdxs,1),1);
         addDecayYs=addDecayFits; %need empty cell of same size
         addRiseYs=addDecayFits; %need empty cell of same size
         addIsMini=true(size(addIdxs,1),2);

         app.ProgressGauge.Value=0;
         switch app.TriggerNextEventDropDown.Value
            case 'Very Low'
               TriggerVal_Derivative=-5;
            case 'Low'
               TriggerVal_Derivative=-10;
            case 'Medium'
               TriggerVal_Derivative=-15;
            case 'High'
               TriggerVal_Derivative=-20;
            case 'Very High'
               TriggerVal_Derivative=-35;
            case 'Ultra High'
               TriggerVal_Derivative=-50;
         end
         TriggerVal_Derivative=(TriggerVal_Derivative*100e-6)/app.fileSI;
         RoughBaseline=medfilt1(app.yDataFilt, 1500);

         for indexEPSC = 1:size(addIdxs,1)
            app.ProgressGauge.Value=(indexEPSC/size(addIdxs,1))*100;
            drawnow


            %Make sure we're really at the peak
            range=addIdxs(indexEPSC,2)-3:addIdxs(indexEPSC,2)+12;
            range=range(range>0);
            [~,idx]=findpeaks(app.yData(range)*-1,'SortStr','descend');
            if isempty(idx)
               [~,idx]=min(app.yData(range));
            else
               idx=idx(1,1);
            end

            addIdxs(indexEPSC,2)=range(idx);

            MaxRisePhaseTime=0.005;
            LocalBaseline=RoughBaseline(addIdxs(indexEPSC,2));

            if app.yData(addIdxs(indexEPSC,2))-LocalBaseline>-15
               MaxRisePhaseTime=0.0035;
            end


            RisePhaseXrev=fliplr(addIdxs(indexEPSC,2)-round(MaxRisePhaseTime/app.fileSI):addIdxs(indexEPSC,2));
            RisePhaseYrev=smoothyDataAvg(RisePhaseXrev);
            RisePhaseYrev=RisePhaseYrev-max(RisePhaseYrev);

            if any(RisePhaseYrev(round(5e-04/app.fileSI):end-round(0.002/app.fileSI))<0.92*RisePhaseYrev(1))
               IPOINTS = findchangepts(RisePhaseYrev, 'Statistic', 'linear');
               RisePhaseXrev=RisePhaseXrev(IPOINTS+1):-1:RisePhaseXrev(IPOINTS+1)-round(MaxRisePhaseTime/app.fileSI);
               RisePhaseYrev=smoothyDataAvg(RisePhaseXrev);
            end


            NewRiseIndex=RisePhaseXrev(find(movsum(diff(RisePhaseYrev)<0.3,[0 round(4*100e-6/app.fileSI)])>round(4*100e-6/app.fileSI),1,'first'));

            if isempty(NewRiseIndex)
               NewRiseIndex=RisePhaseXrev(find(movsum(diff(RisePhaseYrev)<0.3,[0 round(4*100e-6/app.fileSI)])>round(3.5*100e-6/app.fileSI),1,'first'));
            end

            if isempty(NewRiseIndex)
               NewRiseIndex=RisePhaseXrev(find(movsum(diff(RisePhaseYrev)<0.4,[0 round(4*100e-6/app.fileSI)])>round(4*100e-6/app.fileSI),1,'first'));
            end

            if isempty(NewRiseIndex)
               NewRiseIndex=RisePhaseXrev(find(movsum(diff(RisePhaseYrev)<0.4,[0 round(4*100e-6/app.fileSI)])>round(3.5*100e-6/app.fileSI),1,'first'));
            end

            if isempty(NewRiseIndex)
               NewRiseIndex=RisePhaseXrev(find(movsum(diff(RisePhaseYrev)<0.5,[0 round(4*100e-6/app.fileSI)])>round(4*100e-6/app.fileSI),1,'first'));
            end
            if isempty(NewRiseIndex)
               NewRiseIndex=RisePhaseXrev(find(movsum(diff(RisePhaseYrev)<0.5,[0 round(5*100e-6/app.fileSI)])>round(3.5*100e-6/app.fileSI),1,'first'));
            end
            if isempty(NewRiseIndex)
               NewRiseIndex=RisePhaseXrev(find(movsum(diff(RisePhaseYrev)<0.6,[0 round(5*100e-6/app.fileSI)])>round(4*100e-6/app.fileSI),1,'first'));
            end
            if isempty(NewRiseIndex)
               NewRiseIndex=RisePhaseXrev(find(movsum(diff(RisePhaseYrev)<0.6,[0 round(5*100e-6/app.fileSI)])>round(3*100e-6/app.fileSI),1,'first'));
            end

            if ~isempty(NewRiseIndex)
               addIdxs(indexEPSC,1)=NewRiseIndex;
            else
               %Lets try to find miniOnset:
               miniRiseWindowRange=addIdxs(indexEPSC,2)-round(0.0025/app.fileSI):addIdxs(indexEPSC,2);
               miniRiseWindowRange=miniRiseWindowRange(:);
               miniRiseWindow=app.yData(miniRiseWindowRange);

               diff_RiseWindow=sgolayfilt(diff(miniRiseWindow),2, round(ceil((0.0017/app.fileSI)/2)*2+1));
               diff_RiseWindow=[diff_RiseWindow; diff_RiseWindow(end)]; %#ok<AGROW>

               RiseWindow_DownOnly=zeros(size(diff_RiseWindow));
               for j = 2:length(RiseWindow_DownOnly)
                  if diff_RiseWindow(j-1) <= 0
                     RiseWindow_DownOnly(j) = RiseWindow_DownOnly(j-1) + diff_RiseWindow(j-1);
                  end
               end
               %check=[ miniRiseWindowRange miniRiseWindow diff_RiseWindow RiseWindow_DownOnly]
               OnsetRangeIdx=find(movsum(diff_RiseWindow,[0 round(7*1e-4/app.fileSI)])<0.75*TriggerVal_Derivative,1,'first');

               if isempty(OnsetRangeIdx)
                  OnsetRangeIdx=find(movsum(diff_RiseWindow,[0 round(7*1e-4/app.fileSI)])<0.6*TriggerVal_Derivative,1,'first');
               end

               if isempty(OnsetRangeIdx)
                  OnsetRangeIdx=find(RiseWindow_DownOnly==0,1,'last')-1;
               end

               if isempty(OnsetRangeIdx)
                  OnsetRangeIdx=find(movsum(diff_RiseWindow,[0 round(7*1e-4/app.fileSI)])<0.4*TriggerVal_Derivative,1,'first');
               end

               if and(~isempty(OnsetRangeIdx),OnsetRangeIdx>2)
                  [~,idx]=max(miniRiseWindow(max(OnsetRangeIdx-2,1):min(OnsetRangeIdx+2,length(miniRiseWindow))));
                  addIdxs(indexEPSC,1)=miniRiseWindowRange(OnsetRangeIdx+idx);
               else
                  [~,MaxRangeIdx]=max(miniRiseWindow);
                  addIdxs(indexEPSC,1)=miniRiseWindowRange(MaxRangeIdx);
               end
            end


            decayEndIdx=[];
            decayStartIdx=[];
            decayEndGrandIdx=[];

            FitCurdecayWindow=app.FitdecayWindow.Value;

            if abs(app.yData(addIdxs(indexEPSC,2))-app.yData(addIdxs(indexEPSC,1)))<8
               FitCurdecayWindow=app.FitdecayWindow.Value*0.5;
            elseif abs(app.yData(addIdxs(indexEPSC,2))-app.yData(addIdxs(indexEPSC,1)))<15
               FitCurdecayWindow=app.FitdecayWindow.Value*0.65;
            elseif abs(app.yData(addIdxs(indexEPSC,2))-app.yData(addIdxs(indexEPSC,1)))<25
               FitCurdecayWindow=app.FitdecayWindow.Value*0.8;
            end

            %reduce decayWindow if theres any events inbetween
            diffWithOtherIdxs=addIdxs(:,1)-addIdxs(indexEPSC,2);
            NextIndex=find(and(diffWithOtherIdxs>0,diffWithOtherIdxs<(round(FitCurdecayWindow/app.fileSI))),1,'first');

            if ~isempty(NextIndex)
               FitCurdecayWindow=diffWithOtherIdxs(NextIndex)*app.fileSI;
            end

            NextIndex=[];
            NewRiseIndex=[];


            if (addIdxs(indexEPSC,2)+(FitCurdecayWindow/app.fileSI))>length(app.yDataFilt)
               decayEndGrandIdx=length(app.yDataFilt);
            else
               decayEndGrandIdx=addIdxs(indexEPSC,2)+(FitCurdecayWindow/app.fileSI);
            end
            decayY=app.yData(addIdxs(indexEPSC,2):decayEndGrandIdx);

            %check for  events during decay:
            MaxSumIdx=[];
            RangeIdxMax=[];
            rangeIdxs=[];
            downOnlyCurDecayStart=[];

            downOnlyCurDecay=downOnly(addIdxs(indexEPSC,2):decayEndGrandIdx);

            if ~downOnlyCurDecay(1)==0
               downOnlyCurDecayStart=find(downOnlyCurDecay==0,1,'first');
            else
               downOnlyCurDecayStart=1;
            end


            TriggerVal_DownOnly=round(TriggerVal_Derivative*2);

            sumDownonly_CurDecay=movsum(downOnlyCurDecay(downOnlyCurDecayStart:end),[round(4*1e-4/app.fileSI) 0]);
            MaxSumIdx=find(sumDownonly_CurDecay<TriggerVal_DownOnly,1,'first')+downOnlyCurDecayStart-1; %where trailing movsum of Downonly is first below -3.5
            NextRiseIdx=find(movsum(diff(decayY),[round(6*1e-4/app.fileSI) 0])<TriggerVal_Derivative,1,'first')-round(3*1e-4/app.fileSI); %where trailing sum of derivative is <-8


            if ~app.SumDerivativeCheckBox.Value
               NextRiseIdx=[];
            end

            if ~app.SumDownOnlyCheckBox.Value
               MaxSumIdx=[];
            end


            if and(~isempty(NextRiseIdx),~isempty(MaxSumIdx))
               MaxSumIdx=min(NextRiseIdx,MaxSumIdx);
            elseif and(~isempty(NextRiseIdx),isempty(MaxSumIdx))
               MaxSumIdx=NextRiseIdx;
            end


            if ~isempty(MaxSumIdx)
               %new event started probably earlier, so find max value
               %of yData before event
               MaxSumIdx=min(MaxSumIdx,length(decayY));
               rangeIdxs=MaxSumIdx-15:MaxSumIdx;
               rangeIdxs=rangeIdxs(rangeIdxs>0);
               [~,RangeIdxMax]=max(decayY(rangeIdxs));
               MaxSumIdx=rangeIdxs(RangeIdxMax);
               % terminate decayY before new event
            else
               MaxSumIdx=length(decayY);%else take whole length for now
            end


            decayY=decayY(1:MaxSumIdx);


            %subtract baseline before mini onset
            decayY=decayY-app.yDataFilt(addIdxs(indexEPSC,1));
            decayY=decayY(:);

            addFitVals(indexEPSC,1)=app.yDataFilt(addIdxs(indexEPSC,1)); %1 = local baseline
            addFitVals(indexEPSC,2)=app.yData(addIdxs(indexEPSC,2))-addFitVals(indexEPSC,1);%2=Amplitude
            addFitVals(indexEPSC,7)=abs(diff(addIdxs(indexEPSC,:)))*app.fileSI;   %7=risetime
            addRiseYs{indexEPSC}=app.yDataFilt(addIdxs(indexEPSC,1):addIdxs(indexEPSC,2));

            if or(abs(addFitVals(indexEPSC,2))<abs(app.MinAmp.Value), abs(addFitVals(indexEPSC,2))>abs(app.MaxAmp.Value))
               addIsMini(indexEPSC,2)=false; %Dont fit if amp < minAmp or amp>MaxAmp
            end

            if length(decayY)<5
               addIsMini(indexEPSC,2)=false;
               continue
            end

            if app.SgolaySpan.Value>length(decayY)
               CurSpan=length(decayY)-1;
            elseif ceil(app.SgolaySpan.Value*length(decayY))<3
               CurSpan=2.000001/length(decayY);
            else
               CurSpan=app.SgolaySpan.Value;
            end
            decayY_smooth=smooth(decayY, CurSpan, 'sgolay');

            AmpRecov=[0.97 0.03]; %we'll fit 3-97%

            if or(isempty(decayY)||length(decayY)<5,~any(decayY>(AmpRecov(1)*decayY(1))))%no recovery
               addIsMini(indexEPSC,2)=false; %.. again
               continue
            end

            decayStartIdx=find(decayY>(AmpRecov(1)*decayY_smooth(1)),1,'first'); %find idx
            decayEndIdx=find(movsum(decayY_smooth>(AmpRecov(2)*(min(decayY))),2)==2,1,'first')-1; %find 1st instance where recovery is AmpRecov(2) for at least 2 datapoints

            if isempty(decayStartIdx)
               decayStartIdx=3;
            end

            if isempty(decayEndIdx)
               if any(decayY_smooth>((AmpRecov(2))*decayY_smooth(1)))
                  decayEndIdx=find(decayY_smooth>((AmpRecov(2))*decayY_smooth(1)),1,'first');
               elseif any(decayY_smooth>((AmpRecov(2)+0.05)*decayY_smooth(1)))
                  decayEndIdx=find(decayY_smooth>((AmpRecov(2)+0.05)*decayY_smooth(1)),1,'first');
                  addFitVals(indexEPSC,14)=AmpRecov(2)+0.05;
                  %                elseif any(decayY_smooth>((AmpRecov(2)+0.1)*decayY_smooth(1)))
                  %                   decayEndIdx=find(decayY_smooth>((AmpRecov(2)+0.1)*decayY_smooth(1)),1,'first');
                  %                   addFitVals(indexEPSC,14)=AmpRecov(2)+0.1;
                  %                elseif any(decayY_smooth>((AmpRecov(2)+0.15)*decayY_smooth(1)))
                  %                   decayEndIdx=find(decayY_smooth>((AmpRecov(2)+0.15)*decayY_smooth(1)),1,'first');
                  %                   addFitVals(indexEPSC,14)=AmpRecov(2)+0.15;
                  %                elseif any(decayY_smooth>((AmpRecov(2)+0.2)*decayY_smooth(1)))
                  %                   decayEndIdx=find(decayY_smooth>((AmpRecov(2)+0.2)*decayY_smooth(1)),1,'first');
                  %                   addFitVals(indexEPSC,14)=AmpRecov(2)+0.2;
               end
            else
               addFitVals(indexEPSC,14)=AmpRecov(2);
            end

            if ~isempty(decayEndIdx)
               decayY=decayY(decayStartIdx:decayEndIdx);
               decayY_smooth=decayY_smooth(decayStartIdx:decayEndIdx);
            end

            if length(decayY)>5
               addFitVals(indexEPSC,6)=(sum(addRiseYs{indexEPSC})+sum(decayY_smooth))*-app.fileSI; %app.FitVals 6 = charge
               addFitVals(indexEPSC,11)=length(decayY);  %FitVals 11 = DecayFitLength (points)
               addFitVals(indexEPSC,13)=decayStartIdx;

               decayX=app.fileSI:app.fileSI:length(decayY)*app.fileSI;
               decayX=decayX(:);

               if app.FitSmoothData.Value
                  decayY=decayY_smooth(:);
               else
                  decayY=decayY(:);
               end

               %estimate starting points
               pars0 = [decayY(1) 0.002 decayY_smooth(end)];

               switch app.FitMethodDropDown.Value
                  case 'Fit NLLS'
                     opts=fitoptions('Method','nonlinearleastsquares');

                     if app.RobustmethodCheckBox.Value
                        opts.Robust='LAR';
                     else
                        opts.Robust='Off';
                     end

                     opts.StartPoint=pars0;
                     opts.Lower=[1.1*decayY(1) 0.0001 decayY(1)-abs(decayY(end))];
                     opts.Upper=[5 0.05 500];

                     if any(opts.Lower>opts.Upper)
                        opts.Lower=[decayY(1)-10 0.0001 decayY(end)-80];
                        opts.Upper=[decayY(1)+10 0.05 decayY(end)+10];
                     end

                     opts.TolX=1e-8;
                     opts.MaxFunEvals=800;
                     opts.MaxIter=550;


                     try
                        [fit0bj,gof]=fit(decayX, decayY,'a*exp(-x/b)+c',opts);
                     catch
                        fprintf('error encountered index:%d\n', indexEPSC);
                        addFitVals(indexEPSC,11)=NaN;
                        addFitVals(indexEPSC,13)=NaN;
                        app.FitVals(indexEPSC,11)=NaN; %DecayFitLength
                        app.FitVals(indexEPSC,13)=NaN;
                        addIsMini(indexEPSC,2)=false;
                        continue

                     end


                     addFitVals(indexEPSC,3:5)=coeffvalues(fit0bj);

                     addDecayFits{indexEPSC}=[(addIdxs(indexEPSC,2)+decayStartIdx:(addIdxs(indexEPSC,2)+decayStartIdx+length(decayY)-1))'.*app.fileSI feval(fit0bj,decayX)+addFitVals(indexEPSC,1)];
                     addFitVals(indexEPSC,9)=gof.adjrsquare;
                     %                             addDecayFits{indexEPSC}=[[app.Idxs(indexEPSC,2):1:(app.Idxs(indexEPSC,2)+length(decayY)-1)]'.*app.fileSI feval(fit0bj,decayX)+addFitVals(indexEPSC,1)];
                  case 'fminsearch'
                     disp('fminsearch doesnt work atm. Please use another Fit method');
                  case 'lsqcurvefit'

                     lowerBound=[1.2*decayY(1) 0.0001 -0.6*abs(addFitVals(indexEPSC,2))];
                     upperBound=[0.8*decayY(1) 0.05 0.15*abs(addFitVals(indexEPSC,2))];
                     lsqOpts=optimoptions('lsqcurvefit');
                     %other options: lsqOpts.Algorithm='levenberg-marquardt';
                     %current: lsqOpts.Algorithm='trust-region-reflective';
                     lsqOpts.FunctionTolerance=1e-9;
                     lsqOpts.MaxIterations=600;
                     lsqOpts.StepTolerance=1e-10;
                     lsqOpts.OptimalityTolerance=1e-10;
                     lsqOpts.Display='off';
                     lsqOpts.Diagnostics='off';
                     lsqOpts.DiffMaxChange;
                     lsqOpts.DiffMinChange;

                     F = @(x,decayX)x(1)*exp(-x(2)*decayX) + x(3);
                     [lsq1.xfunc,lsq1.resnorm,lsq1.residual,lsq1.exitflag,lsq1.output,lsq1.lambda]=lsqcurvefit(F, pars0, decayX, decayY,lowerBound,upperBound,lsqOpts);
                     addFitVals(indexEPSC,3:5)=lsq1.xfunc;

                     addDecayFits{indexEPSC}=[(addIdxs(indexEPSC,2)+decayStartIdx:(addIdxs(indexEPSC,2)+decayStartIdx+length(decayY)-1))'.*app.fileSI F(lsq1.xfunc,decayX)+addFitVals(indexEPSC,1)];
                     [~, addFitVals(indexEPSC,9)]=CalcRsq(decayY,lsq1.residual,lsq1.xfunc);

               end
            else
               addFitVals(indexEPSC,8)=1; %BadFit on
               addIsMini(indexEPSC,2)=false;
            end
         end

         addFitVals(:,10)=abs(addFitVals(:,3)-addFitVals(:,2))./abs(addFitVals(:,2)); %normalised diff between measured amp and fitted amp
         addFitVals(:,12)=true(size(addFitVals,1),1);

         addBadFits(:,1)=addFitVals(:,4)>app.MaxDecayTau.Value/1000; %fitted decay time too long
         addBadFits(:,2)=addFitVals(:,10)>0.3; %AmpDev too high
         addBadFits(:,3)=addFitVals(:,11)<5; % badfit if fitlength is less than 5
         addBadFits(:,4)=addFitVals(:,9)<app.MinRsqSpinner.Value; %badfit if Rsq_adj value isnt reached
         addFitVals(:,8)=logical(any([addFitVals(:,8) addBadFits],2)); %Update badfit with any badfit

         app.Idxs=[app.Idxs; addIdxs];
         app.FitDatas.isMini=[app.FitDatas.isMini; addIsMini];

         if ~(size(addFitVals,2)==size(app.FitVals,2))
            addCols=nan(size(app.FitVals,1),size(addFitVals,2)-size(app.FitVals,2));
            app.FitVals=[app.FitVals addCols];
         end


         app.FitVals=[app.FitVals;addFitVals];
         app.FitDatas.BadFits=[app.FitDatas.BadFits;addBadFits];
         app.FitDatas.decayFits=[app.FitDatas.decayFits; addDecayFits];

         if size(app.Idxs,1)>size(app.FitVals,1)
            [c2,~,~] = unique(app.Idxs(:,2),'stable');
            app.Idxs=app.Idxs(c2,:);
         end

         [~,sortIdx] = sort(app.Idxs(:,2));
         app.Idxs=app.Idxs(sortIdx,:);
         app.FitDatas.isMini=app.FitDatas.isMini(sortIdx,:);
         app.FitVals=app.FitVals(sortIdx,:);
         app.FitDatas.BadFits=app.FitDatas.BadFits(sortIdx,:);

         if length(app.FitDatas.decayFits)~=length(sortIdx)
            while length(sortIdx)>length(app.FitDatas.decayFits)
               app.FitDatas.decayFits{end+1}=[];
            end
         end


         app.FitDatas.decayFits=app.FitDatas.decayFits(sortIdx,:);
         app.ManualSelectionData.addDecayYs=addDecayYs;  %dont know if im gonna do anything with this yet
         app.ManualSelectionData.addRiseYs=addRiseYs; %dont know if im gonna do anything with this yet

         %             Remove any events that are outside of fit intervals (in case
         %             analysis window has changed)

         endIdx1=round(app.Interval1End.Value/app.fileSI);
         startIdx2=round(app.Interval2start.Value/app.fileSI);
         endIdx2=round(app.Interval2end.Value/app.fileSI);

         remove_outsideInt=or(and(any(app.Idxs>endIdx1,2),any(app.Idxs<startIdx2,2)),...
            any(app.Idxs>endIdx2,2));
         app.Idxs=app.Idxs(~remove_outsideInt,:);
         app.FitDatas.isMini=app.FitDatas.isMini(~remove_outsideInt,:);
         app.FitDatas.BadFits=app.FitDatas.BadFits(~remove_outsideInt,:);
         app.FitVals=app.FitVals(~remove_outsideInt,:);
         app.FitDatas.decayFits=app.FitDatas.decayFits(~remove_outsideInt,:);


         delete(app.ax4);
         app.ax4 = uiaxes(app.Tab2Grid1);
         xlabel(app.ax4, 'Time (s)')
         ylabel(app.ax4, 'I (pA)')
         zlabel(app.ax4, 'Z')
         app.ax4.FontName = 'PT Sans';
         app.ax4.ColorOrder = [0 0.447 0.741;0.85 0.325 0.098;0.929 0.694 0.125;0.494 0.184 0.556;0.466 0.674 0.188;0.301 0.745 0.933;0.635 0.078 0.184];
         app.ax4.Layout.Row = 1;
         app.ax4.Layout.Column = 1;
         app.ax4.Tag = 'TagAx4';
         app.ax4.UserData.ClickedPoints=[];
         app.ax4.UserData.AddPoints=[];
         app.ax4.UserData.AddedMiniCoor=[];
         app.ax4.UserData.RejectMiniCoords=[];
         app.ax4.UserData.AddFormerlyRejectedData=[];
         app.ax4.PlotBoxAspectRatioMode='auto';
         app.ax4.UserData.Remarks=[];

         hold(app.ax4,'on')
         plot(app.ax4,(1:length(app.yData)).*app.fileSI, sgolayfilt(app.yData,2,ceil(((5*100e-6)/app.fileSI)/2)*2+1),'Color',[0.9 0.9 0.9],'LineWidth', ...
            app.MiniFigure.UserData.tailoredLineWidth,'Tag','PlotAx4origyData');

         hold(app.ax4,'on')
         plot(app.ax4,(1:length(app.yDataFilt)).*app.fileSI,app.yDataFilt,'Color',[0.3 0.35 0.35],'LineWidth',app.TracesLineWidthSpinner.Value,'Tag','PlotAx4yDataFilt');

         scatter(app.ax4, app.Idxs(app.FitDatas.isMini(:,1),2).*app.fileSI,app.yData(app.Idxs(app.FitDatas.isMini(:,1),2)),app.MarkerSizeSpinner.Value, ...
            [0.0 0.95 0.0],"o",'LineWidth',app.MarkerLineWidthSpinner.Value,'Tag','ScatFittedMinis');

         app.Lamp.Color=[0 1 0];
         app.ax4.XLim=[app.Interval1Start.Value app.Interval1Start.Value+app.Tab2_dXSlider.Value];
         app.ax4.YLim=[median(app.yData)-0.66666*app.Tab2_dYSlider.Value median(app.yData)+0.3333*app.Tab2_dYSlider.Value];

         app.TextArea.Value{end+1}=sprintf('New of %d trace analysis finised. Dont forget to update dataStruct!',1);
         app.UpdateanalyseddataButton.FontColor=[0 0 1];


      end

      % Button pushed function: FinishHere_interval1
      function GoFinishCurrentXLim(app, event)
         FinishTimepnt=app.ax4.XLim(1)+(0.99*diff(app.ax4.XLim)); %take 99% of current XLims as ending point
         FinishIdx=round(FinishTimepnt/app.fileSI);
         FinishTimepnt=FinishIdx*app.fileSI;
         app.FitInterval(1,2)=FinishTimepnt;
         app.Interval1End.Value=FinishTimepnt;


      end

      % Button pushed function: FinishHere_interval2
      function GoFinishCurrentXLimInt2(app, event)
         FinishTimepnt=app.ax4.XLim(1)+(0.99*diff(app.ax4.XLim)); %take 99% of current XLims as ending point
         FinishIdx=round(FinishTimepnt/app.fileSI);
         FinishTimepnt=FinishIdx*app.fileSI;
         app.FitInterval(2,2)=FinishTimepnt;
         app.Interval2end.Value=FinishTimepnt;


      end

      % Value changed function: Interval1End, Interval1Start
      function ChangeAx4LimToFitInterval(app, event)
         if app.Interval1End.Value>app.Interval1Start.Value
            app.ax4.XLim=[app.Interval1Start.Value app.Interval1End.Value];
         else
            app.ax4.XLim=[app.Interval1Start.Value app.Interval1Start.Value+3];
         end
         app.Tab2_XLim1.Value=app.Interval1Start.Value;
      end

      % Callback function
      function ClearAxes4Data(app, event)
         if ~isempty(or(app.ax4.UserData.RejectDataPoints,app.ax4.UserData.AddedMiniCoor))
            selection = uiconfirm(app.MiniFigure, 'Clear Current Data?', 'Confirm Clear', 'Options', {'Yes','No'}, ...
               'DefaultOption', 'Yes', 'CancelOption', 'No','Icon','warning');
         else
            disp('nothing to clear');
         end
      end

      % Button pushed function: NextFileDropBtn
      function GoToNextDropFile(app, event)
         CurItemIdx=find(strcmpi(app.FilesDrop.ItemsData,app.FilesDrop.Value));
         if CurItemIdx==length(app.FilesDrop.ItemsData)
            nextItemIdx=1;
         else
            nextItemIdx=CurItemIdx+1;
         end
         app.FilesDrop.Value=app.FilesDrop.ItemsData(nextItemIdx);
         HaveChangedFileDrop(app, event)
      end

      % Button pushed function: PreviousFileDropBtn
      function GoToPrevDropFile(app, event)
         CurItemIdx=find(strcmpi(app.FilesDrop.ItemsData,app.FilesDrop.Value));
         if CurItemIdx==1
            nextItemIdx=length(app.FilesDrop.ItemsData);
         else
            nextItemIdx=CurItemIdx-1;
         end
         app.FilesDrop.Value=app.FilesDrop.ItemsData(nextItemIdx);
         HaveChangedFileDrop(app, event)
      end

      % Button pushed function: ZoomresetBtn
      function TurnOnZoomReset(app, event)
         zoom(app.MiniFigure,'reset');
      end

      % Value changed function: PanBtn
      function TurnOnPan(app, event)
         switch app.PanBtn.Value
            case 1
               pan(app.MiniFigure,'on');
               disableDefaultInteractivity(app.MiniFigure.CurrentAxes);
            case 0
               pan(app.MiniFigure,'off');
               enableDefaultInteractivity(app.MiniFigure.CurrentAxes);
         end
      end

      % Value changed function: ZoomInBtn
      function OnOffZoomIn(app, event)
         switch app.ZoomInBtn.Value
            case 1
               disableDefaultInteractivity(app.MiniFigure.CurrentAxes);
               zoom(app.MiniFigure,'on','Direction','in','RightClickAction','InverseZoom');
            case 0
               enableDefaultInteractivity(app.MiniFigure.CurrentAxes);
               zoom(app.MiniFigure,'off')
         end
      end

      % Callback function
      function OnOffZoomXonly(app, event)
         switch app.ZoomXonlyBtn.Value
            case 1
               disableDefaultInteractivity(app.MiniFigure.CurrentAxes);
               zoom(app.MiniFigure,'xon','RightClickAction','InverseZoom');
            case 0
               enableDefaultInteractivity(app.MiniFigure.CurrentAxes);
               zoom(app.MiniFigure,'off');
         end
      end

      % Button pushed function: GoNextfileIdxBtn
      function GoLoadNextfileIdx(app, event)

         if or(~isfield(app.LoaddataStructButton.UserData,'fileIdx'),isempty(app.LoaddataStructButton.UserData.fileIdx))
            app.TextArea.Value={'Next trace is only availible when analysed data is loaded'};
         else
            app.LoaddataStructButton.UserData.fileIdx=app.LoaddataStructButton.UserData.fileIdx+1;

            if app.LoaddataStructButton.UserData.fileIdx<=app.LoaddataStructButton.UserData.dataStructLength
               LoadDataStructData(app,app.LoaddataStructButton.UserData.fileIdx);
            else
               app.TextArea.Value={'Last data trace, no next data availible'};
            end
         end
      end

      % Button pushed function: GoPreviousfileIdxBtn
      function GoLoadPreviousfileIdx(app, event)


         if or(~isfield(app.LoaddataStructButton.UserData,'fileIdx'),isempty(app.LoaddataStructButton.UserData.fileIdx))
            app.TextArea.Value={'Previous trace is only availible when analysed data is loaded'};
         else
            app.LoaddataStructButton.UserData.fileIdx=app.LoaddataStructButton.UserData.fileIdx-1;
            if app.LoaddataStructButton.UserData.fileIdx>=1
               LoadDataStructData(app,app.LoaddataStructButton.UserData.fileIdx)
            else
               app.TextArea.Value={'First trace in data, no previous data availible'};
            end
         end
      end

      % Button pushed function: SaveAllButton
      function SaveAllData(app, event)
         if or(~isfield(app.MiniFigure.UserData,'filePath'),isempty(app.MiniFigure.UserData.filePath))
            app.MiniFigure.UserData.filePath=fullfile(app.FilesDrop.UserData.pathName,[app.FilesDrop.Value '.abf']);
         end
         dataStruct=struct();

         [FPath,Fname,~] = fileparts(app.MiniFigure.UserData.filePath);
         dataStruct.filename=Fname;
         dataStruct.yData=app.yData;
         dataStruct.yDataFilt=app.yDataFilt;
         dataStruct.fileSI=app.fileSI;
         dataStruct.FitInterval=app.FitInterval;
         dataStruct.Idxs=app.Idxs;
         dataStruct.isMini=app.FitDatas.isMini;
         dataStruct.FitVals=app.FitVals;
         dataStruct.BadFits=app.FitDatas.BadFits;
         dataStruct.decayFits=app.FitDatas.decayFits;
         dataStruct.Updated=date;
         if isfield(app.ax4.UserData,'Remarks')
            dataStruct.Remarks=app.ax4.UserData.Remarks;
         else
            dataStruct.Remarks=[];
         end


         dataStruct.FitParameters=struct('TriggerVal', app.TriggerNextEventDropDown.Value,'DecaySearchWindow',...
            app.FitdecayWindow.Value,'FitSmooth', app.FitSmoothData.Value,'SgolaySpan',app.SgolaySpan.Value,...
            'Constraints',[app.MinAmp.Value app.MaxAmp.Value;app.MinDecayTau.Value app.MaxDecayTau.Value]);


         %make sure that all data is within app.FitInterval
         FitIntDataIdxs=round(dataStruct.FitInterval./app.fileSI);
         WhithinInterval1=and(dataStruct.Idxs(:,2)> FitIntDataIdxs(1,1),dataStruct.Idxs(:,2)< FitIntDataIdxs(1,2));
         if size(dataStruct.FitInterval,1)>1
            WhithinInterval2=and(dataStruct.Idxs(:,2)> FitIntDataIdxs(2,1),dataStruct.Idxs(:,2)< FitIntDataIdxs(2,2));
         else
            WhithinInterval2=false(size(dataStruct.Idxs(:,2)));
         end

         dataStruct.Idxs=dataStruct.Idxs(WhithinInterval1 | WhithinInterval2,:);
         dataStruct.isMini=dataStruct.isMini(WhithinInterval1 | WhithinInterval2,:);
         dataStruct.FitVals=dataStruct.FitVals(WhithinInterval1 | WhithinInterval2,:);
         dataStruct.BadFits=dataStruct.BadFits(WhithinInterval1 | WhithinInterval2,:);
         dataStruct.decayFits=dataStruct.decayFits(WhithinInterval1 | WhithinInterval2,:);

         if app.ExportFullMinis.Value
            fullMinis=cell(length(dataStruct.Idxs(:,2)),1);
            for n=1:length(dataStruct.Idxs)
               if isempty(dataStruct.decayFits(n))
                  continue
               else
                  fullMiniRange=dataStruct.Idxs(n,1)-20:dataStruct.Idxs(n,2)+dataStruct.FitVals(n,11);
                  fullMinis{n}=dataStruct.yData(fullMiniRange);
                  fullMiniRange=[];
               end
            end
         end

         if app.SaveMinisWithData.Value
            dataStruct.fullMinis=fullMinis;
         else
            dataStruct.fullMinis=[];
         end

         outputVars={'dataStruct'};
         if ~any(contains(app.SaveOptionsListBox.Value,'Save Filtered Data (Tab2)'))
            dataStruct=rmfield(dataStruct,'yDataFilt'); %#ok<*STRNU>
         end

         if ~any(contains(app.SaveOptionsListBox.Value,'Save Decay Fits'))
            dataStruct.FitDatas=rmfield(dataStruct.FitDatas,'decayFits'); %#ok<*STRNU>
         end


         if any(contains(app.SaveOptionsListBox.Value,'Create dataHeaders'))
            Header_FitVals={'local baseline','Amplitude','Fitted Amplitude','decayTau','decayoffset','charge',...
               'riseTime','BadFit','rsq','Amplitude deviation (measured vs fit)','DecayFitLength','Manually Selected'};
            Header_BadFits={'Tau too large','Fitted amp deviates too much from real amp',...
               'Not enough datapoints for fit','Rsq_adj <  MinRsq'};
            Header_isMini={'Categorised as mini or not before fitting; based on Risetime, MinAmp, Tailend of Data (all cannot fit)',...
               'Categorised after fitting; 0 if fit not possible'}; %#ok<*NASGU>
         end


         if app.AppendtodataStructCheckBox.Value
            if app.NewdataStructCheckBox.Value %Make new dataStruct
               pathName = uigetdir();
               matfileName=app.MatFileNameEditField.Value;
            else %Add to existing file
               [matfileName,pathName] = uigetfile('*.mat');
            end

            if ~isempty(nonzeros([matfileName,pathName]))
               cd(pathName)
               %first save as single recs cause we might remove fields afterwards
               if ~isfolder('Matfiles_singleRecs')
                  mkdir('Matfiles_singleRecs');
               end
               cd('Matfiles_singleRecs')
               save(sprintf('%s',dataStruct.filename), 'dataStruct', '-v7.3');
               app.TextAreaSaveOut.Value(end+1)={'Saved single rec'};


               cd(pathName);

               MATOBJ = matfile(matfileName,'Writable',true);
               fields1=fieldnames(MATOBJ.dataStruct);
               fields2=fieldnames(dataStruct);
               if numel(fields1)>numel(fields2)
                  [~, loc1] = ismember(cellstr(fields1), cellstr(fields2));
                  dataStruct.(fields1{(loc1==0)})={};
                  % dataStruct2 = setfield(dataStruct2,fields1{(loc1==0)},{});
               elseif numel(fields1)<numel(fields2)
                  [~, loc1] = ismember(cellstr(fields2), cellstr(fields1));
                  dataStruct=rmfield(dataStruct,fields2{(loc1==0)});
               end
               MATOBJ.dataStruct=[MATOBJ.dataStruct dataStruct];
               app.TextAreaSaveOut.Value{end+1}=sprintf('Succesfully appended %s to dataStruct',app.FilesDrop.Value);

               if any(contains(app.SaveOptionsListBox.Value,'Create dataHeaders'))
                  if exist(fullfile(pathName,'dataHeaders.mat'),'file')==2
                     delete(fullfile(pathName,'dataHeaders.mat'));
                  end
                  save('dataHeaders','Header_BadFits', 'Header_isMini', 'Header_FitVals', '-v7.3');
               end

            else
               disp('Didnt save, user selected cancel')

            end
         else %save only as single rec
            [filename1, filepath1] = uiputfile([dataStruct.filename '.mat'], 'Save analysis file:');
            if ~isempty(nonzeros([filepath1,filename1]))
               filename1 = fullfile(filepath1, filename1);
               save(sprintf('%s',filename1), 'dataStruct', '-v7.3');

               if any(contains(app.SaveOptionsListBox.Value,'Create dataHeaders'))
                  if exist(fullfile(filepath1,'dataHeaders.mat'),'file')==2
                     delete(fullfile(filepath1,'dataHeaders.mat'));
                  end
                  curdir=pwd;
                  cd(filepath1)
                  save('dataHeaders','Header_BadFits', 'Header_isMini', 'Header_FitVals', '-v7.3');
                  cd(curdir);
               end
            else
               disp('Didnt save, user selected cancel')
            end
         end %of saving to existing datastruct vs saving as single rec only


         %    If move abf files is checked, move curent file to FinishedFolder
         if app.MoveAbfFileToAnalysedCheckBox.Value
            curdir=pwd;
            cd(FPath)
            if ~isfolder('abfFiles Finished')
               mkdir('abfFiles Finished');
            end
            % path=app.MiniFigure.UserData.filePath(1:endPath);fileName=app.MiniFigure.UserData.filePath(endPath+1:end);
            if exist(fullfile(FPath,[Fname '.abf']),'file')
               movefile(fullfile(FPath,[Fname '.abf']),fullfile(FPath,'abfFiles Finished'),"f");
            end

            if app.MoveDoubleRecsCheckBox.Value
               dirfiles=dir('*.abf');
               dirfiles={dirfiles(:).name}';
               idxOtherfiles=find(contains(dirfiles,Fname(1:end-5)));
               if ~isempty(idxOtherfiles)
                  for i=1:length(idxOtherfiles)
                     movefile(fullfile(FPath,dirfiles{idxOtherfiles(i)}),fullfile(FPath,'abfFiles Finished'),"f");
                  end
               end
            end
            cd(curdir)
         end

         %if were analysing a bunch of data, remove current filename from dropdown list
         if ~strcmpi(app.FilesDrop.Value,'No data yet')&&numel(app.FilesDrop.Items)>1
            CurItemIdx=find(strcmpi(app.FilesDrop.ItemsData,app.FilesDrop.Value));
            if CurItemIdx==length(app.FilesDrop.ItemsData)
               nextItemIdx=1;
            else
               nextItemIdx=CurItemIdx+1;
            end

            app.FilesDrop.Value=app.FilesDrop.ItemsData(nextItemIdx);
            HaveChangedFileDrop(app, event)

            app.FilesDrop.ItemsData(CurItemIdx)=[];
            app.FilesDrop.Items(CurItemIdx)=[];

            if length(app.FilesDrop.ItemsData)>=CurItemIdx
               app.FilesDrop.Value=app.FilesDrop.ItemsData(CurItemIdx);
            elseif (CurItemIdx-1)>0
               app.FilesDrop.Value=app.FilesDrop.ItemsData(CurItemIdx-1);
            end

            HaveChangedFileDrop(app, event)
         end
      end

      % Button pushed function: LoaddataStructButton
      function GoLoaddataStruct(app, event)
         [matfileName,pathName] = uigetfile('*.mat');
         if ~isempty(nonzeros([matfileName,pathName]))
            addpath(pathName);
            cd(pathName);
            MATOBJ = matfile(matfileName,'Writable',true);
            fileIdx=1;
            app.LoaddataStructButton.UserData.matfileName=matfileName;
            app.LoaddataStructButton.UserData.matpathName=pathName;
            app.LoaddataStructButton.UserData.dataStructLength=length(MATOBJ.dataStruct);
            app.LoaddataStructButton.UserData.fileIdx=fileIdx;
            app.LoaddataStructButton.UserData.FileObj=MATOBJ;


            if length(MATOBJ.dataStruct)>1
               app.GoNextfileIdxBtn.FontColor=[0 0 0];
               app.GoPreviousfileIdxBtn.FontColor=[0.8 0.8 0.8];
               app.GoToTraceNoEditField.Enable=1;
               app.GoToTraceNoEditField.Limits(2)=length(MATOBJ.dataStruct);
            else
               app.GoNextfileIdxBtn.FontColor=[0.8 0.8 0.8];
               app.GoPreviousfileIdxBtn.FontColor=[0.8 0.8 0.8];

            end
            LoadDataStructData(app,fileIdx)
         end


      end

      % Button pushed function: UpdateanalyseddataButton
      function GoUpdateDataStruct(app, event)
         if and(isfield(app.LoaddataStructButton.UserData, 'matfileName'),~isempty(app.LoaddataStructButton.UserData.FileObj))

            MATOBJ=app.LoaddataStructButton.UserData.FileObj;
            fileIdx=app.LoaddataStructButton.UserData.fileIdx;
            %check to see that we have the right fileIdx
            [~,Fname,~] = fileparts(app.MiniFigure.UserData.filePath);
            ds=MATOBJ.dataStruct(1,fileIdx);

            if contains(ds.filename,Fname)&&abs(diff([mean(ds.yData(1:1000)) mean(app.yData(1:1000))]))<0.5
               ds.FitInterval=[app.Interval1Start.Value app.Interval1End.Value; app.Interval2start.Value app.Interval2end.Value];
               ds.Idxs=[];
               ds.isMini=[];
               ds.BadFits=[];
               ds.FitVals=[];
               ds.decayFits=[];

               endIdx1=round(app.Interval1End.Value/app.fileSI);
               startIdx2=round(app.Interval2start.Value/app.fileSI);
               endIdx2=round(app.Interval2end.Value/app.fileSI);


               remove_outsideInt=or(and(any(app.Idxs>endIdx1,2),any(app.Idxs<startIdx2,2)),...
                  any(app.Idxs>endIdx2,2));
               ds.Idxs=app.Idxs(~remove_outsideInt,:);
               ds.isMini=app.FitDatas.isMini(~remove_outsideInt,:);
               ds.BadFits=app.FitDatas.BadFits(~remove_outsideInt,:);
               ds.FitVals=app.FitVals(~remove_outsideInt,:);
               ds.decayFits=app.FitDatas.decayFits(~remove_outsideInt,:);
               ds.Updated=date;

               if isfield(app.ax4.UserData,'Remarks')
                  ds.Remarks=app.ax4.UserData.Remarks;
               else
                  ds.Remarks=[];
               end

               if isfield(ds,'yDataFilt')&&~isempty(ds.yDataFilt)
                  ds.yDataFilt=app.yDataFilt;
               end

               ds.FitParameters=struct('TriggerVal', app.TriggerNextEventDropDown.Value,'DecaySearchWindow',...
                  app.FitdecayWindow.Value,'FitSmooth', app.FitSmoothData.Value,'SgolaySpan',app.SgolaySpan.Value,...
                  'Constraints',[app.MinAmp.Value app.MaxAmp.Value;app.MinDecayTau.Value app.MaxDecayTau.Value]);

               cd(app.LoaddataStructButton.UserData.matpathName);
               cd('IndividualFiles_new')
               save(sprintf('%s.mat',Fname),'ds','-v7.3');
               cd(app.LoaddataStructButton.UserData.matpathName);


               try
                  MATOBJ.dataStruct(1,fileIdx)=ds;
                  app.TextArea.Value={'dataStruct succesfully updated'};
               catch
                  app.TextArea.Value={'dataStruct not updated, separate file saved'};
               end

            else
               app.TextArea.Value={'Warning: data doesnt match, did not save'};
            end
         else
            app.TextArea.Value={'Could not find matching dataStruct line, did not save'};
         end

         app.UpdateanalyseddataButton.FontColor=[0 0 0];
      end

      % Callback function
      function SelectUseOnlyDerivative(app, event)
         switch app.OnlyderMethodBtn.Value
            case 1
               set(app.MoveSumOptionsPanel.Children,'Visible','off')
               app.MoveSumOptionsPanel.Visible='off';
               set(app.DerOnlyTypePanel.Children,'Visible','on')
               app.DerOnlyTypePanel.Visible='on';

               drawnow

            case 0
               set(app.DerOnlyTypePanel.Children,'Visible','off')
               app.DerOnlyTypePanel.Visible='off';
               app.MoveSumOptionsPanel.Visible='on';
               set(app.MoveSumOptionsPanel.Children,'Visible','on')
               drawnow
         end
      end

      % Value changed function: TextArea
      function ListenForAddNote(app, event)

         if ~isfield(app.ax4.UserData,'Remarks')
            app.ax4.UserData.Remarks=[];
         end


         IdxNoteCell=find(or(contains(app.TextArea.Value,'Note','IgnoreCase',true),contains(app.TextArea.Value,'Remark','IgnoreCase',true)));


         if ~isempty(IdxNoteCell)
            CellLine=app.TextArea.Value{IdxNoteCell};
            [~,endidx]=regexpi(CellLine,'(AddNote: |AddRemark:|AddNote |AddNote:|AddNote)');

            if ~isempty(app.TextArea.Value{IdxNoteCell+1})
               Remarks=[CellLine(endidx+1:end) '; '  app.TextArea.Value{IdxNoteCell+1}];
            else
               Remarks=CellLine(endidx+1:end);
            end


            if ~isempty(app.CurrTrace)
               app.ax4.UserData.Remarks=[Remarks ' @' sprintf('%s',app.CurrTrace)];
            elseif isfield(app.ax4.UserData,'CurrTrace')&&~isempty(app.ax4.UserData.CurrTrace)
               app.ax4.UserData.Remarks=[Remarks ' @' sprintf('%s',app.ax4.UserData.CurrTrace)];
            elseif isfield(app.ax1.UserData,'CurrTrace')&&~isempty(app.ax1.UserData.CurrTrace)
               app.ax4.UserData.Remarks=[Remarks ' @' sprintf('%s',app.ax1.UserData.CurrTrace)];
            else
               disp('couldnt find current trace name; no remarks added')
            end

         end %of isempty(IdxCell)


         IdxZoomCell=find(contains(app.TextArea.Value,'Zoom','IgnoreCase',true));
         if ~isempty(IdxZoomCell)
            app.ax4.XLim=[0 length(app.yData)*app.fileSI];
            app.TextArea.Value{IdxZoomCell}='Showing full trace';
         end


         IdxClearAll=find(contains(app.TextArea.Value,'ClearAll','IgnoreCase',true)); %#ok<EFIND>
         if ~isempty(IdxClearAll)
            app.TextArea.Value='';
         end


         if and(any(contains(app.TextArea.Value,'Marker','IgnoreCase',true)),...
               or(any(contains(app.TextArea.Value,'Decrease','IgnoreCase',true)),any(contains(app.TextArea.Value,'smaller','IgnoreCase',true))))
            hScat=findobj(app.ax4,'Type','Scatter');

            if ~isempty(hScat)
               for n=1:length(hScat)
                  hScat(n).SizeData=25;
                  hScat(n).LineWidth=0.7;
               end
            end
         elseif and(contains(app.TextArea.Value,'Marker','IgnoreCase',true),...
               or(contains(app.TextArea.Value,'Increase','IgnoreCase',true),contains(app.TextArea.Value,'Bigger','IgnoreCase',true)))
            hScat=findobj(app.ax4,'Type','Scatter');

            if ~isempty(hScat)
               for n=1:length(hScat)
                  hScat(n).SizeData=max(40, hScat(n).SizeData*1.2);
                  hScat(n).LineWidth=max(1.4,hScat(n).LineWidth*1.2);
               end
            end


         end


      end

      % Value changed function: ExportFullMinis
      function ChangeExportFullMinis(app, event)
         switch app.ExportFullMinis.Value
            case 0
               app.SaveMinisWithData.Value=0;
               app.SaveMinisWithData.Enable=false;
            case 1
               app.SaveMinisWithData.Enable=true;
         end
      end

      % Value changed function: SaveOptionsListBox
      function ChangeSaveOptions(app, event)
         if~any(contains(app.SaveOptionsListBox.Value,'Create dataHeaders'))
            app.HeadersSeparateCheckBox.Value=0;
            app.HeadersSeparateCheckBox.Enable=false;
         else
            app.HeadersSeparateCheckBox.Enable=true;
         end

      end

      % Button pushed function: GoHere_interval1
      function GoToInterVal1(app, event)
         yLims=app.ax4.YLim;
         app.ax4.XLim=[app.Interval1Start.Value app.Interval1Start.Value+app.Tab2_dXSlider.Value];
         %             app.ax4.YLim=[app.Tab2_YLim1.Value app.Tab2_YLim1.Value+app.Tab2_dYSlider.Value];
         app.Tab2_XLim1.Value=app.ax4.XLim(1);
         app.ax4.YLim=yLims;
      end

      % Button pushed function: GoHere_interval2
      function GoToInterVal2(app, event)
         yLims=app.ax4.YLim;
         app.ax4.XLim=[app.Interval2start.Value app.Interval2start.Value+app.Tab2_dXSlider.Value];
         app.Tab2_XLim1.Value=app.ax4.XLim(1);
         app.ax4.YLim=yLims;
      end

      % Button pushed function: BtnYStepUpAx4
      function MoveYupAx4(app, event)
         xLims=app.ax4.XLim;
         app.ax4.YLim=app.ax4.YLim+(app.Tab2_dYSlider.Value/4);
         app.Tab2_YLim1.Value=app.ax4.YLim(1);
         app.ax4.XLim=xLims;

         hScat=findobj(app.ax4,'Type','Scatter');

         if ~isempty(hScat)
            for n=1:length(hScat)
               hScat(n).SizeData=25;
               hScat(n).LineWidth=0.7;
            end
         end

      end

      % Button pushed function: BtnYStepDownAx4
      function MoveYDownAx4(app, event)
         xLims=app.ax4.XLim;
         app.ax4.YLim=app.ax4.YLim-(app.Tab2_dYSlider.Value/4);
         app.Tab2_YLim1.Value=app.ax4.YLim(1);
         app.ax4.XLim=xLims;
      end

      % Button pushed function: RefitDataButton
      function GoRefitData(app, event)

         app.Lamp.Color=[1 0 0];
         drawnow
         if isempty(app.yDataFilt)
            GoFilteryData_Tab2(app)
         end


         app.dataDerFilt=[];
         app.dataDerFilt = sgolayfilt(diff(app.yDataFilt),3, round(0.0013/app.fileSI)+1);
         app.dataDerFilt=[app.dataDerFilt(1); app.dataDerFilt];
         %Make down-only filter

         funcDownOnly = zeros(size(app.yDataFilt));
         for icnt = 2:length(app.dataDerFilt)
            if app.dataDerFilt(icnt) <= 0
               funcDownOnly(icnt) = funcDownOnly(icnt-1) + app.dataDerFilt(icnt-1);
            end
         end


         RoughBaseline=medfilt1(app.yDataFilt, 1000);


         %confirm peak indexes
         for icnt=1:length(app.Idxs)
            PeakRange1=app.Idxs(icnt,2)-6:app.Idxs(icnt,2)+25;
            [~,idxPeakRange1]=min(funcDownOnly(PeakRange1));
            PeakRange2=PeakRange1(idxPeakRange1)-2:PeakRange1(idxPeakRange1)+2;
            [~,idxPeakRange2]=min(app.yDataFilt(PeakRange2));
            app.Idxs(icnt,2)=PeakRange2(idxPeakRange2);

         end


         if any(or(diff(app.Idxs,[],2)<3,diff(app.Idxs,[],2)>200))
            WrongStarts=find(or(diff(app.Idxs,[],2)<3,diff(app.Idxs,[],2)>200));
            whereNull = find(funcDownOnly == 0);
            for icnt=1:length(WrongStarts)
               app.Idxs(WrongStarts(icnt),1)=whereNull(find(whereNull < app.Idxs(WrongStarts(icnt),2)-3, 1, 'last'));
            end
         end


         %Select either 4pA below roughbaseline or manually selected
         %
         % app.FitDatas.IsManSelec=app.FitVals(or(app.FitVals(:,12),app.yDataFilt(app.Idxs(:,2))-RoughBaseline(app.Idxs(:,2))<=-4),12); %Store manual selection column here for now
         % app.Idxs=app.Idxs(or(app.FitVals(:,12),app.yDataFilt(app.Idxs(:,2))-RoughBaseline(app.Idxs(:,2))<=-4),:);


         app.FitDatas.IsManSelec=app.FitVals(:,12); %Store manual selection column here for now
         app.FitVals = nan(size(app.Idxs,1),12); %Wipe FitVals
         app.FitDatas.isMini=true(size(app.Idxs,1),2);
         app.FitDatas.decayFits=cell(size(app.Idxs,1),1);
         app.FitDatas.rsq=zeros(size(app.Idxs,1),1);

         Fitminis(app)
         FitPostProces(app)


         delete(findall(app.ax4,'Tag','ScatFittedMinis'));
         scatter(app.ax4, app.Idxs(app.FitDatas.isMini(:,1),2).*app.fileSI,app.yData(app.Idxs(app.FitDatas.isMini(:,1),2)), app.MarkerSizeSpinner.Value, ...
            [0.0 0.95 0.0],"o",'LineWidth',app.MarkerLineWidthSpinner.Value,'Tag','ScatFittedMinis');


      end

      % Button pushed function: RefitAllDataBtn
      function GoRefitAllData(app, event)
         app.Lamp.Color=[1 0 0];
         drawnow

         CurIdx=app.LoaddataStructButton.UserData.fileIdx;

         for nFileIdx=app.LoaddataStructButton.UserData.fileIdx:app.LoaddataStructButton.UserData.dataStructLength
            app.LoaddataStructButton.UserData.fileIdx=nFileIdx;
            LoadDataStructData(app,app.LoaddataStructButton.UserData.fileIdx);
            GoRefitData(app, event)
            GoUpdateDataStruct(app, event) %save

         end

         app.TextArea.Value=sprintf('Finished re-fitting of %d traces',app.LoaddataStructButton.UserData.dataStructLength-CurIdx);
         app.Lamp.Color=[0 1 0];
         drawnow


      end

      % Button pushed function: GoLastfileIdxBtn
      function GoToLastFileIdx(app, event)
         if or(~isfield(app.LoaddataStructButton.UserData,'fileIdx'),isempty(app.LoaddataStructButton.UserData.fileIdx))
            app.TextArea.Value={'Next trace is only availible when analysed data is loaded'};
         else
            app.LoaddataStructButton.UserData.fileIdx=app.LoaddataStructButton.UserData.dataStructLength;
            LoadDataStructData(app,app.LoaddataStructButton.UserData.fileIdx);

         end
      end

      % Button pushed function: GoFirstfileIdxBtn
      function GoToFirstFileIdx(app, event)
         if or(~isfield(app.LoaddataStructButton.UserData,'fileIdx'),isempty(app.LoaddataStructButton.UserData.fileIdx))
            app.TextArea.Value={'Next trace is only availible when analysed data is loaded'};
         else
            app.LoaddataStructButton.UserData.fileIdx=1;
            LoadDataStructData(app,app.LoaddataStructButton.UserData.fileIdx);

         end
      end

      % Callback function: TracesLineWidthSpinner, TracesLineWidthSpinner
      function SetAx4Properties(app, event)

         hScat=findobj(app.ax4,'Type','Scatter');

         if ~isempty(hScat)
            for n=1:length(hScat)
               hScat(n).SizeData=app.MarkerSizeSpinner.Value;
               hScat(n).LineWidth=app.MarkerLineWidthSpinner.Value;
            end
         end


         hLine=findobj(app.ax4,'Type','Line');

         if ~isempty(hLine)
            for n=1:length(hLine)
               hLine(n).LineWidth=app.MarkerLineWidthSpinner.Value;
            end
         end


      end

      % Button pushed function: DebugBtn, DebugBtnTab2
      function GoDebug(app, event)
         app.ax1.YLim=app.ax1.YLim+0.35*app.dYSlider.Value;

         app.ax1.YLim=app.ax1.YLim+0.35*app.dYSlider.Value;

      end

      % Value changed function: BadFitSwitch
      function ExcludeBadFits(app, event)
         value = app.BadFitSwitch.Value;

         switch app.BadFitSwitch.Value
            case Include
               disp('this function is out of order')


            case Exclude
               disp('this function is out of order')

         end


      end

      % Button pushed function: ShoweventsinnormallyfilteredtraceButton
      function PlotNormallyFilteredTraceAx2(app, event)
         delete(app.ax2.Children)
         hold(app.ax2,'on')

         xData=(1:length(app.yData)).*app.fileSI;

         yDataNormalFilt = sgolayfilt(app.yData, 3, ceil(((17*100e-6)/app.fileSI)/2)*2+1);

         plot(app.ax2,xData,yDataNormalFilt,'Color',[0,0.33,0.833],'LineWidth',0.6,'Tag','PlotAx2yDataFilt');

         hold(app.ax2,'on')
         scatter(app.ax2, app.wherePeaks.*app.fileSI,yDataNormalFilt(app.wherePeaks),20,[1 0 1],"o",'LineWidth',0.9,'Tag','ScatAx2wherePeaks');
         %             scatter(app.ax2, app.whereStarts.*app.fileSI,yDataNormalFilt(app.whereStarts),[],[0 1 0],"+",'Tag','ScatAx2whereStarts');
         linkaxes([app.ax1 app.ax2 app.ax3],'x');

         app.ax2.YLim=app.ax1.YLim;


      end

      % Value changed function: ShowFittedStartsCheckBox
      function GoShowFittedStarts(app, event)
         value = app.ShowFittedStartsCheckBox.Value;

         h_ScatMiniStarts=findobj(app.ax4,'Tag','ScatMiniStarts'); %see if we already have plotobject
         h_ScatAddedMiniStarts=findobj(app.ax4,'Tag','ScatAddedMiniStarts'); %plotobj for added minis


         switch app.ShowFittedStartsCheckBox.Value
            case 1
               % first, delete any old fits
               if ~isempty(h_ScatMiniStarts)
                  delete(findall(app.ax4,'Tag','ScatMiniStarts'));
                  delete(findall(app.ax4,'Tag','ScatAddedMiniStarts'));
               end


               %Plot starts
               hold(app.ax4,'on')


               scatter(app.ax4, app.Idxs(app.FitDatas.isMini(:,1),1).*app.fileSI,app.yData(app.Idxs(app.FitDatas.isMini(:,1),1)), app.MarkerSizeSpinner.Value*2.2, ...
                  [0.6824 0.2706 1],"+",'LineWidth',app.MarkerLineWidthSpinner.Value*1.6,'Tag','ScatMiniStarts');


            case 0
               delete(findall(app.ax4,'Tag','ScatMiniStarts'));
               delete(findall(app.ax4,'Tag','ScatAddedMiniStarts'));

         end

      end

      % Callback function
      function SetFitsLineWidth(app, event)
         value = app.FitsLineWidthSpinner.Value;
         h_PlottedMiniFits=findobj(app.ax4,'Tag','PlottedMiniFits'); %see if we already have plotobject
         h_PlottedAddedMiniFits=findobj(app.ax4,'Tag','PlottedAddedMiniFits'); %plotobj for added minis
         set(h_PlottedMiniFits,'LineWidth',app.FitsLineWidthSpinner.Value);
         set(h_PlottedAddedMiniFits,'LineWidth',app.FitsLineWidthSpinner.Value);


      end

      % Button pushed function: CountcurrenteventsButton
      function GoCountMinisTillCurrent(app, event)

         CurrentPointTime=app.ax4.XLim(2);
         CurrentPointIdx=round(app.ax4.XLim(2)/app.fileSI);
         startIdx1=round(app.Interval1Start.Value/app.fileSI);
         startIdx2=round(app.Interval2start.Value/app.fileSI);
         endIdx1=round(app.Interval1End.Value/app.fileSI);
         endIdx2=round(app.Interval2end.Value/app.fileSI);

         %Determine if we're in interval 1 or 2
         if and(CurrentPointIdx<endIdx1,CurrentPointIdx>startIdx1)
            %we're in interval 1
            endIdx1=CurrentPointIdx;
         elseif and(CurrentPointIdx<endIdx2,CurrentPointIdx>startIdx2)
            %were in interval 2
            endIdx2=CurrentPointIdx;
         end

         %Gather event numbers from idxs and clicked points

         ClickedPoints=app.ax4.UserData.ClickedPoints;

         if ~isempty(ClickedPoints)
            AddPoints=ClickedPoints(logical(ClickedPoints(:,3)),1:2);
            DelPoints=ClickedPoints(~logical(ClickedPoints(:,3)),1:2);

            nAddPoints_int1=sum(AddPoints(:,1)<endIdx1*app.fileSI);
            nDelPoints_int1=sum(DelPoints(:,1)<endIdx1*app.fileSI);

            nAddPoints_int2=sum(and(AddPoints(:,1)>startIdx2*app.fileSI,AddPoints(:,1)<endIdx2*app.fileSI));
            nDelPoints_int2=sum(and(DelPoints(:,1)>startIdx2*app.fileSI,DelPoints(:,1)<endIdx2*app.fileSI));

            nIdxs_int1=sum(any(app.Idxs<endIdx1,2));
            nIdxs_int2=sum(and(any(app.Idxs>startIdx2,2),any(app.Idxs<endIdx2,2)));

            nEvents_int1=nIdxs_int1+nAddPoints_int1-nDelPoints_int1;
            nEvents_int2=nIdxs_int2+nAddPoints_int2-nDelPoints_int2;
           
         else
            nEvents_int1=sum(any(app.Idxs<endIdx1,2));
            nEvents_int2=sum(and(any(app.Idxs>startIdx2,2),any(app.Idxs<endIdx2,2)));
         end

          nEvents_total=nEvents_int1+nEvents_int2;

         app.Interval1EditField.Value=nEvents_int1;
         if nEvents_int1>125
            app.Interval1EditField.BackgroundColor=[0.39 0.83 0.07];
         else
            app.Interval1EditField.BackgroundColor=[1 1 1];
         end

         app.Interval2EditField.Value=nEvents_int2;
         if nEvents_int2>125
            app.Interval2EditField.BackgroundColor=[0.39 0.83 0.07];
         else
            app.Interval2EditField.BackgroundColor=[1 1 1];
         end
         
         app.TotalEditField.Value=nEvents_total;
         
         if nEvents_total>250
            app.Interval2EditField.BackgroundColor=[0.39 0.83 0.07];
         elseif (app.Interval1End.Value-app.Interval1Start.Value)+(app.Interval2end.Value-app.Interval2start.Value)>30
            app.Interval2EditField.BackgroundColor=[1 1 1];
         else
            app.Interval2EditField.BackgroundColor=[1 0 0];
         end


      end

      % Value changed function: GoToTraceNoEditField
      function GoToTraceNo(app, event)


         app.LoaddataStructButton.UserData.fileIdx=app.GoToTraceNoEditField.Value;

         if app.LoaddataStructButton.UserData.fileIdx<=app.LoaddataStructButton.UserData.dataStructLength
            LoadDataStructData(app,app.LoaddataStructButton.UserData.fileIdx);
         else
            app.TextArea.Value={'Index Exceeds dataStruct'};
         end


      end

      % Button pushed function: CountallButton
      function GoCountMinisTotal(app, event)


         startIdx1=round(app.Interval1Start.Value/app.fileSI);
         startIdx2=round(app.Interval2start.Value/app.fileSI);
         endIdx1=round(app.Interval1End.Value/app.fileSI);
         endIdx2=round(app.Interval2end.Value/app.fileSI);

         CurrentPointTime=app.Interval2end.Value;
         CurrentPointIdx=endIdx2;


         %Determine if we're in interval 1 or 2
         if and(CurrentPointIdx<endIdx1,CurrentPointIdx>startIdx1)
            %we're in interval 1
            endIdx1=CurrentPointIdx;
         elseif and(CurrentPointIdx<endIdx2,CurrentPointIdx>startIdx2)
            %were in interval 2
            endIdx2=CurrentPointIdx;
         end

         %Gather event numbers from idxs and clicked points

         ClickedPoints=app.ax4.UserData.ClickedPoints;

         if ~isempty(ClickedPoints)
            AddPoints=ClickedPoints(logical(ClickedPoints(:,3)),1:2);
            DelPoints=ClickedPoints(~logical(ClickedPoints(:,3)),1:2);

            nAddPoints_int1=sum(AddPoints(:,1)<endIdx1*app.fileSI);
            nDelPoints_int1=sum(DelPoints(:,1)<endIdx1*app.fileSI);

            nAddPoints_int2=sum(and(AddPoints(:,1)>startIdx2*app.fileSI,AddPoints(:,1)<endIdx2*app.fileSI));
            nDelPoints_int2=sum(and(DelPoints(:,1)>startIdx2*app.fileSI,DelPoints(:,1)<endIdx2*app.fileSI));

            nIdxs_int1=sum(any(app.Idxs<endIdx1,2));
            nIdxs_int2=sum(and(any(app.Idxs>startIdx2,2),any(app.Idxs<endIdx2,2)));

            nEvents_int1=nIdxs_int1+nAddPoints_int1-nDelPoints_int1;
            nEvents_int2=nIdxs_int2+nAddPoints_int2-nDelPoints_int2;
           
         else
            nEvents_int1=sum(any(app.Idxs<endIdx1,2));
            nEvents_int2=sum(and(any(app.Idxs>startIdx2,2),any(app.Idxs<endIdx2,2)));
         end

          nEvents_total=nEvents_int1+nEvents_int2;

         app.Interval1EditField.Value=nEvents_int1;
         if nEvents_int1>125
            app.Interval1EditField.BackgroundColor=[0.39 0.83 0.07];
         else
            app.Interval1EditField.BackgroundColor=[1 1 1];
         end

         app.Interval2EditField.Value=nEvents_int2;
         if nEvents_int2>125
            app.Interval2EditField.BackgroundColor=[0.39 0.83 0.07];
         else
            app.Interval2EditField.BackgroundColor=[1 1 1];
         end
         
         app.TotalEditField.Value=nEvents_total;
         
         if or(nEvents_total>250,(app.Interval1End.Value-app.Interval1Start.Value)+(app.Interval2end.Value-app.Interval2start.Value)>30)
            app.TotalEditField.BackgroundColor=[0.39 0.83 0.07];
        
         else
            app.TotalEditField.BackgroundColor=[1 0 0];
         end


      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.MiniFigure = uifigure('Visible', 'off');
         app.MiniFigure.Name = 'MATLAB App';
         app.MiniFigure.Tag = 'MiniFigureTag';
         app.MiniGrid1 = uigridlayout(app.MiniFigure);
         app.MiniGridTopPart = uigridlayout(app.MiniGrid1);
         app.LoadMatFileButton = uibutton(app.MiniGridTopPart, 'push');
         app.LoadMatFileButton.ButtonPushedFcn = createCallbackFcn(app, @GoLoadMatfiles, true);
         app.LoadMatFileButton.Icon = 'MatlabIcon.png';
         app.LoadMatFileButton.Text = 'Load matfile';
         app.LoadAbfButton = uibutton(app.MiniGridTopPart, 'push');
         app.LoadAbfButton.ButtonPushedFcn = createCallbackFcn(app, @GetABFfiles, true);
         app.LoadAbfButton.Icon = 'MolecularDevices.png';
         app.LoadAbfButton.Text = 'Load abf file';
         app.FilesDrop = uidropdown(app.MiniGridTopPart);
         app.FilesDrop.Items = {'No data yet'};
         app.FilesDrop.ValueChangedFcn = createCallbackFcn(app, @HaveChangedFileDrop, true);
         app.FilesDrop.Value = 'No data yet';
         app.LoadFileListBtn = uibutton(app.MiniGridTopPart, 'push');
         app.LoadFileListBtn.ButtonPushedFcn = createCallbackFcn(app, @LoadFileList, true);
         app.LoadFileListBtn.Icon = 'MolecularDevices.png';
         app.LoadFileListBtn.Text = 'Load Multiple Files';
         app.Lamp = uilamp(app.MiniGridTopPart);
         app.MarkBadTraceBtn = uibutton(app.MiniGridTopPart, 'push');
         app.MarkBadTraceBtn.ButtonPushedFcn = createCallbackFcn(app, @MoveBadTraceABFfile, true);
         app.MarkBadTraceBtn.Icon = 'icons_MarkData_Icon.png';
         app.MarkBadTraceBtn.Tooltip = {'Mark Bad Trace (move to "Bad Traces" dir)'};
         app.MarkBadTraceBtn.Text = '';
         app.DeleteAbfBtn = uibutton(app.MiniGridTopPart, 'push');
         app.DeleteAbfBtn.ButtonPushedFcn = createCallbackFcn(app, @MoveDeleteABFfile, true);
         app.DeleteAbfBtn.Icon = 'icons_GarbageBin.png';
         app.DeleteAbfBtn.Tooltip = {'Delete abf file (Move to "deleted Files")'};
         app.DeleteAbfBtn.Text = '';
         app.ClearDataBtn = uibutton(app.MiniGridTopPart, 'push');
         app.ClearDataBtn.ButtonPushedFcn = createCallbackFcn(app, @ClearAllData, true);
         app.ClearDataBtn.Icon = 'icons_ClearData_Brush.png';
         app.ClearDataBtn.Tooltip = {'Clear Trace Data'};
         app.ClearDataBtn.Text = '';
         app.LoadSampleDataButton = uibutton(app.MiniGridTopPart, 'push');
         app.LoadSampleDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSampleTrace, true);
         app.LoadSampleDataButton.Icon = 'icons_-LoadSampleTrace.png';
         app.LoadSampleDataButton.Visible = 'off';
         app.LoadSampleDataButton.Tooltip = {'Load Sample Trace'};
         app.LoadSampleDataButton.Text = '';
         app.LoadSampleDataTab2Btn = uibutton(app.MiniGridTopPart, 'push');
         app.LoadSampleDataTab2Btn.ButtonPushedFcn = createCallbackFcn(app, @LoadSampleDataTab2, true);
         app.LoadSampleDataTab2Btn.Icon = 'icons_LoadSampleTab2.png';
         app.LoadSampleDataTab2Btn.Visible = 'off';
         app.LoadSampleDataTab2Btn.Tooltip = {'Load Sample Trace'};
         app.LoadSampleDataTab2Btn.Text = '';
         app.NextFileDropBtn = uibutton(app.MiniGridTopPart, 'push');
         app.NextFileDropBtn.ButtonPushedFcn = createCallbackFcn(app, @GoToNextDropFile, true);
         app.NextFileDropBtn.Text = '▶︎';
         app.PreviousFileDropBtn = uibutton(app.MiniGridTopPart, 'push');
         app.PreviousFileDropBtn.ButtonPushedFcn = createCallbackFcn(app, @GoToPrevDropFile, true);
         app.PreviousFileDropBtn.Text = '◀︎';
         app.ZoomresetBtn = uibutton(app.MiniGridTopPart, 'push');
         app.ZoomresetBtn.ButtonPushedFcn = createCallbackFcn(app, @TurnOnZoomReset, true);
         app.ZoomresetBtn.Visible = 'off';
         app.ZoomresetBtn.Text = 'Zoom reset';
         app.ZoomInBtn = uibutton(app.MiniGridTopPart, 'state');
         app.ZoomInBtn.ValueChangedFcn = createCallbackFcn(app, @OnOffZoomIn, true);
         app.ZoomInBtn.Visible = 'off';
         app.ZoomInBtn.Text = {'Zoom In'; ''};
         app.PanBtn = uibutton(app.MiniGridTopPart, 'state');
         app.PanBtn.ValueChangedFcn = createCallbackFcn(app, @TurnOnPan, true);
         app.PanBtn.Visible = 'off';
         app.PanBtn.Text = 'Pan';
         app.ClearTraceButton = uibutton(app.MiniGridTopPart, 'push');
         app.ClearTraceButton.ButtonPushedFcn = createCallbackFcn(app, @ClearCurrentListTrace, true);
         app.ClearTraceButton.Tooltip = {'Clear current Trace (only in list mode)'};
         app.ClearTraceButton.Text = 'Clear Trace';
         app.ClearTraceDeleteButton = uibutton(app.MiniGridTopPart, 'push');
         app.ClearTraceDeleteButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAndDeleteCurrentListTrace, true);
         app.ClearTraceDeleteButton.Tooltip = {'Clear current Trace (only in list mode)'};
         app.ClearTraceDeleteButton.Text = 'Clear Trace & Delete';
         app.ClearTraceMarkBadButton = uibutton(app.MiniGridTopPart, 'push');
         app.ClearTraceMarkBadButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAndMarkBadCurrentListTrace, true);
         app.ClearTraceMarkBadButton.Tooltip = {'Clear current Trace (only in list mode)'};
         app.ClearTraceMarkBadButton.Text = 'Clear Trace & Mark Bad';
         app.TabGroup = uitabgroup(app.MiniGrid1);
         app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
         app.Tab1 = uitab(app.TabGroup);
         app.Tab1.Title = 'Filter Data';
         app.Tab1Grid1 = uigridlayout(app.Tab1);
         app.Tab1GridRight1 = uigridlayout(app.Tab1Grid1);
         app.Panel2Data = uipanel(app.Tab1GridRight1);
         app.Panel2Data.Title = 'Second data Filter';
         app.FilterDropDown_2Label = uilabel(app.Panel2Data);
         app.FilterDropDown_2Label.Text = 'Filter';
         app.SecondaryDataFilterType = uidropdown(app.Panel2Data);
         app.SecondaryDataFilterType.Items = {'None', 'Moving Average', 'Median Filter', 'Sgolay', 'Low Pass', 'Lowess', 'Loess'};
         app.SecondaryDataFilterType.Tooltip = {'Not recommended: take data through second filter'};
         app.SecondaryDataFilterType.Value = 'Moving Average';
         app.LengthLabel_2 = uilabel(app.Panel2Data);
         app.LengthLabel_2.Text = 'Length';
         app.SecondaryDataFilterLength = uispinner(app.Panel2Data);
         app.SecondaryDataFilterLength.Step = 2;
         app.SecondaryDataFilterLength.Limits = [1 101];
         app.SecondaryDataFilterLength.RoundFractionalValues = 'on';
         app.SecondaryDataFilterLength.Value = 19;
         app.LowPassfqLabel_4 = uilabel(app.Panel2Data);
         app.LowPassfqLabel_4.Text = 'LowPass fq';
         app.SecondaryDataLowPassHz = uieditfield(app.Panel2Data, 'numeric');
         app.SecondaryDataLowPassHz.Limits = [1 20000000];
         app.SecondaryDataLowPassHz.RoundFractionalValues = 'on';
         app.SecondaryDataLowPassHz.Value = 1000;
         app.OrderLabel_2 = uilabel(app.Panel2Data);
         app.OrderLabel_2.Text = 'Order';
         app.SecondaryDataFilterOrder = uispinner(app.Panel2Data);
         app.SecondaryDataFilterOrder.Limits = [1 99];
         app.SecondaryDataFilterOrder.RoundFractionalValues = 'on';
         app.SecondaryDataFilterOrder.Tooltip = {'Filter order (sgolay only)'};
         app.SecondaryDataFilterOrder.Value = 2;
         app.Panel1Data = uipanel(app.Tab1GridRight1);
         app.Panel1Data.Title = 'Data Filter';
         app.LowPassfqLabel_5 = uilabel(app.Panel1Data);
         app.LowPassfqLabel_5.Text = 'LowPass fq';
         app.DataLowPassHz = uieditfield(app.Panel1Data, 'numeric');
         app.DataLowPassHz.Limits = [1 20000000];
         app.DataLowPassHz.RoundFractionalValues = 'on';
         app.DataLowPassHz.Tooltip = {'Lowpass frequency (Hz), applies to lowpass filter only. '};
         app.DataLowPassHz.Value = 1000;
         app.OrderLabel_5 = uilabel(app.Panel1Data);
         app.OrderLabel_5.Text = 'Order';
         app.DataFilterOrder = uispinner(app.Panel1Data);
         app.DataFilterOrder.Limits = [1 99];
         app.DataFilterOrder.RoundFractionalValues = 'on';
         app.DataFilterOrder.Tooltip = {'Filter order (sgolay only); use 2 or 3'};
         app.DataFilterOrder.Value = 2;
         app.ShowPeaksCheckBox = uicheckbox(app.Panel1Data);
         app.ShowPeaksCheckBox.ValueChangedFcn = createCallbackFcn(app, @Ax1ShowPeaks, true);
         app.ShowPeaksCheckBox.Text = 'Show Peaks';
         app.ShowPeaksCheckBox.Value = true;
         app.ShowStartsCheckBox = uicheckbox(app.Panel1Data);
         app.ShowStartsCheckBox.ValueChangedFcn = createCallbackFcn(app, @Ax1ShowStarts, true);
         app.ShowStartsCheckBox.Text = 'Show Starts';
         app.ShowStartsCheckBox.Value = true;
         app.ShowPeaks2CheckBox = uicheckbox(app.Panel1Data);
         app.ShowPeaks2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ax1ShowPeaks2, true);
         app.ShowPeaks2CheckBox.Visible = 'off';
         app.ShowPeaks2CheckBox.Text = 'Show Peaks 2';
         app.ShowStarts2CheckBox = uicheckbox(app.Panel1Data);
         app.ShowStarts2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ax1ShowStarts2, true);
         app.ShowStarts2CheckBox.Visible = 'off';
         app.ShowStarts2CheckBox.Text = 'Show Starts 2';
         app.FilterDropDownLabel_3 = uilabel(app.Panel1Data);
         app.FilterDropDownLabel_3.Text = 'Filter';
         app.DataFilterType = uidropdown(app.Panel1Data);
         app.DataFilterType.Items = {'None', 'Moving Average', 'Median Filter', 'Sgolay', 'Low Pass', 'Lowess', 'Loess'};
         app.DataFilterType.Tooltip = {'Select which filter to use. Lowess, loess or moving average work well here'};
         app.DataFilterType.Value = 'Lowess';
         app.LengthLabel_5 = uilabel(app.Panel1Data);
         app.LengthLabel_5.Text = 'Length';
         app.DataFilterLength = uispinner(app.Panel1Data);
         app.DataFilterLength.Step = 2;
         app.DataFilterLength.Limits = [1 101];
         app.DataFilterLength.RoundFractionalValues = 'on';
         app.DataFilterLength.Tooltip = {'Filter length: suggested value range=7-23'};
         app.DataFilterLength.Value = 17;
         app.Panel3Data = uipanel(app.Tab1GridRight1);
         app.FilterTraceButton = uibutton(app.Panel3Data, 'push');
         app.FilterTraceButton.ButtonPushedFcn = createCallbackFcn(app, @FilterTraceButtonPushed, true);
         app.FilterTraceButton.Text = 'Filter Trace';
         app.ShowUnfiltereddataCheckBox = uicheckbox(app.Panel3Data);
         app.ShowUnfiltereddataCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowHideUnfilteredData, true);
         app.ShowUnfiltereddataCheckBox.Text = 'Show Unfiltered data';
         app.ShowUnfiltereddataCheckBox.Value = true;
         app.OnlyderMethodBtn = uicheckbox(app.Panel3Data);
         app.OnlyderMethodBtn.Text = 'OnlyDerMethodBtn';
         app.Tab1GridRight2 = uigridlayout(app.Tab1Grid1);
         app.Panel1Der = uipanel(app.Tab1GridRight2);
         app.Panel1Der.Title = 'Derivative Filter';
         app.FilterDropDownLabel_2 = uilabel(app.Panel1Der);
         app.FilterDropDownLabel_2.Text = 'Filter';
         app.DerFilterType = uidropdown(app.Panel1Der);
         app.DerFilterType.Items = {'None', 'Moving Average', 'Median Filter', 'Sgolay', 'Low Pass', 'Lowess', 'Loess'};
         app.DerFilterType.Tooltip = {'Which filter to use for derivative'};
         app.DerFilterType.Value = 'Sgolay';
         app.LengthLabel_3 = uilabel(app.Panel1Der);
         app.LengthLabel_3.Text = 'Length';
         app.DerFilterLength = uispinner(app.Panel1Der);
         app.DerFilterLength.Step = 2;
         app.DerFilterLength.Limits = [1 101];
         app.DerFilterLength.RoundFractionalValues = 'on';
         app.DerFilterLength.Tooltip = {'Filter length: suggested value range=7-23'};
         app.DerFilterLength.Value = 17;
         app.LowPassfqLabel_2 = uilabel(app.Panel1Der);
         app.LowPassfqLabel_2.Text = 'LowPass fq';
         app.DerLowPassHz = uieditfield(app.Panel1Der, 'numeric');
         app.DerLowPassHz.Limits = [1 20000000];
         app.DerLowPassHz.RoundFractionalValues = 'on';
         app.DerLowPassHz.Tooltip = {'Lowpass frequency (Hz), applies to lowpass filter only. '};
         app.DerLowPassHz.Value = 500;
         app.OrderLabel_3 = uilabel(app.Panel1Der);
         app.OrderLabel_3.Text = 'Order';
         app.DerFilterOrder = uispinner(app.Panel1Der);
         app.DerFilterOrder.Limits = [1 99];
         app.DerFilterOrder.RoundFractionalValues = 'on';
         app.DerFilterOrder.Tooltip = {'Filter order (sgolay only); use 2 or 3'};
         app.DerFilterOrder.Value = 3;
         app.Panel2Der = uipanel(app.Tab1GridRight2);
         app.Panel2Der.Title = 'Second Derivative Filter';
         app.FilterDropDown_2Label_2 = uilabel(app.Panel2Der);
         app.FilterDropDown_2Label_2.Text = 'Filter';
         app.SecondaryDerFilterType = uidropdown(app.Panel2Der);
         app.SecondaryDerFilterType.Items = {'None', 'Moving Average', 'Median Filter', 'Sgolay', 'Low Pass', 'Lowess', 'Loess'};
         app.SecondaryDerFilterType.Tooltip = {'Not recommended: take derivative through second filter'};
         app.SecondaryDerFilterType.Value = 'None';
         app.SecondaryDerFilterLength = uispinner(app.Panel2Der);
         app.SecondaryDerFilterLength.Step = 2;
         app.SecondaryDerFilterLength.Limits = [1 101];
         app.SecondaryDerFilterLength.RoundFractionalValues = 'on';
         app.SecondaryDerFilterLength.Value = 17;
         app.LengthLabel_4 = uilabel(app.Panel2Der);
         app.LengthLabel_4.Text = 'Length';
         app.LowPassfqLabel = uilabel(app.Panel2Der);
         app.LowPassfqLabel.Text = 'LowPass fq';
         app.SecondaryDerLowPassHz = uieditfield(app.Panel2Der, 'numeric');
         app.SecondaryDerLowPassHz.Limits = [1 20000000];
         app.SecondaryDerLowPassHz.RoundFractionalValues = 'on';
         app.SecondaryDerLowPassHz.Value = 1000;
         app.OrderLabel_4 = uilabel(app.Panel2Der);
         app.OrderLabel_4.Text = 'Order';
         app.SecondaryDerFilterOrder = uispinner(app.Panel2Der);
         app.SecondaryDerFilterOrder.Limits = [1 99];
         app.SecondaryDerFilterOrder.RoundFractionalValues = 'on';
         app.SecondaryDerFilterOrder.Tooltip = {'Filter order (sgolay only)'};
         app.SecondaryDerFilterOrder.Value = 2;
         app.Gauge_semicirc2 = uigauge(app.Panel2Der, 'semicircular');
         app.FilterDerGoButton = uibutton(app.Panel2Der, 'push');
         app.FilterDerGoButton.ButtonPushedFcn = createCallbackFcn(app, @GoFilterDerivative, true);
         app.FilterDerGoButton.Tooltip = {'Start filtering of derivative and finding events'};
         app.FilterDerGoButton.Text = 'Filter Derivative';
         app.ReAnalyse = uibutton(app.Panel2Der, 'push');
         app.ReAnalyse.ButtonPushedFcn = createCallbackFcn(app, @ReanalysePeaks, true);
         app.ReAnalyse.Tooltip = {'Reanalyse events after changing tresholds'};
         app.ReAnalyse.Text = {'Renalyse'; ''};
         app.ShowProgressCheck = uicheckbox(app.Panel2Der);
         app.ShowProgressCheck.Text = 'Show progress';
         app.ShowProgressCheck.Value = true;
         app.SlidingDerivative = uicheckbox(app.Panel2Der);
         app.SlidingDerivative.Tooltip = {'Uses sliding window [n-1 n+1] to determine derivative. If unchecked: diff(xn)-> difference between [n n+1['};
         app.SlidingDerivative.Text = 'Use Sliding filter Derivative';
         app.SlidingDerivative.Value = true;
         app.Panel3Der = uipanel(app.Tab1GridRight2);
         app.ButtonGroup = uibuttongroup(app.Panel3Der);
         app.ButtonGroup.Tag = 'TresholdingBtnGroup';
         app.stDerivativeButton = uiradiobutton(app.ButtonGroup);
         app.stDerivativeButton.Text = '1st Derivative';
         app.MovSumButton = uiradiobutton(app.ButtonGroup);
         app.MovSumButton.Text = 'MovSum';
         app.rdDerivativeButton = uiradiobutton(app.ButtonGroup);
         app.rdDerivativeButton.Text = '3rd Derivative';
         app.rdDerivativeButton.Value = true;
         app.TresholdLabel = uilabel(app.Panel3Der);
         app.TresholdLabel.Tooltip = {'Load previously analysed data'; ''};
         app.TresholdLabel.Text = 'Treshold';
         app.derTresh = uispinner(app.Panel3Der);
         app.derTresh.Step = 0.5;
         app.derTresh.Tooltip = {'Load previously analysed data'; ''};
         app.derTresh.Value = 13;
         app.LocalSmoothRange = uispinner(app.Panel3Der);
         app.LocalSmoothRange.Limits = [3 Inf];
         app.LocalSmoothRange.RoundFractionalValues = 'on';
         app.LocalSmoothRange.ValueDisplayFormat = '%d';
         app.LocalSmoothRange.Tooltip = {'Load previously analysed data'; ''};
         app.LocalSmoothRange.Value = 20;
         app.SmoothLabel = uilabel(app.Panel3Der);
         app.SmoothLabel.Tooltip = {'Load previously analysed data'; ''};
         app.SmoothLabel.Text = 'Smooth';
         app.der3Tresh = uispinner(app.Panel3Der);
         app.der3Tresh.Step = 0.5;
         app.der3Tresh.Tooltip = {'Load previously analysed data'; ''};
         app.der3Tresh.Value = 15;
         app.TresholdLabel_3 = uilabel(app.Panel3Der);
         app.TresholdLabel_3.Tooltip = {'Load previously analysed data'; ''};
         app.TresholdLabel_3.Text = 'Treshold';
         app.PlotDersAx2 = uicheckbox(app.Panel3Der);
         app.PlotDersAx2.Text = 'Plot this instead of derivative';
         app.PlotDersAx2.Value = true;
         app.ShoweventsinnormallyfilteredtraceButton = uibutton(app.Panel3Der, 'push');
         app.ShoweventsinnormallyfilteredtraceButton.ButtonPushedFcn = createCallbackFcn(app, @PlotNormallyFilteredTraceAx2, true);
         app.ShoweventsinnormallyfilteredtraceButton.Text = {'Show events in normally filtered trace'; ''};
         app.Tab1GridRight3 = uigridlayout(app.Tab1Grid1);
         app.DerOnlyTypePanel = uipanel(app.Tab1GridRight3);
         app.DerOnlyTypePanel.Title = 'Derivative only method';
         app.DerOnlyTypePanel.Visible = 'off';
         app.PreZerosLabel = uilabel(app.DerOnlyTypePanel);
         app.PreZerosLabel.Visible = 'off';
         app.PreZerosLabel.Text = 'dp before onset';
         app.dpafteronsetLabel = uilabel(app.DerOnlyTypePanel);
         app.dpafteronsetLabel.Visible = 'off';
         app.dpafteronsetLabel.Tooltip = {'''WindowLength'''};
         app.dpafteronsetLabel.Text = 'dp after onset';
         app.OnsetTreshLabel = uilabel(app.DerOnlyTypePanel);
         app.OnsetTreshLabel.Visible = 'off';
         app.OnsetTreshLabel.Text = 'Onset treshold';
         app.MaxRiseTimemsLabel = uilabel(app.DerOnlyTypePanel);
         app.MaxRiseTimemsLabel.Visible = 'off';
         app.MaxRiseTimemsLabel.Text = 'Max Rise Time (ms)';
         app.derMaxRise = uieditfield(app.DerOnlyTypePanel, 'numeric');
         app.derMaxRise.RoundFractionalValues = 'on';
         app.derMaxRise.Visible = 'off';
         app.derMaxRise.Value = 2;
         app.DecayTresholdLabel = uilabel(app.DerOnlyTypePanel);
         app.DecayTresholdLabel.Visible = 'off';
         app.DecayTresholdLabel.Tooltip = {'%When is derivative above treshold (identify decays)'};
         app.DecayTresholdLabel.Text = 'Decay Treshold';
         app.derMaxDecay = uieditfield(app.DerOnlyTypePanel, 'numeric');
         app.derMaxDecay.RoundFractionalValues = 'on';
         app.derMaxDecay.Visible = 'off';
         app.MinIEIsamplesLabel = uilabel(app.DerOnlyTypePanel);
         app.MinIEIsamplesLabel.Visible = 'off';
         app.MinIEIsamplesLabel.Tooltip = {'%When is derivative above treshold (identify decays)'};
         app.MinIEIsamplesLabel.Text = 'Min IEI (samples)';
         app.derMinIEI = uieditfield(app.DerOnlyTypePanel, 'numeric');
         app.derMinIEI.Limits = [0 Inf];
         app.derMinIEI.RoundFractionalValues = 'on';
         app.derMinIEI.Visible = 'off';
         app.derMinIEI.Value = 5;
         app.FixLimsPanel = uipanel(app.Tab1GridRight3);
         app.BtnYStepUp = uibutton(app.FixLimsPanel, 'push');
         app.BtnYStepUp.ButtonPushedFcn = createCallbackFcn(app, @Tab1_MoveYLimUp, true);
         app.BtnYStepUp.Tooltip = {'Scroll through trace: move up'};
         app.BtnYStepUp.Text = '▲';
         app.BtnYStepDown = uibutton(app.FixLimsPanel, 'push');
         app.BtnYStepDown.ButtonPushedFcn = createCallbackFcn(app, @Tab1_MoveYLimDown, true);
         app.BtnYStepDown.Tooltip = {'Scroll through trace: move down'};
         app.BtnYStepDown.Text = '▼';
         app.BtnXStepForw = uibutton(app.FixLimsPanel, 'push');
         app.BtnXStepForw.ButtonPushedFcn = createCallbackFcn(app, @MoveXforwards, true);
         app.BtnXStepForw.Tooltip = {'Scroll through trace: move forwards'};
         app.BtnXStepForw.Text = '▶︎';
         app.BtnXStepBack = uibutton(app.FixLimsPanel, 'push');
         app.BtnXStepBack.ButtonPushedFcn = createCallbackFcn(app, @MoveXback, true);
         app.BtnXStepBack.Tooltip = {'Scroll through trace: move backwards'};
         app.BtnXStepBack.Text = '◀︎';
         app.dYSlider = uislider(app.FixLimsPanel);
         app.dYSlider.Limits = [0.0001 600];
         app.dYSlider.MajorTicks = [0.0001 100 200 300 400 500 600];
         app.dYSlider.MajorTickLabels = {'0', '100', '200', '300', '400', '500', '600'};
         app.dYSlider.ValueChangedFcn = createCallbackFcn(app, @SetYLimsTab1, true);
         app.dYSlider.ValueChangingFcn = createCallbackFcn(app, @SetYLimsTab1, true);
         app.dYSlider.MinorTicks = [];
         app.dYSlider.Tooltip = {'Set pA interval for current display'};
         app.dYSlider.Value = 300;
         app.dYSliderLabel = uilabel(app.FixLimsPanel);
         app.dYSliderLabel.Text = 'dY';
         app.YLim1Label = uilabel(app.FixLimsPanel);
         app.YLim1Label.Text = 'YLim(1)';
         app.YLim1val = uieditfield(app.FixLimsPanel, 'numeric');
         app.YLim1val.ValueDisplayFormat = '%.1f';
         app.YLim1val.ValueChangedFcn = createCallbackFcn(app, @SetYLimsTab1, true);
         app.YLim1val.Tooltip = {'Set bottom view of current display (doesnt affect analysed data)'};
         app.YLim1val.Value = -200;
         app.XLim1EditFieldLabel = uilabel(app.FixLimsPanel);
         app.XLim1EditFieldLabel.Text = 'XLim(1)';
         app.XLim1val = uieditfield(app.FixLimsPanel, 'numeric');
         app.XLim1val.ValueDisplayFormat = '%.1f';
         app.XLim1val.ValueChangedFcn = createCallbackFcn(app, @SetXstartAnddX, true);
         app.XLim1val.Tooltip = {'Set timepoint for start of current display (only affects display, not data)'};
         app.dXSliderLabel = uilabel(app.FixLimsPanel);
         app.dXSliderLabel.Text = 'dX';
         app.dXSlider = uislider(app.FixLimsPanel);
         app.dXSlider.Limits = [0.01 6];
         app.dXSlider.MajorTicks = [0.01 1 2 3 4 5 6];
         app.dXSlider.MajorTickLabels = {'0', '1', '2', '3', '4', '5', '6'};
         app.dXSlider.ValueChangedFcn = createCallbackFcn(app, @SetXstartAnddX, true);
         app.dXSlider.ValueChangingFcn = createCallbackFcn(app, @SetXstartAnddX, true);
         app.dXSlider.MinorTicks = [];
         app.dXSlider.Tooltip = {'Set time interval for current display'};
         app.dXSlider.Value = 2.5;
         app.DebugBtn = uibutton(app.FixLimsPanel, 'push');
         app.DebugBtn.ButtonPushedFcn = createCallbackFcn(app, @GoDebug, true);
         app.DebugBtn.Tooltip = {'Scroll through trace: move up'};
         app.DebugBtn.Text = 'Debug';
         app.MoveSumOptionsPanel = uipanel(app.Tab1GridRight3);
         app.MoveSumOptionsPanel.Title = 'Movsum options';
         app.SumTresholdLabel_Diff = uilabel(app.MoveSumOptionsPanel);
         app.SumTresholdLabel_Diff.Tooltip = {''};
         app.SumTresholdLabel_Diff.Text = 'Sum Treshold';
         app.DiffSumTresh = uispinner(app.MoveSumOptionsPanel);
         app.DiffSumTresh.LowerLimitInclusive = 'off';
         app.DiffSumTresh.UpperLimitInclusive = 'off';
         app.DiffSumTresh.Limits = [1 Inf];
         app.DiffSumTresh.RoundFractionalValues = 'on';
         app.DiffSumTresh.ValueDisplayFormat = '%d';
         app.DiffSumTresh.Tooltip = {''};
         app.DiffSumTresh.Value = 8;
         app.DerivativeLabel = uilabel(app.MoveSumOptionsPanel);
         app.DerivativeLabel.Tooltip = {''};
         app.DerivativeLabel.Text = 'Derivative';
         app.DecayLabel = uilabel(app.MoveSumOptionsPanel);
         app.DecayLabel.Tooltip = {''};
         app.DecayLabel.Text = 'Decay';
         app.TresholdLabel_Rise = uilabel(app.MoveSumOptionsPanel);
         app.TresholdLabel_Rise.Tooltip = {'Load previously analysed data'; ''};
         app.TresholdLabel_Rise.Text = 'Treshold';
         app.RiseTreshold = uispinner(app.MoveSumOptionsPanel);
         app.RiseTreshold.Step = 0.01;
         app.RiseTreshold.Tooltip = {'Decreae if risephase is identified too late. Expected values:[-0.05 0.4]'};
         app.RiseTreshold.Value = 0.1;
         app.SumLengthLabel_Rise = uilabel(app.MoveSumOptionsPanel);
         app.SumLengthLabel_Rise.Tooltip = {'Load previously analysed data'; ''};
         app.SumLengthLabel_Rise.Text = 'Sum Length';
         app.RiseSumLength = uispinner(app.MoveSumOptionsPanel);
         app.RiseSumLength.Limits = [1 Inf];
         app.RiseSumLength.RoundFractionalValues = 'on';
         app.RiseSumLength.ValueDisplayFormat = '%d';
         app.RiseSumLength.Tooltip = {''};
         app.RiseSumLength.Value = 4;
         app.SumTresholdLabel_Rise = uilabel(app.MoveSumOptionsPanel);
         app.SumTresholdLabel_Rise.Tooltip = {'Load previously analysed data'; ''};
         app.SumTresholdLabel_Rise.Text = 'Sum Treshold';
         app.RiseSumTresh = uispinner(app.MoveSumOptionsPanel);
         app.RiseSumTresh.Limits = [1 Inf];
         app.RiseSumTresh.RoundFractionalValues = 'on';
         app.RiseSumTresh.ValueDisplayFormat = '%d';
         app.RiseSumTresh.Tooltip = {''};
         app.RiseSumTresh.Value = 3;
         app.RiseLabel = uilabel(app.MoveSumOptionsPanel);
         app.RiseLabel.Tooltip = {'Load previously analysed data'; ''};
         app.RiseLabel.Text = 'Rise';
         app.TresholdSpinnerLabel = uilabel(app.MoveSumOptionsPanel);
         app.TresholdSpinnerLabel.Text = 'Treshold';
         app.DecayTreshold = uispinner(app.MoveSumOptionsPanel);
         app.DecayTreshold.Step = 0.01;
         app.DecayTreshold.Value = 0.25;
         app.SumLengthSpinnerLabel = uilabel(app.MoveSumOptionsPanel);
         app.SumLengthSpinnerLabel.Text = 'Sum Length';
         app.DecaySumLength = uispinner(app.MoveSumOptionsPanel);
         app.DecaySumLength.RoundFractionalValues = 'on';
         app.DecaySumLength.ValueDisplayFormat = '%d';
         app.DecaySumLength.Value = 7;
         app.SumTresholdSpinnerLabel = uilabel(app.MoveSumOptionsPanel);
         app.SumTresholdSpinnerLabel.Text = 'Sum Treshold';
         app.DecaySumTresh = uispinner(app.MoveSumOptionsPanel);
         app.DecaySumTresh.RoundFractionalValues = 'on';
         app.DecaySumTresh.ValueDisplayFormat = '%d';
         app.DecaySumTresh.Value = 6;
         app.TresholdSpinnerLabel_2 = uilabel(app.MoveSumOptionsPanel);
         app.TresholdSpinnerLabel_2.Text = 'Treshold';
         app.DiffTresh = uispinner(app.MoveSumOptionsPanel);
         app.DiffTresh.Step = 0.01;
         app.DiffTresh.LowerLimitInclusive = 'off';
         app.DiffTresh.UpperLimitInclusive = 'off';
         app.DiffTresh.ValueDisplayFormat = '%.2f';
         app.DiffTresh.Value = -0.35;
         app.SumLengthSpinnerLabel_2 = uilabel(app.MoveSumOptionsPanel);
         app.SumLengthSpinnerLabel_2.Text = 'Sum Length';
         app.DiffSumLength = uispinner(app.MoveSumOptionsPanel);
         app.DiffSumLength.RoundFractionalValues = 'on';
         app.DiffSumLength.ValueDisplayFormat = '%d';
         app.DiffSumLength.Value = 9;
         app.Tab2 = uitab(app.TabGroup);
         app.Tab2.Title = 'Detect Minis';
         app.Tab2Grid1 = uigridlayout(app.Tab2);
         app.ax4 = uiaxes(app.Tab2Grid1);
         xlabel(app.ax4, 'Time (s)')
         ylabel(app.ax4, 'I (pA)')
         zlabel(app.ax4, 'Z')
         app.ax4.Tag = 'TagAx4';
         app.Tab2BottomGrid = uigridlayout(app.Tab2Grid1);
         app.NextEventTriggerPanel = uipanel(app.Tab2BottomGrid);
         app.NextEventTriggerPanel.Title = 'Next Event Trigger';
         app.SettriggervalueDropDownLabel = uilabel(app.NextEventTriggerPanel);
         app.SettriggervalueDropDownLabel.Text = 'Set trigger value:';
         app.TriggerNextEventDropDown = uidropdown(app.NextEventTriggerPanel);
         app.TriggerNextEventDropDown.Items = {'Very Low', 'Low', 'Medium', 'High', 'Very High', 'Ultra High'};
         app.TriggerNextEventDropDown.Tooltip = {'Specifies when decay stops because a new event is identified within the time window. Low = triggers on events with slow rising phase, may trigger during noise. High = only triggered by events with very steep and clean rising phases. '};
         app.TriggerNextEventDropDown.Value = 'Very High';
         app.SumDownOnlyCheckBox = uicheckbox(app.NextEventTriggerPanel);
         app.SumDownOnlyCheckBox.Text = 'Sum DownOnly';
         app.SumDownOnlyCheckBox.Value = true;
         app.SumDerivativeCheckBox = uicheckbox(app.NextEventTriggerPanel);
         app.SumDerivativeCheckBox.Text = 'Sum Derivative';
         app.SumDerivativeCheckBox.Value = true;
         app.SettriggervalueDropDownLabel_2 = uilabel(app.NextEventTriggerPanel);
         app.SettriggervalueDropDownLabel_2.Text = 'Calculate using:';
         app.ConstraintsPanel = uipanel(app.Tab2BottomGrid);
         app.ConstraintsPanel.Title = 'Constraints';
         app.MinAmpLabel = uilabel(app.ConstraintsPanel);
         app.MinAmpLabel.Text = 'Min';
         app.MinAmp = uieditfield(app.ConstraintsPanel, 'numeric');
         app.MinAmp.Limits = [1 100];
         app.MinAmp.Value = 3;
         app.MinAmpLabel_pA = uilabel(app.ConstraintsPanel);
         app.MinAmpLabel_pA.Text = 'pA';
         app.MaxAmpLabel = uilabel(app.ConstraintsPanel);
         app.MaxAmpLabel.Text = 'Max';
         app.MaxAmp = uieditfield(app.ConstraintsPanel, 'numeric');
         app.MaxAmp.Limits = [1 500];
         app.MaxAmp.Value = 400;
         app.MaxAmpLabel_pA = uilabel(app.ConstraintsPanel);
         app.MaxAmpLabel_pA.Text = 'pA';
         app.MinAmpLabel_2 = uilabel(app.ConstraintsPanel);
         app.MinAmpLabel_2.Text = 'Amplitude';
         app.MinAmpLabel_3 = uilabel(app.ConstraintsPanel);
         app.MinAmpLabel_3.Text = {'Decay τ'; ''};
         app.MinDecayTauLabel = uilabel(app.ConstraintsPanel);
         app.MinDecayTauLabel.Text = 'Min';
         app.MinDecayTau = uieditfield(app.ConstraintsPanel, 'numeric');
         app.MinDecayTau.Limits = [0.1 250];
         app.MinDecayTau.Value = 0.3;
         app.MaxDecayTauLabel = uilabel(app.ConstraintsPanel);
         app.MaxDecayTauLabel.Text = 'Max';
         app.MaxDecayTau = uieditfield(app.ConstraintsPanel, 'numeric');
         app.MaxDecayTau.Limits = [5 500];
         app.MaxDecayTau.Value = 20;
         app.MaxDecayTauLabel_ms = uilabel(app.ConstraintsPanel);
         app.MaxDecayTauLabel_ms.Text = 'ms';
         app.MinDecayTauLabel_ms = uilabel(app.ConstraintsPanel);
         app.MinDecayTauLabel_ms.Text = 'ms';
         app.Panel_5 = uipanel(app.Tab2BottomGrid);
         app.FitNowButton = uibutton(app.Panel_5, 'push');
         app.FitNowButton.ButtonPushedFcn = createCallbackFcn(app, @GoFitNow, true);
         app.FitNowButton.Icon = 'FitIcon-02.png';
         app.FitNowButton.Text = 'Fit Now';
         app.Tab2PlotNow = uibutton(app.Panel_5, 'push');
         app.Tab2PlotNow.ButtonPushedFcn = createCallbackFcn(app, @PlotAx4Now, true);
         app.Tab2PlotNow.Icon = 'function_charts-512.png';
         app.Tab2PlotNow.Text = 'Plot Now';
         app.FinishHere_interval1 = uibutton(app.Panel_5, 'push');
         app.FinishHere_interval1.ButtonPushedFcn = createCallbackFcn(app, @GoFinishCurrentXLim, true);
         app.FinishHere_interval1.Tooltip = {'Finish analysis of interval 1 at the current display (takes 90% of current xlims as end point)'};
         app.FinishHere_interval1.Text = 'Finish here';
         app.Interval1End = uieditfield(app.Panel_5, 'numeric');
         app.Interval1End.Limits = [0.01 999];
         app.Interval1End.ValueDisplayFormat = '%.4g';
         app.Interval1End.ValueChangedFcn = createCallbackFcn(app, @ChangeAx4LimToFitInterval, true);
         app.Interval1End.Value = 19;
         app.Interval1EditFieldLabel = uilabel(app.Panel_5);
         app.Interval1EditFieldLabel.Text = 'Interval 1';
         app.Interval1Start = uieditfield(app.Panel_5, 'numeric');
         app.Interval1Start.Limits = [0.01 999];
         app.Interval1Start.ValueDisplayFormat = '%.4g';
         app.Interval1Start.ValueChangedFcn = createCallbackFcn(app, @ChangeAx4LimToFitInterval, true);
         app.Interval1Start.Value = 9;
         app.FinishHere_interval2 = uibutton(app.Panel_5, 'push');
         app.FinishHere_interval2.ButtonPushedFcn = createCallbackFcn(app, @GoFinishCurrentXLimInt2, true);
         app.FinishHere_interval2.Tooltip = {'Finish analysis of interval 2 at the current display (takes 90% of current xlims as end point)'};
         app.FinishHere_interval2.Text = 'Finish here';
         app.Interval2EditFieldLabel = uilabel(app.Panel_5);
         app.Interval2EditFieldLabel.Text = 'Interval 2';
         app.Interval2start = uieditfield(app.Panel_5, 'numeric');
         app.Interval2start.Limits = [0 999];
         app.Interval2start.ValueDisplayFormat = '%.3g';
         app.Interval2start.Value = 34;
         app.Interval2end = uieditfield(app.Panel_5, 'numeric');
         app.Interval2end.Limits = [0 999];
         app.Interval2end.ValueDisplayFormat = '%.3g';
         app.Interval2end.Value = 44;
         app.GoHere_interval1 = uibutton(app.Panel_5, 'push');
         app.GoHere_interval1.ButtonPushedFcn = createCallbackFcn(app, @GoToInterVal1, true);
         app.GoHere_interval1.Tooltip = {'Move display to start of interval 1'};
         app.GoHere_interval1.Text = 'Go here';
         app.GoHere_interval2 = uibutton(app.Panel_5, 'push');
         app.GoHere_interval2.ButtonPushedFcn = createCallbackFcn(app, @GoToInterVal2, true);
         app.GoHere_interval2.Tooltip = {'Move display to start of interval 1'};
         app.GoHere_interval2.Text = 'Go here';
         app.CopytraceparametersPanel = uipanel(app.Tab2BottomGrid);
         app.CopytraceparametersPanel.Title = 'Copy trace parameters';
         app.CopyTriggervalCheckBox = uicheckbox(app.CopytraceparametersPanel);
         app.CopyTriggervalCheckBox.Text = 'Trigger val';
         app.CopyTriggervalCheckBox.Value = true;
         app.CopyFitSmoothValCheckBox = uicheckbox(app.CopytraceparametersPanel);
         app.CopyFitSmoothValCheckBox.Text = 'Fit smooth';
         app.CopyDecaySearchWindowCheckBox = uicheckbox(app.CopytraceparametersPanel);
         app.CopyDecaySearchWindowCheckBox.Text = 'Decay search window';
         app.CopyDecaySearchWindowCheckBox.Value = true;
         app.CopySGspanCheckBox = uicheckbox(app.CopytraceparametersPanel);
         app.CopySGspanCheckBox.Text = 'Sgolay Span';
         app.CopyConstraintsCheckBox = uicheckbox(app.CopytraceparametersPanel);
         app.CopyConstraintsCheckBox.Text = 'Constraints';
         app.ProgressPanel = uipanel(app.Tab2BottomGrid);
         app.GridLayout4 = uigridlayout(app.ProgressPanel);
         app.ProgressGauge = uigauge(app.GridLayout4, 'circular');
         app.ShowprogressBtn = uibutton(app.GridLayout4, 'state');
         app.ShowprogressBtn.Text = 'Show progress';
         app.ShowprogressBtn.Value = true;
         app.ConfirmMinisPanel = uipanel(app.Tab2BottomGrid);
         app.AddeventsButton = uibutton(app.ConfirmMinisPanel, 'state');
         app.AddeventsButton.ValueChangedFcn = createCallbackFcn(app, @TurnOnAdditionMode, true);
         app.AddeventsButton.Tooltip = {'Turns on selection mode: click on unmarked minis in trace to add new minis'};
         app.AddeventsButton.Icon = 'DataSelection_traceSelectionTransparent-02.png';
         app.AddeventsButton.Text = 'Add events';
         app.UndolastclickButton = uibutton(app.ConfirmMinisPanel, 'push');
         app.UndolastclickButton.ButtonPushedFcn = createCallbackFcn(app, @GoUndoLastClick, true);
         app.UndolastclickButton.Tooltip = {'Undo last click (either select/reject or added event)'};
         app.UndolastclickButton.Text = 'Undo last click';
         app.FinishButton = uibutton(app.ConfirmMinisPanel, 'push');
         app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishAddition_FitNewPoints, true);
         app.FinishButton.Tooltip = {'Click this one after finishing selection and rejection; new events will be fitted and rejected events will be removed'};
         app.FinishButton.Text = 'Finish';
         app.AcceptRejectButton = uibutton(app.ConfirmMinisPanel, 'state');
         app.AcceptRejectButton.ValueChangedFcn = createCallbackFcn(app, @TurnOnSelectionMode, true);
         app.AcceptRejectButton.Tooltip = {'Rejection mode: click on green dots to reject minis [add mode: click on orange dots to accept (only in exclude badfits mode, doesnt work at the moment]'};
         app.AcceptRejectButton.Icon = 'DataSelection_traceSelectionTransparent-01.png';
         app.AcceptRejectButton.Text = {'Accept/Reject'; ''};
         app.FittingParametersPanel = uigridlayout(app.Tab2BottomGrid);
         app.FittingparametersPanel = uipanel(app.FittingParametersPanel);
         app.FittingparametersPanel.Title = 'Fitting parameters';
         app.FitMethodDropDownLabel = uilabel(app.FittingparametersPanel);
         app.FitMethodDropDownLabel.Text = 'Fit Method';
         app.FitMethodDropDown = uidropdown(app.FittingparametersPanel);
         app.FitMethodDropDown.Items = {'Fit NLLS', 'fminsearch', 'lsqcurvefit'};
         app.FitMethodDropDown.Value = 'Fit NLLS';
         app.MinRsqSpinnerLabel = uilabel(app.FittingparametersPanel);
         app.MinRsqSpinnerLabel.Text = 'Min Rsq';
         app.MinRsqSpinner = uispinner(app.FittingparametersPanel);
         app.MinRsqSpinner.Step = 0.05;
         app.MinRsqSpinner.Limits = [0.1 1];
         app.MinRsqSpinner.Value = 0.6;
         app.SpanfilterLabel = uilabel(app.FittingparametersPanel);
         app.SpanfilterLabel.Text = 'Sgolay Span:';
         app.SgolaySpan = uieditfield(app.FittingparametersPanel, 'numeric');
         app.SgolaySpan.Value = 0.15;
         app.DecaySearchwindowsLabel = uilabel(app.FittingparametersPanel);
         app.DecaySearchwindowsLabel.Text = 'Decay search window(s)';
         app.FitdecayWindow = uieditfield(app.FittingparametersPanel, 'numeric');
         app.FitdecayWindow.Limits = [0.0005 0.2];
         app.FitdecayWindow.Tooltip = {'For fixed time interval this sets the interval (suggested value=0.01). For free time interval, this sets the window in which will be searched for recovery to baseline (suggested value=0.015-0.03)'};
         app.FitdecayWindow.Value = 0.015;
         app.FitSmoothData = uicheckbox(app.FittingparametersPanel);
         app.FitSmoothData.Text = 'Fit smooth data';
         app.FitSmoothData.Value = true;
         app.RobustmethodCheckBox = uicheckbox(app.FittingparametersPanel);
         app.RobustmethodCheckBox.Text = 'Robust method';
         app.UseFindpeakstoconfirmpeakCheckBox = uicheckbox(app.FittingparametersPanel);
         app.UseFindpeakstoconfirmpeakCheckBox.Text = 'Use Findpeaks to confirm peak';
         app.UseFindpeakstoconfirmpeakCheckBox.Value = true;
         app.Tab2DataFilterPanel = uipanel(app.Tab2BottomGrid);
         app.Tab2DataFilterPanel.Title = 'Data Filter';
         app.Filter1DropDownLabel = uilabel(app.Tab2DataFilterPanel);
         app.Filter1DropDownLabel.Text = 'Filter 1';
         app.Filter1Selection = uidropdown(app.Tab2DataFilterPanel);
         app.Filter1Selection.Items = {'None', 'Moving Average', 'Median Filter', 'Sgolay', 'Low Pass', 'Lowess', 'Loess'};
         app.Filter1Selection.Value = 'Sgolay';
         app.LengthSpinnerLabel = uilabel(app.Tab2DataFilterPanel);
         app.LengthSpinnerLabel.Text = 'Length';
         app.Filter1Length = uispinner(app.Tab2DataFilterPanel);
         app.Filter1Length.Step = 2;
         app.Filter1Length.Limits = [3 101];
         app.Filter1Length.Value = 15;
         app.OrderSpinnerLabel = uilabel(app.Tab2DataFilterPanel);
         app.OrderSpinnerLabel.Text = 'Order';
         app.Order1Order = uispinner(app.Tab2DataFilterPanel);
         app.Order1Order.Value = 2;
         app.fqkHzEditFieldLabel = uilabel(app.Tab2DataFilterPanel);
         app.fqkHzEditFieldLabel.Text = 'fq (kHz)';
         app.Filter1fq_kHz = uieditfield(app.Tab2DataFilterPanel, 'numeric');
         app.Filter1fq_kHz.Limits = [0 50];
         app.Filter1fq_kHz.Value = 1;
         app.Filter2DropDownLabel = uilabel(app.Tab2DataFilterPanel);
         app.Filter2DropDownLabel.Text = 'Filter 2';
         app.Filter2Selection = uidropdown(app.Tab2DataFilterPanel);
         app.Filter2Selection.Items = {'None', 'Moving Average', 'Median Filter', 'Sgolay', 'Low Pass', 'Lowess', 'Loess'};
         app.Filter2Selection.Value = 'Moving Average';
         app.LengthSpinner_2Label = uilabel(app.Tab2DataFilterPanel);
         app.LengthSpinner_2Label.Text = 'Length';
         app.Filter2Length = uispinner(app.Tab2DataFilterPanel);
         app.Filter2Length.Step = 2;
         app.Filter2Length.Limits = [3 101];
         app.Filter2Length.Value = 5;
         app.OrderSpinner_2Label = uilabel(app.Tab2DataFilterPanel);
         app.OrderSpinner_2Label.Text = 'Order';
         app.Filter2Order = uispinner(app.Tab2DataFilterPanel);
         app.Filter2Order.Value = 2;
         app.fqkHzEditField_2Label = uilabel(app.Tab2DataFilterPanel);
         app.fqkHzEditField_2Label.Text = 'fq (kHz)';
         app.Filter2fq_kHz = uieditfield(app.Tab2DataFilterPanel, 'numeric');
         app.Filter2fq_kHz.Limits = [0 50];
         app.Filter2fq_kHz.Value = 0.5;
         app.GridLayout6 = uigridlayout(app.Tab2BottomGrid);
         app.Tab2PanelLeftTop = uipanel(app.GridLayout6);
         app.Tab2PanelLeftTop.Title = 'BadFits';
         app.BadFitSwitch = uiswitch(app.Tab2PanelLeftTop, 'slider');
         app.BadFitSwitch.Items = {'Include', 'Exclude'};
         app.BadFitSwitch.ValueChangedFcn = createCallbackFcn(app, @ExcludeBadFits, true);
         app.BadFitSwitch.Value = 'Include';
         app.LoaddataStructButton = uibutton(app.Tab2PanelLeftTop, 'push');
         app.LoaddataStructButton.ButtonPushedFcn = createCallbackFcn(app, @GoLoaddataStruct, true);
         app.LoaddataStructButton.Tooltip = {'Load previously analysed data'; ''};
         app.LoaddataStructButton.Text = 'Load dataStruct';
         app.GoNextfileIdxBtn = uibutton(app.Tab2PanelLeftTop, 'push');
         app.GoNextfileIdxBtn.ButtonPushedFcn = createCallbackFcn(app, @GoLoadNextfileIdx, true);
         app.GoNextfileIdxBtn.Tooltip = {'Go to next recording'};
         app.GoNextfileIdxBtn.Text = '→';
         app.GoPreviousfileIdxBtn = uibutton(app.Tab2PanelLeftTop, 'push');
         app.GoPreviousfileIdxBtn.ButtonPushedFcn = createCallbackFcn(app, @GoLoadPreviousfileIdx, true);
         app.GoPreviousfileIdxBtn.Tooltip = {'Go to previous recording'};
         app.GoPreviousfileIdxBtn.Text = '←';
         app.UpdateanalyseddataButton = uibutton(app.Tab2PanelLeftTop, 'push');
         app.UpdateanalyseddataButton.ButtonPushedFcn = createCallbackFcn(app, @GoUpdateDataStruct, true);
         app.UpdateanalyseddataButton.Text = 'Update analysed data';
         app.RefitDataButton = uibutton(app.Tab2PanelLeftTop, 'push');
         app.RefitDataButton.ButtonPushedFcn = createCallbackFcn(app, @GoRefitData, true);
         app.RefitDataButton.Text = 'Refit Data';
         app.GoLastfileIdxBtn = uibutton(app.Tab2PanelLeftTop, 'push');
         app.GoLastfileIdxBtn.ButtonPushedFcn = createCallbackFcn(app, @GoToLastFileIdx, true);
         app.GoLastfileIdxBtn.Tooltip = {'Go to last recording of dataStruct'};
         app.GoLastfileIdxBtn.Text = {'⇥'; ''};
         app.GoFirstfileIdxBtn = uibutton(app.Tab2PanelLeftTop, 'push');
         app.GoFirstfileIdxBtn.ButtonPushedFcn = createCallbackFcn(app, @GoToFirstFileIdx, true);
         app.GoFirstfileIdxBtn.Tooltip = {'Go to first recording of dataStruct'};
         app.GoFirstfileIdxBtn.Text = '⇤';
         app.RefitAllDataBtn = uibutton(app.Tab2PanelLeftTop, 'push');
         app.RefitAllDataBtn.ButtonPushedFcn = createCallbackFcn(app, @GoRefitAllData, true);
         app.RefitAllDataBtn.Text = 'Refit All Data';
         app.TabGroup2 = uitabgroup(app.GridLayout6);
         app.TextTab = uitab(app.TabGroup2);
         app.TextTab.Title = 'Text';
         app.GridLayout8 = uigridlayout(app.TextTab);
         app.TextArea = uitextarea(app.GridLayout8);
         app.TextArea.ValueChangedFcn = createCallbackFcn(app, @ListenForAddNote, true);
         app.CountEventsTab = uitab(app.TabGroup2);
         app.CountEventsTab.Title = 'Count';
         app.CountcurrenteventsButton = uibutton(app.CountEventsTab, 'push');
         app.CountcurrenteventsButton.ButtonPushedFcn = createCallbackFcn(app, @GoCountMinisTillCurrent, true);
         app.CountcurrenteventsButton.Text = 'Count current events';
         app.Interval1EditFieldLabel_2 = uilabel(app.CountEventsTab);
         app.Interval1EditFieldLabel_2.Text = 'Interval 1';
         app.Interval1EditField = uieditfield(app.CountEventsTab, 'numeric');
         app.Interval1EditField.Limits = [0 Inf];
         app.Interval1EditField.RoundFractionalValues = 'on';
         app.Interval1EditField.ValueDisplayFormat = '%d';
         app.Interval2Label = uilabel(app.CountEventsTab);
         app.Interval2Label.Text = 'Interval 2';
         app.Interval2EditField = uieditfield(app.CountEventsTab, 'numeric');
         app.Interval2EditField.Limits = [0 Inf];
         app.Interval2EditField.RoundFractionalValues = 'on';
         app.Interval2EditField.ValueDisplayFormat = '%d';
         app.TotalEditFieldLabel = uilabel(app.CountEventsTab);
         app.TotalEditFieldLabel.Text = 'Total';
         app.TotalEditField = uieditfield(app.CountEventsTab, 'numeric');
         app.CountallButton = uibutton(app.CountEventsTab, 'push');
         app.CountallButton.ButtonPushedFcn = createCallbackFcn(app, @GoCountMinisTotal, true);
         app.CountallButton.Text = 'Count all';
         app.FormatSpecTab = uitab(app.TabGroup2);
         app.FormatSpecTab.Title = 'FormatSpec';
         app.LineWidthSpinnerLabel = uilabel(app.FormatSpecTab);
         app.LineWidthSpinnerLabel.Text = 'LineWidth';
         app.TracesLineWidthSpinner = uispinner(app.FormatSpecTab);
         app.TracesLineWidthSpinner.Step = 0.1;
         app.TracesLineWidthSpinner.ValueChangingFcn = createCallbackFcn(app, @SetAx4Properties, true);
         app.TracesLineWidthSpinner.Limits = [0.1 Inf];
         app.TracesLineWidthSpinner.ValueChangedFcn = createCallbackFcn(app, @SetAx4Properties, true);
         app.TracesLineWidthSpinner.Value = 1.2;
         app.FitsSpinner_2Label = uilabel(app.FormatSpecTab);
         app.FitsSpinner_2Label.Text = 'Fits';
         app.FitsLineWidthSpinner = uispinner(app.FormatSpecTab);
         app.FitsLineWidthSpinner.Step = 0.1;
         app.FitsLineWidthSpinner.Limits = [0.1 Inf];
         app.FitsLineWidthSpinner.Value = 1.2;
         app.MarkerSizeSpinnerLabel = uilabel(app.FormatSpecTab);
         app.MarkerSizeSpinnerLabel.Text = 'MarkerSize';
         app.MarkerSizeSpinner = uispinner(app.FormatSpecTab);
         app.MarkerSizeSpinner.Step = 2;
         app.MarkerSizeSpinner.Limits = [4 Inf];
         app.MarkerSizeSpinner.ValueDisplayFormat = '%d';
         app.MarkerSizeSpinner.Value = 26;
         app.LineSpinnerLabel = uilabel(app.FormatSpecTab);
         app.LineSpinnerLabel.Text = 'Line';
         app.MarkerLineWidthSpinner = uispinner(app.FormatSpecTab);
         app.MarkerLineWidthSpinner.Step = 0.1;
         app.MarkerLineWidthSpinner.Limits = [0 Inf];
         app.MarkerLineWidthSpinner.Value = 1.1;
         app.Tab = uitab(app.TabGroup2);
         app.Tab.Title = 'Tab';
         app.TextArea_2 = uitextarea(app.Tab);
         app.GoToTab = uitab(app.TabGroup2);
         app.GoToTab.Title = 'GoTo';
         app.GototracenoEditFieldLabel = uilabel(app.GoToTab);
         app.GototracenoEditFieldLabel.Text = 'Go to trace no:';
         app.GoToTraceNoEditField = uieditfield(app.GoToTab, 'numeric');
         app.GoToTraceNoEditField.Limits = [1 Inf];
         app.GoToTraceNoEditField.RoundFractionalValues = 'on';
         app.GoToTraceNoEditField.ValueDisplayFormat = '%d';
         app.GoToTraceNoEditField.ValueChangedFcn = createCallbackFcn(app, @GoToTraceNo, true);
         app.GoToTraceNoEditField.Enable = 'off';
         app.GoToTraceNoEditField.Value = 1;
         app.Panel_10 = uipanel(app.Tab2BottomGrid);
         app.GridLayout7 = uigridlayout(app.Panel_10);
         app.Panel_6 = uipanel(app.GridLayout7);
         app.Panel_11 = uipanel(app.Panel_6);
         app.Tab2_BtnXStepBack = uibutton(app.Panel_11, 'push');
         app.Tab2_BtnXStepBack.ButtonPushedFcn = createCallbackFcn(app, @MoveXBack_ax4, true);
         app.Tab2_BtnXStepBack.Text = '◀︎';
         app.Tab2_dYSlider = uislider(app.GridLayout7);
         app.Tab2_dYSlider.Limits = [10 850];
         app.Tab2_dYSlider.MajorTicks = [0 150 300 450 600 750];
         app.Tab2_dYSlider.MajorTickLabels = {'0', '150', '300', '450', '600', '750'};
         app.Tab2_dYSlider.ValueChangedFcn = createCallbackFcn(app, @SetdYSlider_ax4, true);
         app.Tab2_dYSlider.ValueChangingFcn = createCallbackFcn(app, @SetdYSlider_ax4, true);
         app.Tab2_dYSlider.MinorTicks = [];
         app.Tab2_dYSlider.Value = 400;
         app.DisplayoptionsPanel = uipanel(app.GridLayout7);
         app.DisplayoptionsPanel.Title = 'Display options';
         app.ShowFitsCheckBox = uicheckbox(app.DisplayoptionsPanel);
         app.ShowFitsCheckBox.ValueChangedFcn = createCallbackFcn(app, @GoShowFits, true);
         app.ShowFitsCheckBox.Text = 'Show Fits';
         app.ShowRejectedallCheckBox = uicheckbox(app.DisplayoptionsPanel);
         app.ShowRejectedallCheckBox.ValueChangedFcn = createCallbackFcn(app, @GoShowRejected, true);
         app.ShowRejectedallCheckBox.Enable = 'off';
         app.ShowRejectedallCheckBox.Visible = 'off';
         app.ShowRejectedallCheckBox.Text = 'Show Rejected(all)';
         app.ShowRejectedallCheckBox.Value = true;
         app.ShowSpecRejected = uicheckbox(app.DisplayoptionsPanel);
         app.ShowSpecRejected.ValueChangedFcn = createCallbackFcn(app, @ToggleSpecifiedRejected, true);
         app.ShowSpecRejected.Enable = 'off';
         app.ShowSpecRejected.Visible = 'off';
         app.ShowSpecRejected.Text = 'Show Rejected:';
         app.ShowunfilteredCheckBox = uicheckbox(app.DisplayoptionsPanel);
         app.ShowunfilteredCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowHideUnfilteredAx4, true);
         app.ShowunfilteredCheckBox.Text = 'Show unfiltered';
         app.ShowunfilteredCheckBox.Value = true;
         app.DropDown = uidropdown(app.DisplayoptionsPanel);
         app.DropDown.Items = {'Too long 𝞃', 'Fit amp deviation >30%', 'Too few decay datapoints', 'Rsq<Min Rsq'};
         app.DropDown.ValueChangedFcn = createCallbackFcn(app, @GoShowSpecifiedRejected, true);
         app.DropDown.Enable = 'off';
         app.DropDown.Visible = 'off';
         app.DropDown.Value = 'Too long 𝞃';
         app.BtnYStepUpAx4 = uibutton(app.DisplayoptionsPanel, 'push');
         app.BtnYStepUpAx4.ButtonPushedFcn = createCallbackFcn(app, @MoveYupAx4, true);
         app.BtnYStepUpAx4.Tooltip = {'Scroll through trace: move up'};
         app.BtnYStepUpAx4.Text = '▲';
         app.BtnYStepDownAx4 = uibutton(app.DisplayoptionsPanel, 'push');
         app.BtnYStepDownAx4.ButtonPushedFcn = createCallbackFcn(app, @MoveYDownAx4, true);
         app.BtnYStepDownAx4.Tooltip = {'Scroll through trace: move down'};
         app.BtnYStepDownAx4.Text = '▼';
         app.DebugBtnTab2 = uibutton(app.DisplayoptionsPanel, 'push');
         app.DebugBtnTab2.ButtonPushedFcn = createCallbackFcn(app, @GoDebug, true);
         app.DebugBtnTab2.Enable = 'off';
         app.DebugBtnTab2.Visible = 'off';
         app.DebugBtnTab2.Tooltip = {''};
         app.DebugBtnTab2.Text = 'Debug';
         app.ShowFittedStartsCheckBox = uicheckbox(app.DisplayoptionsPanel);
         app.ShowFittedStartsCheckBox.ValueChangedFcn = createCallbackFcn(app, @GoShowFittedStarts, true);
         app.ShowFittedStartsCheckBox.Text = 'Show Starts';
         app.ConnectwithpeaksCheckBox = uicheckbox(app.DisplayoptionsPanel);
         app.ConnectwithpeaksCheckBox.Enable = 'off';
         app.ConnectwithpeaksCheckBox.Visible = 'off';
         app.ConnectwithpeaksCheckBox.Text = 'Connect with peaks';
         app.X1Label = uilabel(app.DisplayoptionsPanel);
         app.X1Label.Text = 'X1';
         app.Tab2_XLim1 = uieditfield(app.DisplayoptionsPanel, 'numeric');
         app.Tab2_XLim1.Limits = [0 Inf];
         app.Tab2_XLim1.ValueDisplayFormat = '%.2f';
         app.Tab2_XLim1.ValueChangedFcn = createCallbackFcn(app, @SetX1ax4, true);
         app.Tab2_XLim1.Value = 1;
         app.Y1Label = uilabel(app.DisplayoptionsPanel);
         app.Y1Label.Text = 'Y1';
         app.Tab2_YLim1 = uieditfield(app.DisplayoptionsPanel, 'numeric');
         app.Tab2_YLim1.ValueDisplayFormat = '%.2f';
         app.Tab2_YLim1.ValueChangedFcn = createCallbackFcn(app, @SetdYSlider_ax4, true);
         app.Tab2_YLim1.Value = -200;
         app.Tab2_dXSlider = uislider(app.GridLayout7);
         app.Tab2_dXSlider.Limits = [0.01 7.25];
         app.Tab2_dXSlider.MajorTicks = [0.01 0.5 1 2 3 4 5 6 7];
         app.Tab2_dXSlider.MajorTickLabels = {'0', '0.5', '1', '2', '3', '4', '5', '6', '7'};
         app.Tab2_dXSlider.ValueChangedFcn = createCallbackFcn(app, @SetAx4dXslider, true);
         app.Tab2_dXSlider.ValueChangingFcn = createCallbackFcn(app, @SetAx4dXslider, true);
         app.Tab2_dXSlider.MinorTicks = [];
         app.Tab2_dXSlider.Value = 1.5;
         app.Panel_12 = uipanel(app.GridLayout7);
         app.Tab2_BtnXStepForw = uibutton(app.Panel_12, 'push');
         app.Tab2_BtnXStepForw.ButtonPushedFcn = createCallbackFcn(app, @MoveXForw_ax4, true);
         app.Tab2_BtnXStepForw.Text = '▶︎';
         app.Tab3 = uitab(app.TabGroup);
         app.Tab3.Title = 'Saving';
         app.MoveAbfFileToAnalysedCheckBox = uicheckbox(app.Tab3);
         app.MoveAbfFileToAnalysedCheckBox.Tag = 'TagMoveAbfFileToAnalysedCheckBox';
         app.MoveAbfFileToAnalysedCheckBox.Text = 'Move abf file to ''Analysed''';
         app.MoveAbfFileToAnalysedCheckBox.Value = true;
         app.HeadersSeparateCheckBox = uicheckbox(app.Tab3);
         app.HeadersSeparateCheckBox.Tag = 'TagMoveAbfFileToAnalysedCheckBox';
         app.HeadersSeparateCheckBox.Text = 'Store headers in separate .mat file';
         app.SaveoptionsListBoxLabel = uilabel(app.Tab3);
         app.SaveoptionsListBoxLabel.Text = 'Save options';
         app.SaveOptionsListBox = uilistbox(app.Tab3);
         app.SaveOptionsListBox.Items = {'Save Filtered Data (Tab2)', 'Save Decay Fits', 'Create dataHeaders'};
         app.SaveOptionsListBox.Multiselect = 'on';
         app.SaveOptionsListBox.ValueChangedFcn = createCallbackFcn(app, @ChangeSaveOptions, true);
         app.SaveOptionsListBox.Tag = 'TagSaveOptionsListBox';
         app.SaveOptionsListBox.Tooltip = {'Select options for fitting. Multiple selections are allowed.'};
         app.SaveOptionsListBox.Value = {'Save Decay Fits'};
         app.ExportFullMinis = uicheckbox(app.Tab3);
         app.ExportFullMinis.ValueChangedFcn = createCallbackFcn(app, @ChangeExportFullMinis, true);
         app.ExportFullMinis.Tag = 'TagMoveAbfFileToAnalysedCheckBox';
         app.ExportFullMinis.Tooltip = {'Full minis=all pieces of trace that are minis; only required for future implementation of findsignal function, or if you wanna make an average mini'};
         app.ExportFullMinis.Text = 'Export full minis';
         app.SaveMinisWithData = uicheckbox(app.Tab3);
         app.SaveMinisWithData.Tag = 'TagMoveAbfFileToAnalysedCheckBox';
         app.SaveMinisWithData.Tooltip = {'If checked, full mini is included in datastruct'};
         app.SaveMinisWithData.Text = 'Save with Data';
         app.AppendtodataStructCheckBox = uicheckbox(app.Tab3);
         app.AppendtodataStructCheckBox.Tooltip = {'Add current data to general dataStruct, (where every line represents a file)'};
         app.AppendtodataStructCheckBox.Text = 'Append to dataStruct';
         app.AppendtodataStructCheckBox.Value = true;
         app.MoveDoubleRecsCheckBox = uicheckbox(app.Tab3);
         app.MoveDoubleRecsCheckBox.Text = 'Find and move any double recordings from this cell';
         app.MoveDoubleRecsCheckBox.Value = true;
         app.TextAreaSaveOut = uitextarea(app.Tab3);
         app.SaveAllButton = uibutton(app.Tab3, 'push');
         app.SaveAllButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAllData, true);
         app.SaveAllButton.Icon = 'icons_SaveAll.png';
         app.SaveAllButton.Tooltip = {'Save data'};
         app.SaveAllButton.Text = 'Save';
         app.NewdataStructCheckBox = uicheckbox(app.Tab3);
         app.NewdataStructCheckBox.Tooltip = {'Add current data to general dataStruct, (where every line represents a file)'};
         app.NewdataStructCheckBox.Text = 'New dataStruct';
         app.FilenameEditFieldLabel = uilabel(app.Tab3);
         app.FilenameEditFieldLabel.Text = 'Filename';
         app.MatFileNameEditField = uieditfield(app.Tab3, 'text');
         app.MatFileNameEditField.Value = 'MinisNewDataStruct.mat';
         % Show the figure after all components are created
         app.MiniFigure.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = maaiEPSC

         runningApp = getRunningApp(app);

         % Check for running singleton app
         if isempty(runningApp)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MiniFigure)

            % Execute the startup function
            runStartupFcn(app, @MakeInitialisationVars)
         else

            % Focus the running singleton app
            figure(runningApp.MiniFigure)

            app = runningApp;
         end

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.MiniFigure)
      end
   end
end