classdef DialogAppSaveOptions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Button                         matlab.ui.control.Button
        SaveoptionsListBoxLabel        matlab.ui.control.Label
        SaveOptionsListBox             matlab.ui.control.ListBox
        MoveAbfFileToAnalysedCheckBox  matlab.ui.control.CheckBox
        HeadersSeparateCheckBox        matlab.ui.control.CheckBox
    end


    properties (Access = private) 
        CallingApp   % Main app object
        SaveChecks
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, SaveOptions, SaveChecks)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;           
            app.SaveOptionsListBox.Value=SaveOptions;
            app.HeadersSeparateCheckBox.Value=SaveChecks;

%             app.HeadersSeparateCheckBox.Value=SaveChecks(1,1);
%             app.MoveAbfFileToAnalysedCheckBox.Value=SaveChecks(1,2);

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Call main app's public function
%             app.SaveChecks=[app.HeadersSeparateCheckBox.Value app.MoveAbfFileToAnalysedCheckBox.Value];
            
            
            updateSave(app.CallingApp, app.SaveOptionsListBox.Value, app.HeadersSeparateCheckBox.Value);
            
            % Delete the dialog box
            delete(app)
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            % Enable the Plot Opions button in main app
            app.CallingApp.SaveAllButton.Enable = 'on';
            
            % Delete the dialog box 
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Save Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.UIFigure.Tag = 'TagSaveOptionsWindow';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'OK';
            app.SaveoptionsListBoxLabel = uilabel(app.UIFigure);
            app.SaveoptionsListBoxLabel.Text = 'Save options';
            app.SaveOptionsListBox = uilistbox(app.UIFigure);
            app.SaveOptionsListBox.Items = {'Save Filtered Data (Tab2)', 'Save Decay Fits', 'Create FitVals Header', 'Create BadFits Header', 'Create isMini Header'};
            app.SaveOptionsListBox.Multiselect = 'on';
            app.SaveOptionsListBox.Tag = 'TagSaveOptionsListBox';
            app.SaveOptionsListBox.Tooltip = {'Select options for fitting. Multiple selections are allowed.'};
            app.SaveOptionsListBox.Value = {'Save Filtered Data (Tab2)', 'Save Decay Fits', 'Create FitVals Header', 'Create BadFits Header', 'Create isMini Header'};
            app.MoveAbfFileToAnalysedCheckBox = uicheckbox(app.UIFigure);
            app.MoveAbfFileToAnalysedCheckBox.Tag = 'TagMoveAbfFileToAnalysedCheckBox';
            app.MoveAbfFileToAnalysedCheckBox.Text = 'Move abf file to ''Analysed''';
            app.MoveAbfFileToAnalysedCheckBox.Value = true;
            app.HeadersSeparateCheckBox = uicheckbox(app.UIFigure);
            app.HeadersSeparateCheckBox.Tag = 'TagMoveAbfFileToAnalysedCheckBox';
            app.HeadersSeparateCheckBox.Text = 'Store headers in separate .mat file';
            app.HeadersSeparateCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogAppSaveOptions(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end